classdef retrospective < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GithubHyperlink                 matlab.ui.control.Hyperlink
        MessageBox                      matlab.ui.control.TextArea
        AutoRecoLabel                   matlab.ui.control.Label
        AutoRecoCheckBox                matlab.ui.control.CheckBox
        RetrospectiveTitleImage         matlab.ui.control.Image
        JoveHyperlink                   matlab.ui.control.Hyperlink
        RetrospectiveCreditLabel        matlab.ui.control.Label
        DashboardTabGroup               matlab.ui.container.TabGroup
        DashboardTab                    matlab.ui.container.Tab
        ReconstructionTypeButtonGroup   matlab.ui.container.ButtonGroup
        DeepLearningButton              matlab.ui.control.RadioButton
        CompressedSensingButton         matlab.ui.control.RadioButton
        ProgressGauge                   matlab.ui.control.LinearGauge
        ExitButton                      matlab.ui.control.Button
        ExportMRDButton                 matlab.ui.control.Button
        ExportGIFButton                 matlab.ui.control.Button
        ExportDCMButton                 matlab.ui.control.Button
        ReconstructButton               matlab.ui.control.Button
        SortKspaceButton                matlab.ui.control.Button
        FilterButton                    matlab.ui.control.Button
        LoadMRDButton                   matlab.ui.control.Button
        ExportPanel                     matlab.ui.container.Panel
        ReconstructionPanel             matlab.ui.container.Panel
        CinePanel                       matlab.ui.container.Panel
        DynamicsEditFieldLabel          matlab.ui.control.Label
        FramesEditFieldLabel            matlab.ui.control.Label
        RecoTypeDropDownLabel           matlab.ui.control.Label
        DynamicsEditField               matlab.ui.control.NumericEditField
        FramesEditField                 matlab.ui.control.NumericEditField
        RecoTypeDropDown                matlab.ui.control.DropDown
        DetectedRatesBpmPanel           matlab.ui.container.Panel
        RespirationWindowEditFieldLabel  matlab.ui.control.Label
        RespirationEditFieldLabel       matlab.ui.control.Label
        HeartEditFieldLabel             matlab.ui.control.Label
        WidthLabel                      matlab.ui.control.Label
        CenterLabel                     matlab.ui.control.Label
        ResetFilterValuesButton         matlab.ui.control.Button
        RespirationWindowEditField      matlab.ui.control.NumericEditField
        RespirationWidthEditField       matlab.ui.control.NumericEditField
        RespirationEditField            matlab.ui.control.NumericEditField
        HeartWidthEditField             matlab.ui.control.NumericEditField
        HeartEditField                  matlab.ui.control.NumericEditField
        Lamp7                           matlab.ui.control.Lamp
        Lamp6                           matlab.ui.control.Lamp
        Lamp5                           matlab.ui.control.Lamp
        Lamp4                           matlab.ui.control.Lamp
        Lamp3                           matlab.ui.control.Lamp
        Lamp2                           matlab.ui.control.Lamp
        Lamp1                           matlab.ui.control.Lamp
        NavigatorTabGroup               matlab.ui.container.TabGroup
        NavigatorTab                    matlab.ui.container.Tab
        NavigatorFlipSwitch             matlab.ui.control.ToggleSwitch
        NavigatorFlipSwitchLabel        matlab.ui.control.Label
        LogLinButton                    matlab.ui.control.Button
        CoilSpinnerLabel                matlab.ui.control.Label
        CoilSpinner                     matlab.ui.control.Spinner
        SpectrumFig                     matlab.ui.control.UIAxes
        RawNavFig                       matlab.ui.control.UIAxes
        NavigatorSettingsTab            matlab.ui.container.Tab
        FilterPerSliceLabel             matlab.ui.control.Label
        FilterPerSliceCheckBox          matlab.ui.control.CheckBox
        ColorsButtonGroup               matlab.ui.container.ButtonGroup
        BlackWhiteButton                matlab.ui.control.RadioButton
        ClassicButton                   matlab.ui.control.RadioButton
        RespShiftEditField              matlab.ui.control.NumericEditField
        RespShiftEditFieldLabel         matlab.ui.control.Label
        ColorsLabel                     matlab.ui.control.Label
        MaxRespRateEditField            matlab.ui.control.NumericEditField
        MaxRespRateEditFieldLabel       matlab.ui.control.Label
        MinRespRateEditField            matlab.ui.control.NumericEditField
        MinRespRateEditFieldLabel       matlab.ui.control.Label
        AutoSoundsCheckBox              matlab.ui.control.CheckBox
        SoundsLabel                     matlab.ui.control.Label
        AutoSoundsCheckBoxLabel         matlab.ui.control.Label
        MaxHeartRateEditField           matlab.ui.control.NumericEditField
        MaxHeartRateEditFieldLabel      matlab.ui.control.Label
        MinHeartRateEditField           matlab.ui.control.NumericEditField
        MinHeartRateEditFieldLabel      matlab.ui.control.Label
        IgnoreRespirationToggleCheckBox  matlab.ui.control.CheckBox
        IgnoreRespirationToggleCheckBoxLabel  matlab.ui.control.Label
        FilterOrderEditField            matlab.ui.control.NumericEditField
        FilterOrderEditFieldLabel       matlab.ui.control.Label
        RespirationToggleCheckBox       matlab.ui.control.CheckBox
        RespirationToggleCheckBoxLabel  matlab.ui.control.Label
        PhysioFilteringLabel            matlab.ui.control.Label
        RespirationLabel                matlab.ui.control.Label
        DiscardEditField                matlab.ui.control.NumericEditField
        DiscardEditFieldLabel           matlab.ui.control.Label
        NavigatorNRPointsEditField      matlab.ui.control.NumericEditField
        NavigatorNRPointsEditFieldLabel  matlab.ui.control.Label
        SpeciesButton                   matlab.ui.container.ButtonGroup
        ZebraFishButton                 matlab.ui.control.RadioButton
        RatButton                       matlab.ui.control.RadioButton
        MouseButton                     matlab.ui.control.RadioButton
        NavigatorEndEditField           matlab.ui.control.NumericEditField
        NavigatorEndEditFieldLabel      matlab.ui.control.Label
        PresetsLabel                    matlab.ui.control.Label
        NavigatorLabel                  matlab.ui.control.Label
        NavigatorAnalysisTabGroup       matlab.ui.container.TabGroup
        NavigatoranalysisTab            matlab.ui.container.Tab
        DisplayRangeLabel               matlab.ui.control.Label
        RangeEditField                  matlab.ui.control.NumericEditField
        RangeEditFieldLabel             matlab.ui.control.Label
        RangeSlider                     matlab.ui.control.Slider
        StartSlider                     matlab.ui.control.Slider
        StartEditField                  matlab.ui.control.NumericEditField
        startEditFieldLabel             matlab.ui.control.Label
        NavFastSlowSwitch               matlab.ui.control.RockerSwitch
        NavStopButton                   matlab.ui.control.Button
        NavStartButton                  matlab.ui.control.Button
        IncludeCheckBox                 matlab.ui.control.CheckBox
        IncludeCheckBoxLabel            matlab.ui.control.Label
        IncludeStartSlider              matlab.ui.control.Slider
        IncludeEndSlider                matlab.ui.control.Slider
        ExcludeCheckBox                 matlab.ui.control.CheckBox
        ExcludeCheckBoxLabel            matlab.ui.control.Label
        ExcludeStartSlider              matlab.ui.control.Slider
        ExcludeEndSlider                matlab.ui.control.Slider
        RespRateTimeFig                 matlab.ui.control.UIAxes
        HeartRateTimeFig                matlab.ui.control.UIAxes
        RespirationNavFig               matlab.ui.control.UIAxes
        CardiacNavFig                   matlab.ui.control.UIAxes
        ParametersTabGroup              matlab.ui.container.TabGroup
        SequenceTab                     matlab.ui.container.Tab
        FlipAnglesViewField             matlab.ui.control.EditField
        FlipAnglesViewFieldLabel        matlab.ui.control.Label
        TEViewField                     matlab.ui.control.NumericEditField
        TEViewFiedLabel                 matlab.ui.control.Label
        FieldOfViewViewField            matlab.ui.control.NumericEditField
        FieldOfViewViewFieldLabel       matlab.ui.control.Label
        NrFlipAnglesViewField           matlab.ui.control.NumericEditField
        NrFlipAnglesViewFieldLabel      matlab.ui.control.Label
        TimeDynViewField                matlab.ui.control.EditField
        TimeDynViewFieldLabel           matlab.ui.control.Label
        ScanTimeViewField               matlab.ui.control.EditField
        ScanTimeViewFieldLabel          matlab.ui.control.Label
        NcViewField                     matlab.ui.control.NumericEditField
        NcViewFieldLabel                matlab.ui.control.Label
        NzViewField                     matlab.ui.control.NumericEditField
        NzViewFieldLabel                matlab.ui.control.Label
        TrajectoryViewField             matlab.ui.control.EditField
        TrajectoryViewFieldLabel        matlab.ui.control.Label
        TRViewField                     matlab.ui.control.NumericEditField
        TRViewFieldLabel                matlab.ui.control.Label
        FillingViewField                matlab.ui.control.NumericEditField
        FillingViewFieldLabel           matlab.ui.control.Label
        AveragesViewField               matlab.ui.control.NumericEditField
        AveragesViewFieldLabel          matlab.ui.control.Label
        NrKlinesViewField               matlab.ui.control.NumericEditField
        NrKlinesViewFieldLabel          matlab.ui.control.Label
        RepetitionsViewField            matlab.ui.control.NumericEditField
        RepetitionsViewFieldLabel       matlab.ui.control.Label
        TrajectorySizeViewField         matlab.ui.control.NumericEditField
        TrajectorySizeViewFieldLabel    matlab.ui.control.Label
        NxViewField                     matlab.ui.control.NumericEditField
        NxViewFieldLabel                matlab.ui.control.Label
        NyViewField                     matlab.ui.control.NumericEditField
        NyViewFieldLabel                matlab.ui.control.Label
        DataFileLabel                   matlab.ui.control.Label
        DataFile                        matlab.ui.control.TextArea
        TrajectoryFig                   matlab.ui.control.UIAxes
        GeometryTab                     matlab.ui.container.Tab
        SliceGapViewField               matlab.ui.control.NumericEditField
        SliceGapViewFieldLabel          matlab.ui.control.Label
        OffsetXViewField                matlab.ui.control.NumericEditField
        OffsetXViewFieldlabel           matlab.ui.control.Label
        OffsetYViewField                matlab.ui.control.NumericEditField
        OffsetYViewFieldLabel           matlab.ui.control.Label
        OffsetZViewField                matlab.ui.control.NumericEditField
        OffsetZViewFieldLabel           matlab.ui.control.Label
        AngleXViewField                 matlab.ui.control.NumericEditField
        AngleXViewFieldLabel            matlab.ui.control.Label
        AngleYViewField                 matlab.ui.control.NumericEditField
        AngleYViewFieldLabel            matlab.ui.control.Label
        AngleZViewField                 matlab.ui.control.NumericEditField
        AngleZViewFieldLabel            matlab.ui.control.Label
        FieldOfViewViewField2           matlab.ui.control.NumericEditField
        FieldOfViewViewField2Label      matlab.ui.control.Label
        AspectRatioViewField            matlab.ui.control.NumericEditField
        AspectRatioViewFieldLabel       matlab.ui.control.Label
        SliceThicknessViewField         matlab.ui.control.NumericEditField
        SliceThicknessViewFieldLabel    matlab.ui.control.Label
        PhaseOrientationViewField       matlab.ui.control.NumericEditField
        PhaseOrientationViewFieldLabel  matlab.ui.control.Label
        RecoParametersTab               matlab.ui.container.Tab
        LLRxyzEditFieldLabel            matlab.ui.control.Label
        TVdynEditFieldLabel             matlab.ui.control.Label
        TVxyzEditFieldLabel             matlab.ui.control.Label
        TVcineEditFieldLabel            matlab.ui.control.Label
        ESPIRiTCheckBoxLabel            matlab.ui.control.Label
        VariousLabel                    matlab.ui.control.Label
        CSrecoLabel                     matlab.ui.control.Label
        RingRmCheckBoxLabel             matlab.ui.control.Label
        PCACheckBoxLabel                matlab.ui.control.Label
        SharingEditFieldLabel           matlab.ui.control.Label
        FramesharingLabel               matlab.ui.control.Label
        MatlabRecoCheckBox              matlab.ui.control.CheckBox
        MatlabRecoCheckBoxLabel         matlab.ui.control.Label
        AutoGuessRecoCheckBoxLabel      matlab.ui.control.Label
        ReconstructionpresetsLabel      matlab.ui.control.Label
        RecoPresetsButtonGroup          matlab.ui.container.ButtonGroup
        VfaFunctionButton               matlab.ui.control.RadioButton
        RealTimeFunctionButton          matlab.ui.control.RadioButton
        RespiratoryFunctionButton       matlab.ui.control.RadioButton
        DiastolicFunctionButton         matlab.ui.control.RadioButton
        SystolicFunctionButton          matlab.ui.control.RadioButton
        ScoutButton                     matlab.ui.control.RadioButton
        AutoGuessRecoCheckBox           matlab.ui.control.CheckBox
        SharingEditField                matlab.ui.control.NumericEditField
        RingRmCheckBox                  matlab.ui.control.CheckBox
        PCAwindowEditField              matlab.ui.control.NumericEditField
        PCACheckBox                     matlab.ui.control.CheckBox
        ESPIRiTCheckBox                 matlab.ui.control.CheckBox
        TVdynEditField                  matlab.ui.control.NumericEditField
        TVcineEditField                 matlab.ui.control.NumericEditField
        LLRxyzEditField                 matlab.ui.control.NumericEditField
        TVxyzEditField                  matlab.ui.control.NumericEditField
        WVxyzEditField                  matlab.ui.control.NumericEditField
        WVxyzEditFieldLabel             matlab.ui.control.Label
        RadialTab                       matlab.ui.container.Tab
        DensityCorrectCheckBoxLabel     matlab.ui.control.Label
        DensityCorrectCheckBox          matlab.ui.control.CheckBox
        CenterEchoCheckBoxLabel         matlab.ui.control.Label
        CenterEchoCheckBox              matlab.ui.control.CheckBox
        PhaseCorrectCheckBoxLabel       matlab.ui.control.Label
        PhaseCorrectCheckBox            matlab.ui.control.CheckBox
        TrajectoryButtonGroup           matlab.ui.container.ButtonGroup
        RadialAngleEditField            matlab.ui.control.NumericEditField
        HalfCircleRadialButton          matlab.ui.control.RadioButton
        FullCircleRadialButton          matlab.ui.control.RadioButton
        UserDefinedRadialButton         matlab.ui.control.RadioButton
        RadialTrajectoryLabel           matlab.ui.control.Label
        RingMethodCheckBoxLabel         matlab.ui.control.Label
        RingMethodCheckBox              matlab.ui.control.CheckBox
        DataOffsetRadialEditField       matlab.ui.control.NumericEditField
        DataOffsetRadialEditFieldLabel  matlab.ui.control.Label
        GzDelayEditField                matlab.ui.control.NumericEditField
        GzdelayEditFieldLabel           matlab.ui.control.Label
        GyDelayEditField                matlab.ui.control.NumericEditField
        GydelayEditFieldLabel           matlab.ui.control.Label
        GxDelayEditField                matlab.ui.control.NumericEditField
        GxdelayEditFieldLabel           matlab.ui.control.Label
        GradientdelaysLabel             matlab.ui.control.Label
        GradDelayCalibrationCheckBoxLabel  matlab.ui.control.Label
        GradDelayCalibrationCheckBox    matlab.ui.control.CheckBox
        ReconstructionTabGroup          matlab.ui.container.TabGroup
        RecoTab                         matlab.ui.container.Tab
        OrientationLabel4               matlab.ui.control.Label
        OrientationLabel3               matlab.ui.control.Label
        OrientationLabel1               matlab.ui.control.Label
        OrientationLabel2               matlab.ui.control.Label
        AutoWLButton                    matlab.ui.control.StateButton
        MovieDynEditFieldLabel          matlab.ui.control.Label
        MovieDynEditField               matlab.ui.control.NumericEditField
        LevelScaleSlider                matlab.ui.control.Slider
        LevelScaleSliderLabel           matlab.ui.control.Label
        WindowScaleSlider               matlab.ui.control.Slider
        WindowScaleSliderLabel          matlab.ui.control.Label
        MovieSliceEditField             matlab.ui.control.NumericEditField
        MovieSliceEditFieldLabel        matlab.ui.control.Label
        FinalRespirationViewField       matlab.ui.control.NumericEditField
        FinalRespirationViewFieldLabel  matlab.ui.control.Label
        FinalHeartViewField             matlab.ui.control.NumericEditField
        FinalHeartViewFieldLabel        matlab.ui.control.Label
        MovieFrameEditField             matlab.ui.control.NumericEditField
        MovieFrameEditFieldLabel        matlab.ui.control.Label
        MovieCheckBox1                  matlab.ui.control.CheckBox
        MovieCheckBox2                  matlab.ui.control.CheckBox
        MovieCheckBox3                  matlab.ui.control.CheckBox
        MovieDynDecrButton              matlab.ui.control.Button
        MovieDynLabel                   matlab.ui.control.Label
        MovieDynIncrButton              matlab.ui.control.Button
        SenseMapSpinner2                matlab.ui.control.Spinner
        SenseMapSpinner1                matlab.ui.control.Spinner
        MovieCoilSpinner                matlab.ui.control.Spinner
        ButtonGroup                     matlab.ui.container.ButtonGroup
        SenseMapButton                  matlab.ui.control.RadioButton
        kSpaceButton                    matlab.ui.control.RadioButton
        AveragesButton                  matlab.ui.control.RadioButton
        MovieButton                     matlab.ui.control.RadioButton
        MovieSliceDecrButton            matlab.ui.control.Button
        MovieSliceLabel                 matlab.ui.control.Label
        MovieSliceIncrButton            matlab.ui.control.Button
        MovieFrameDecrButton            matlab.ui.control.Button
        MovieFrameLabel                 matlab.ui.control.Label
        MovieFrameIncrButton            matlab.ui.control.Button
        MovieRotateLabel                matlab.ui.control.Label
        MovieccwButton                  matlab.ui.control.Button
        MoviecwButton                   matlab.ui.control.Button
        ScaleLabel                      matlab.ui.control.Label
        MovieStartButton                matlab.ui.control.Button
        MovieStopButton                 matlab.ui.control.Button
        MovieFig                        matlab.ui.control.UIAxes
        LcurveTab                       matlab.ui.container.Tab
        LcurveTVdynCheckBoxLabel        matlab.ui.control.Label
        LcurveTVcineCheckBoxLabel       matlab.ui.control.Label
        LcurveLLRxyzCheckBoxLabel       matlab.ui.control.Label
        LcurveTVxyzCheckBoxLabel        matlab.ui.control.Label
        LcurveWVxyzCheckBoxLabel        matlab.ui.control.Label
        LcurveTVdynCheckBox             matlab.ui.control.CheckBox
        LcurveTVcineCheckBox            matlab.ui.control.CheckBox
        LcurveLLRxyzCheckBox            matlab.ui.control.CheckBox
        LcurveTVxyzCheckBox             matlab.ui.control.CheckBox
        LcurveWVxyzCheckBox             matlab.ui.control.CheckBox
        RegularizationLabel             matlab.ui.control.Label
        LambdaIterationsEditField       matlab.ui.control.NumericEditField
        LambdaIterationsEditFieldLabel  matlab.ui.control.Label
        LambdaStepsEditField            matlab.ui.control.NumericEditField
        LambdaStepsEditFieldLabel       matlab.ui.control.Label
        Lambda2EditField                matlab.ui.control.NumericEditField
        Lambda2EditFieldLabel           matlab.ui.control.Label
        Lambda1EditField                matlab.ui.control.NumericEditField
        Lambda1EditFieldLabel           matlab.ui.control.Label
        LambdaLabel                     matlab.ui.control.Label
        LcurveStopButton                matlab.ui.control.Button
        LcurveStartButton               matlab.ui.control.Button
        LcurveModelErrorFig             matlab.ui.control.UIAxes
        LcurveFig                       matlab.ui.control.UIAxes
        RadialRecoTab                   matlab.ui.container.Tab
        TrajMovie3DStopButton           matlab.ui.control.Button
        TrajMovie3DStartButton          matlab.ui.control.Button
        Traj3DLabel                     matlab.ui.control.Label
        SpokeEditFieldLabel             matlab.ui.control.Label
        SpokeEditField                  matlab.ui.control.NumericEditField
        TrajMovieStopButton             matlab.ui.control.Button
        TrajMovieStartButton            matlab.ui.control.Button
        TrajMovieDynEditField           matlab.ui.control.NumericEditField
        TrajMovieSliceEditField         matlab.ui.control.NumericEditField
        TrajMovieFrameEditField         matlab.ui.control.NumericEditField
        TrajMovieCheckBox3              matlab.ui.control.CheckBox
        TrajMovieCheckBox2              matlab.ui.control.CheckBox
        TrajMovieCheckBox1              matlab.ui.control.CheckBox
        TrajMovieFrameEditFieldLabel    matlab.ui.control.Label
        TrajMovieSliceEditFieldLabel    matlab.ui.control.Label
        TrajMovieDynEditFieldLabel      matlab.ui.control.Label
        TrajectoryLargeFig              matlab.ui.control.UIAxes
        StatusLabel                     matlab.ui.control.Label
        StatusImage                     matlab.ui.control.Image
        StatusBackgroundBorder          matlab.ui.control.EditField
        StatusBackgroundImage           matlab.ui.control.Image
        MRSImage                        matlab.ui.control.Image
        TulipImage                      matlab.ui.control.Image
        Credits                         matlab.ui.control.Label
    end


    % RETROSPECTIVE TRIGGERING RECONSTRUCTION
    %
    % Gustav Strijkers
    % Amsterdam UMC, Academic Medical Center (AMC)
    % Amsterdam, the Netherlands
    % g.j.strijkers@amsterdamumc.nl
    %
    %
    %
    % Version: Aug, 2024
    % Matlab 2023b
    %
    %
    % Version 1.0
    % - initial commit
    %
    % Version 2.0 changes:
    % - app designer GUI
    %
    % Version 2.1 changes:
    % - Cleaned-up the code
    % - GIF export file name now includes the number of frames
    % - Automatically start sorting k-space when the number of frames is changed
    % - Added buttons to rotate movie
    % - Added buttons to shift movie in the frame/time dimension
    % - Animated gif export image intensity is scaled by the intensity scaling slider
    % - Trajectory figure added
    % - Full x-scale in plot windows
    % - Added sliders to adjust navigator view range
    % - Parameter no_effective_views is now obsolete
    %
    % Version 3.0 changes:
    % - Added a matlab Conjugent Gradient method for CS-reconstruction without Bart toolbox
    %
    % Version 3.1 changes:
    % - Added the possibility to reconstruction a respiratory CINE
    % - Corrected a bug in the calculation of the total acquisition time
    % - Added the possibility to reconstruct only part of the data
    % - Added detected respiration and heart rate markers and values in navigator power spectrum
    % - Bug fixed number_of_cardiac frames
    % - Improved check on correct loading of MRD file
    % - Cleaned up the code
    %
    % Version 3.2 changes:
    % - Added edit fields for cardiac and respiratory filter values
    % - Fixed an issue with the image size of the movie in the app
    % - Added a reconstruction progress bar
    %
    % Version 3.3 changes:
    % - Bug fixes
    %
    % Version 3.4 changes:
    % - Major clean up of the code to accomodate future versions
    % - Added app message box
    % - Bug fixes
    % - Simplification of how to switch the buttons and lights on and off
    %
    % Version 3.5 changes:
    % - Bug fix for movie frame circular rotation DICOM export
    % - Fix a crash when respiratory or heart filter width > center value
    %
    % Version 3.6 changes:
    % - Added Total Variation in space regularization - Only works with Bart toolbox
    % - Now reading DICOM information from scanner reconstruction, including correct orientations
    % - VNSA weighting added
    % - Added additional search for DICOM information, compatible with PET/MRI systems
    %
    % Version 4.0 changes:
    % - Bug fixes
    % - Reconstructions of 3D+time data implemented
    % - Cleaned-up Matlab conjugate-gradient reconstruction code
    % - Added busy icons
    %
    % Version 5.0 changes:
    % - Added averages and k-space view
    % - Reconstruction of multi-coil data, tested with 2 receiver coils
    % - PCA analysis of navigator data and multi-coil data
    % - ESPIRiT coil sensitivity maps for multi-coil data
    % - Cosmetic changes
    % - Window/Level intensity scaling
    % - Respiration filtering with main peak + 1 harmonic
    % - Image orientation corrected for phase-encoding-orientation direction
    %
    % Version 5.1 changes:
    % - Added MRD export button, not functional yet
    % - Added cardiac absolute binning sorting option
    %
    % Version 5.2 changes:
    % - Added average navigator window (not very useful it seems)
    %
    % Version 5.3 changes:
    % - Radial import, first attempts
    % - Cleaned up code to include radial option
    % - Data window includes now an 'include' and 'exclude' data option
    % - Cleaned up code to make it easier to include more navigator types in the future
    %
    % Version 6.0 changes:
    % - Now supports reconstructions of dynamics
    % - VNSA removed (does not seem very useful)
    % - Average navigator removed (idem)
    % - MRD export and reconstruction by scanner reconstructor
    % - Improved check for loading unique dataset in loadMRD
    %
    % Version 6.1 changes:
    % - Implemented 1 frame reconstruction for 2D and 3D recos
    % - Corrected sequential and centric k-space filling for views2
    % - SUR file export to /1 directory on MRI/PET systems
    % - Multi-slice 2D acquisitions supported
    % - If on pc, check for system MRD and data directories
    %
    % Version 6.2 changes:
    % - Correction on 3D navigator code / bug fixes
    % - Discard points parameter added to prevent zipper artifact
    %
    % Version 6.3 changes:
    % - 2D and 3D Tukey filter of k-space, prevents zipper artifact
    % - Added autointensity button
    % - Navigator movie function added
    % - 3D P2ROUD trajectory import implemented, with pe1_order = 4
    %
    % Version 7.0 changes:
    % - Saves textfile with reconstruction setting parameters
    % - L-curve analysis of single slice 2D cine data
    % - Working on radial reconstruction
    % - Zoom of movie possible
    %
    % Version 7.1 changes:
    % - Advanced navigator panel with presets for mouse and rat
    % - Preset panel with presets for different reconstructions
    %
    % Version 7.2 changes:
    % - Bug fix in initial values navigator sliders
    % - Improved respiration detection for multi-slice acquistions
    %
    % Version 7.3 changes:
    % - Reconstruction of realtime = 1 dynamic per heart beat added
    % - Improved export of dynamic series
    % - Added Locally Low-Rank regularization parameter
    %
    % Version 7.3.1 changes:
    % - Import for B-type scanners
    %
    % Version 7.4
    % - Removed CLEAR option - does not seem to do much
    % - Weighted k-line sharing option
    % - Bug fix in L-curve analysis
    %
    % Version 7.5
    % - Support for non-square FOV
    %
    % Version 8.0
    % - VFA T1 mapping import added
    % - 3D gif data is exported as collage
    %
    % Version 8.1
    % - Cleaned up the code
    % - Software version added
    % - Introduced classes for better maintainablity
    %  
    % Version 8.2 & 8.3
    % - Several bug fixes
    % - TR = 0 bug
    % 
    % Version 8.4
    % - RescaleSlope & RescaleIntercept output in dicom file
    % 
    % Version 8.5
    % - Added button to toggle reconstruction to inspiration or expiration
    % - Fixed a bug with MRD export
    % - Fixed a bug in the image orientation with DICOM export
    %
    % Version 8.6
    % - 3D UTE
    % - median filter for navigator spectrum
    % - GIF export also for averages and k-space
    % - Increased MRD import speed dramatically
    % - Improved L-curve analysis
    %
    % Version 9
    % - Gradient delay calibration for 3D UTE
    % - Worked on 2D (gradient-echo) radial scanning
    % - Aspectratio bug fixed
    % - Viewing of trajectory and trajectory movie
    % - Total generalized variation regularization if available
    % - 2D (multi-slice) gradient-echo radial scanning available
    % - 3D UTE scanning available
    % - Some fancy trajectory visualizations for UTE and radial scans
    % - beta: image orientation indications
    % - gif export bug fix
    % - Auto detect nr discard samples
    % - PCA image denoising
    % - spoke increment added for radial imaging
    % - Bug fixes in the 3D P2ROUD reco
    % - Pressing 's' in the app saves an image of the app
    % - Cleaned up gif, dicom, mrd, sur export functions
    % - Fixed error on exit bug
    %
    % Version 9.4
    % - Massive cleanup, reduced number of methods classes to 1 (r) to improve readability
    % - Updated tool tips  
    % - Better error check for writing MRD, Dicom, and GIF files
    %
    % Version 9.5
    % - Non-square matrix implementation in app
    % - Square pixel size for DICOM export and MEDIS compatibility
    % - Dicom attributes imageType and ScanningSequence added for MEDIS import
    %
    % Version 9.6
    % - Black/white and classic color interface
    % - Analyzed code to remove dependencies on too many toolboxes
    % - Remaining toolboxes: 
    %           MATLAB
    %           Image processing toolbox
    %           Parallel computing toolbox
    %           Signal processing toolbox
    % - Improved B-type scanner import   
    % - Some bug-fixes to the interface
    % - Improved DICOM export
    % - Shift respiratory window option
    % - Export K-space option
    %
    % Version 9.7
    % - FLASH sequence version check for off-center corrections
    % - Adjustments to DICOM export to accomodate ITKsnap import
    % - 'a' hotkey to import matlab file back into app
    %
    % Version 9.8
    % - Some preps to include AI reconstructor  
    % - Some bug fixes to on and off switching of buttons
    % - Pixel interpolation of movie
    % - AI reconstructor implemented
    % - Fixed orienation labels
    %
    %
    %
    %
    %
    %
    %
    %
    %
    %
    %


    % Private variables within the app
    properties (Access = public)

        %#ok<*ST2NM> 

        % Software version 
        appVersion = "9.8.4"                                            % software version
        bartVersion                                                     % Bart version

        % Classes
        r                                   retro                       % methods class
    
        % Directories & filenames
        appDir                                                          % application directory
        oldDir                                                          % starting directory
        currentDir                                                      % search dir for mrdpath.txt
        mrdFileName                                                     % mrd file name
        mrdImportPath                                                   % mrd import path
        mrdAutoImportPath                                               % auto loading mrd import path
        dicomExportPath                                                 % dicom export path
        gifExportPath                                                   % gif export path
        mrdExportPath                                                   % mrd export path
        tag                                                             % filename tag for dicom export
        mrdFile                                                         % mrd data file
        mrdAutoFile                                                     % auto loading mrd file
        ignoreFiles                                                     % files to ignore during import
    
        % Acquisition
        acqDur                                                          % total acquisition time
       
        % Movie parameters
        nrFrames                                                        % number of movie frames
        nrDynamics                                                      % number of dynamics of flip-angles
        nrSlices                                                        % number of slices
        nrCardFrames                                                    % number of cardiac frames
        nrRespFrames                                                    % number of respiratory frames
   
        % Flags
        bartDetectedFlag = false                                        % bart toolbox available true or false
        aiDetectedFlag = false                                          % AI engine detected
        validFilterFlag                                                 % navigator data filtered true or false
        validSortFlag                                                   % valid sorting of the data true or false
        validRecoFlag                                                   % valid reconstruction true or false
        validSenseMapFlag                                               % sense maps exists, true or false
        stopMovieFlag                                                   % flag to stop the movie (true / false)
        stopLcurveFlag                                                  % flag to stop Lcurve calculation (true/false)
        stopNavMovieFlag                                                % flag to stop the navigator movie (true / false)
        stopRadialMovieFlag                                             % flag to stop the radial trajectorie movie (true / false)
        stop3DMovieFlag                                                 % flag to stop the 3D trajectorie movie (true / false)
        orientationsFlag                                                % plot image orientations (true / false)
       
        % Plot and movie elements
        progress                                                        % reconstruction progress bar
        cbar                                                            % color bar
        level                                                           % movie intensity level
        window                                                          % movie intensity window
        navMovieSpeed                                                   % speed of the navigator movie
        sliceNr                                                         % slice shown in the movie
        dynamicNr                                                       % dynamic shown in the movie
        dWin1                                                           % inclusion datawindow in heartrate plot
        dWin2                                                           % inclusion datawindow in respirationrate plot
        dWin3                                                           % exclusion datawindow in heartrate plot
        dWin4                                                           % exclusion datawindow in respirationrate plot
        dPlot1                                                          % dynamics indication in the heartrate plot
        dPlot2                                                          % dynamics indication in the respirationrate plot
        dPlot1Txt                                                       % dynamics indication text
        dPlot2Txt                                                       % dynamics indication text
        msPlot1                                                         % multislice indication in the heartrate plot
        msPlot2                                                         % multislice indication in the respirationrate plot
        msPlot1Txt                                                      % multislice indication text
        msPlot2Txt                                                      % multislice indication text
        moviePlot                                                       % movie plot handle
        cardRateWindow                                                  % start-end range indicator in time-heartrate figure
        respRateWindow                                                  % start-end range indicator in time-respirationrate figure
        trajPlotData                                                    % trajectory plot array
        trajPlot                                                        % trajectory plot
        traj3DPlot                                                      % trajectory 3D plot handle
        traj3Dcnt                                                       % trajectory 3D plot counter     
        imageRot                                                        % image rotation (0,1,2,3)
        maxNrSpokesPlot = 500                                           % maximum number of spokes visualized in 3D plot
   
        % Colors and sounds
        green                                                           % other shade of green
        brightgreen                                                     % green
        red                                                             % other shade of red
        brightred                                                       % red
        blue                                                            % blue
        lightblue                                                       % lightblue
        lightgrey                                                       % lightgray
        yellow                                                          % yellow
        orange                                                          % orange
        black                                                           % black
        guiBlack                                                        % app black
        guiGrey                                                         % app grey
        guiMiddleGrey                                                   % app middle grey
        guiDarkGrey                                                     % app dark grey
        guiWhite                                                        % app white
        guiBlackBlue                                                    % app black/blueish
        autoRecoColor                                                   % automatic reconstruction background color
        manualRecoColor                                                 % manual reconstruction background color
        warningSound                                                    % warning sound
        errorSound                                                      % error sound
     
    end % properties


    methods (Access = private)

        % -------------------------------------------------
        % Buttons and lights controls
        % -------------------------------------------------


        % Traffic lights
        function SetLightsFcn(app,bitmap)

            code = flip(dec2bin(bitmap,7));

            if code(1) == '0' app.Lamp1.Color = app.brightred; else app.Lamp1.Color = app.brightgreen; end %#ok<*SEPEX> 
            if code(2) == '0' app.Lamp2.Color = app.brightred; else app.Lamp2.Color = app.brightgreen; end
            if code(3) == '0' app.Lamp3.Color = app.brightred; else app.Lamp3.Color = app.brightgreen; end
            if code(4) == '0' app.Lamp4.Color = app.brightred; else app.Lamp4.Color = app.brightgreen; end
            if code(5) == '0' app.Lamp5.Color = app.brightred; else app.Lamp5.Color = app.brightgreen; end
            if code(6) == '0' app.Lamp6.Color = app.brightred; else app.Lamp6.Color = app.brightgreen; end
            if code(7) == '0' app.Lamp7.Color = app.brightred; else app.Lamp7.Color = app.brightgreen; end

            drawnow;

        end % Traffic lights


        % Dashboard buttons
        function SetDashboardButtonsFcn(app, bitmap)

            code = flip(dec2bin(bitmap,8));

            if code(1) == '0' app.LoadMRDButton.Enable = 'off'; else app.LoadMRDButton.Enable = 'on'; end
            if code(2) == '0' app.FilterButton.Enable = 'off'; else app.FilterButton.Enable = 'on'; end
            if code(3) == '0' app.SortKspaceButton.Enable = 'off'; else app.SortKspaceButton.Enable = 'on'; end
            if code(4) == '0' app.ReconstructButton.Enable = 'off'; else app.ReconstructButton.Enable = 'on'; end
            if code(5) == '0' app.ExportDCMButton.Enable = 'off'; else app.ExportDCMButton.Enable = 'on'; end
            if code(6) == '0' app.ExportGIFButton.Enable = 'off'; else app.ExportGIFButton.Enable = 'on'; end
            if code(7) == '0' app.ExportMRDButton.Enable = 'off'; else app.ExportMRDButton.Enable = 'on'; end
            if code(8) == '0' app.ExitButton.Enable = 'off'; else app.ExitButton.Enable = 'on'; end

            drawnow;

        end % Dashboard buttons


        % Set application colors
        function SetAppColorsFcn(app, guiColors)

            backgroundColor = guiColors{1}; 
            fontColor = guiColors{2}; 
            boxColor = guiColors{3}; 
            gaugeColor = guiColors{4}; 
            gaugeBackgroundColor = guiColors{5}; 
            figureColor = guiColors{6}; 
            movieFigureColor = guiColors{7}; 
            gridColor = guiColors{8}; 
            app.autoRecoColor = guiColors{9};
            app.manualRecoColor= guiColors{10};

            % Main tabs
            app.UIFigure.Color = backgroundColor;
            app.DashboardTab.BackgroundColor = backgroundColor;
            app.NavigatorTab.BackgroundColor = backgroundColor;
            app.NavigatoranalysisTab.BackgroundColor = backgroundColor;
            app.NavigatorSettingsTab.BackgroundColor = backgroundColor;
            app.SequenceTab.BackgroundColor = backgroundColor;
            app.GeometryTab.BackgroundColor = backgroundColor;
            app.RecoParametersTab.BackgroundColor = backgroundColor;
            app.RadialTab.BackgroundColor = backgroundColor;
            app.RecoTab.BackgroundColor = backgroundColor;
            app.LcurveTab.BackgroundColor = backgroundColor;
            app.RadialRecoTab.BackgroundColor = backgroundColor;
            app.MessageBox.FontColor = boxColor;
            app.MessageBox.BackgroundColor = figureColor;
            app.StatusBackgroundImage.BackgroundColor = figureColor;
           
            % Panels
            app.DetectedRatesBpmPanel.BackgroundColor = backgroundColor;
            app.CinePanel.BackgroundColor = backgroundColor;
            app.ReconstructionPanel.BackgroundColor = backgroundColor;
            app.ExportPanel.BackgroundColor = backgroundColor;
            app.SpeciesButton.BackgroundColor = backgroundColor;
            app.ColorsButtonGroup.BackgroundColor = backgroundColor;
            app.RecoPresetsButtonGroup.BackgroundColor = backgroundColor;
            app.TrajectoryButtonGroup.BackgroundColor = backgroundColor;
            app.ButtonGroup.BackgroundColor = backgroundColor;
            app.ReconstructionTypeButtonGroup.BackgroundColor = backgroundColor;

            % Labels
            app.AutoRecoLabel.FontColor = app.guiBlack;
            app.CenterLabel.FontColor = fontColor;
            app.WidthLabel.FontColor = fontColor;
            app.HeartEditFieldLabel.FontColor = fontColor;
            app.RespirationEditFieldLabel.FontColor = fontColor;
            app.RespirationWindowEditFieldLabel.FontColor = fontColor;
            app.RecoTypeDropDownLabel.FontColor = fontColor;
            app.FramesEditFieldLabel.FontColor = fontColor;
            app.DynamicsEditFieldLabel.FontColor = fontColor;
            app.WVxyzEditFieldLabel.FontColor = fontColor;
            app.TVxyzEditFieldLabel.FontColor = fontColor;
            app.LLRxyzEditFieldLabel.FontColor = fontColor;
            app.TVcineEditFieldLabel.FontColor = fontColor;
            app.TVdynEditFieldLabel.FontColor = fontColor;
            app.ESPIRiTCheckBoxLabel.FontColor = fontColor;
            app.DeepLearningButton.FontColor = fontColor;
            app.CompressedSensingButton.FontColor = fontColor;
            

            app.CoilSpinnerLabel.FontColor = fontColor;
            app.NavigatorFlipSwitch.FontColor = fontColor;
            app.NavigatorFlipSwitchLabel.FontColor = fontColor;
            app.LogLinButton.FontColor = fontColor;
            app.LogLinButton.BackgroundColor = backgroundColor;

            app.NavigatorEndEditFieldLabel.FontColor = fontColor;
            app.NavigatorNRPointsEditFieldLabel.FontColor = fontColor;
            app.DiscardEditFieldLabel.FontColor = fontColor;
            app.RespirationToggleCheckBoxLabel.FontColor = fontColor;
            app.IgnoreRespirationToggleCheckBoxLabel.FontColor = fontColor;
            app.AutoSoundsCheckBoxLabel.FontColor = fontColor;
            app.ClassicButton.FontColor = fontColor;
            app.BlackWhiteButton.FontColor = fontColor;
            app.MouseButton.FontColor = fontColor;
            app.ZebraFishButton.FontColor = fontColor;
            app.RatButton.FontColor = fontColor;
            app.FilterOrderEditFieldLabel.FontColor = fontColor;
            app.MinHeartRateEditFieldLabel.FontColor = fontColor;
            app.MaxHeartRateEditFieldLabel.FontColor = fontColor;
            app.MinRespRateEditFieldLabel.FontColor = fontColor;
            app.MaxRespRateEditFieldLabel.FontColor = fontColor;
            app.RespShiftEditFieldLabel.FontColor = fontColor;
            app.FilterPerSliceLabel.FontColor = fontColor;

            app.startEditFieldLabel.FontColor = fontColor;
            app.RangeEditFieldLabel.FontColor = fontColor;
            app.NavFastSlowSwitch.FontColor = fontColor;
            app.IncludeCheckBoxLabel.FontColor = fontColor;
            app.ExcludeCheckBoxLabel.FontColor = fontColor;

            app.DataFileLabel.FontColor = fontColor;
            app.NxViewFieldLabel.FontColor = fontColor;
            app.NyViewFieldLabel.FontColor = fontColor;
            app.NzViewFieldLabel.FontColor = fontColor;
            app.NcViewFieldLabel.FontColor = fontColor;
            app.RepetitionsViewFieldLabel.FontColor = fontColor;
            app.NrFlipAnglesViewFieldLabel.FontColor = fontColor;
            app.FlipAnglesViewFieldLabel.FontColor = fontColor;
            app.FieldOfViewViewFieldLabel.FontColor = fontColor;
            app.TRViewFieldLabel.FontColor = fontColor;
            app.TEViewFiedLabel.FontColor = fontColor;
            app.AveragesViewFieldLabel.FontColor = fontColor;
            app.FillingViewFieldLabel.FontColor = fontColor;
            app.NrKlinesViewFieldLabel.FontColor = fontColor;
            app.TrajectoryViewFieldLabel.FontColor = fontColor;
            app.TrajectorySizeViewFieldLabel.FontColor = fontColor;
            app.ScanTimeViewFieldLabel.FontColor = fontColor;
            app.TimeDynViewFieldLabel.FontColor = fontColor;

            app.FieldOfViewViewField2Label.FontColor = fontColor;
            app.SliceGapViewFieldLabel.FontColor = fontColor;
            app.OffsetXViewFieldlabel.FontColor = fontColor;
            app.OffsetYViewFieldLabel.FontColor = fontColor;
            app.OffsetZViewFieldLabel.FontColor = fontColor;
            app.AngleXViewFieldLabel.FontColor = fontColor;
            app.AngleYViewFieldLabel.FontColor = fontColor;
            app.AngleZViewFieldLabel.FontColor = fontColor;
            app.AspectRatioViewFieldLabel.FontColor = fontColor;
            app.SliceThicknessViewFieldLabel.FontColor = fontColor;
            app.PhaseOrientationViewFieldLabel.FontColor = fontColor;

            app.PCACheckBoxLabel.FontColor = fontColor;
            app.RingRmCheckBoxLabel.FontColor = fontColor;
            app.AutoGuessRecoCheckBoxLabel.FontColor = fontColor;
            app.MatlabRecoCheckBoxLabel.FontColor = fontColor;
            app.SharingEditFieldLabel.FontColor = fontColor;
            app.ScoutButton.FontColor = fontColor;
            app.SystolicFunctionButton.FontColor = fontColor;
            app.DiastolicFunctionButton.FontColor = fontColor;
            app.RespiratoryFunctionButton.FontColor = fontColor;
            app.RealTimeFunctionButton.FontColor = fontColor;
            app.VfaFunctionButton.FontColor = fontColor;

            app.GradDelayCalibrationCheckBoxLabel.FontColor = fontColor;
            app.RingMethodCheckBoxLabel.FontColor = fontColor;
            app.GxdelayEditFieldLabel.FontColor = fontColor;
            app.GydelayEditFieldLabel.FontColor = fontColor;
            app.GzdelayEditFieldLabel.FontColor = fontColor;
            app.DataOffsetRadialEditFieldLabel.FontColor = fontColor;
            app.HalfCircleRadialButton.FontColor = fontColor;
            app.FullCircleRadialButton.FontColor = fontColor;
            app.UserDefinedRadialButton.FontColor = fontColor;
            app.CenterEchoCheckBoxLabel.FontColor = fontColor;
            app.PhaseCorrectCheckBoxLabel.FontColor = fontColor;
            app.DensityCorrectCheckBoxLabel.FontColor = fontColor;

            app.MovieFrameEditFieldLabel.FontColor = fontColor;
            app.MovieSliceEditFieldLabel.FontColor = fontColor;
            app.MovieDynEditFieldLabel.FontColor = fontColor;
            app.FinalHeartViewFieldLabel.FontColor = fontColor;
            app.FinalRespirationViewFieldLabel.FontColor = fontColor;
            app.LevelScaleSliderLabel.FontColor = fontColor;
            app.WindowScaleSliderLabel.FontColor = fontColor;
            app.MovieFrameLabel.FontColor = fontColor;
            app.MovieSliceLabel.FontColor = fontColor;
            app.MovieDynLabel.FontColor = fontColor;
            app.MovieRotateLabel.FontColor = fontColor;
            app.MovieButton.FontColor = fontColor;
            app.AveragesButton.FontColor = fontColor;
            app.kSpaceButton.FontColor = fontColor;
            app.SenseMapButton.FontColor = fontColor;

            app.Lambda1EditFieldLabel.FontColor = fontColor;
            app.Lambda2EditFieldLabel.FontColor = fontColor;
            app.LambdaStepsEditFieldLabel.FontColor = fontColor;
            app.LambdaIterationsEditFieldLabel.FontColor = fontColor;
            app.LcurveWVxyzCheckBoxLabel.FontColor = fontColor;
            app.LcurveTVxyzCheckBoxLabel.FontColor = fontColor;
            app.LcurveLLRxyzCheckBoxLabel.FontColor = fontColor;
            app.LcurveTVcineCheckBoxLabel.FontColor = fontColor;
            app.LcurveTVdynCheckBoxLabel.FontColor = fontColor;

            app.TrajMovieFrameEditFieldLabel.FontColor = fontColor;
            app.TrajMovieSliceEditFieldLabel.FontColor = fontColor;
            app.TrajMovieDynEditFieldLabel.FontColor = fontColor;
            app.SpokeEditFieldLabel.FontColor = fontColor;

            % Gauges
            app.ProgressGauge.FontColor = gaugeColor;
            app.ProgressGauge.BackgroundColor = gaugeBackgroundColor;

            % Figures
            app.RawNavFig.Title.Color = fontColor;
            app.RawNavFig.XColor = fontColor;
            app.RawNavFig.YColor = fontColor;
            app.RawNavFig.Color = figureColor;

            app.SpectrumFig.Title.Color = fontColor;
            app.SpectrumFig.XColor = fontColor;
            app.SpectrumFig.YColor = fontColor;
            app.SpectrumFig.Color = figureColor;

            app.CardiacNavFig.Title.Color = fontColor;
            app.CardiacNavFig.XColor = fontColor;
            app.CardiacNavFig.YColor = fontColor;
            app.CardiacNavFig.Color = figureColor;

            app.RespirationNavFig.Title.Color = fontColor;
            app.RespirationNavFig.XColor = fontColor;
            app.RespirationNavFig.YColor = fontColor;
            app.RespirationNavFig.Color = figureColor;

            app.HeartRateTimeFig.Title.Color = fontColor;
            app.HeartRateTimeFig.XColor = fontColor;
            app.HeartRateTimeFig.YColor = fontColor;
            app.HeartRateTimeFig.Color = figureColor;

            app.RespRateTimeFig.Title.Color = fontColor;
            app.RespRateTimeFig.XColor = fontColor;
            app.RespRateTimeFig.YColor = fontColor;
            app.RespRateTimeFig.Color = figureColor;

            app.LcurveFig.Title.Color = fontColor;
            app.LcurveFig.XColor = fontColor;
            app.LcurveFig.YColor = fontColor;
            app.LcurveFig.Color = figureColor;
            app.LcurveFig.GridColor = gridColor;

            app.LcurveModelErrorFig.Title.Color = fontColor;
            app.LcurveModelErrorFig.XColor = fontColor;
            app.LcurveModelErrorFig.YColor = fontColor;
            app.LcurveModelErrorFig.Color = figureColor;
            app.LcurveModelErrorFig.GridColor = gridColor;

            app.MovieFig.Color = movieFigureColor;
            app.MovieFig.XColor = movieFigureColor;
            app.MovieFig.YColor = movieFigureColor;

            app.TrajectoryLargeFig.Color = figureColor;
            app.TrajectoryLargeFig.XColor = movieFigureColor;
            app.TrajectoryLargeFig.YColor = movieFigureColor;

            app.TrajectoryFig.Color = figureColor;
            app.TrajectoryFig.XColor = movieFigureColor;
            app.TrajectoryFig.YColor = movieFigureColor;

        end % SetAppColors


        % App version check
        function CheckVersionFnc(app)

            try

                currentVersion = convertStringsToChars(app.appVersion);
                versionFile = strcat("https://github.com/Moby1971/Retrospective-v",currentVersion(1:3),"/blob/main/appversion.txt");
                webtxt = webread(versionFile);
                pos = strfind(lower(webtxt),"retrospective_version=");
                newVersion = webtxt(pos+22:pos+22+length(currentVersion)-1);

                newVersion = strrep(newVersion,".","");
                newVersionNr = str2num(newVersion); 
                currentVersion = strrep(currentVersion,".","");
                currentVersionNr = str2num(currentVersion);
    
                if currentVersionNr < newVersionNr
                    app.TextMessage('A new version of RETROSPECTIVE is available ...');
                    app.TextMessage('Visit https://github.com/Moby1971/ to update ...');
                else
                    app.TextMessage('RETROSPECTIVE is up to date ...');
                end

            catch
            end

        end % CheckVersionFnc


        % Navigator selection buttons
        function NavSelButtonsFcn(app, on)

            if on

                app.NavigatorEndEditField.Enable = 'on';
                app.NavigatorEndEditFieldLabel.Enable = 'on';
                app.NavigatorNRPointsEditField.Enable = 'on';
                app.NavigatorNRPointsEditFieldLabel.Enable = 'on';
                app.NavigatorFlipSwitch.Enable = 'on';
                app.NavigatorFlipSwitchLabel.Enable = 'on';
                app.DiscardEditField.Enable = 'on';
                app.DiscardEditFieldLabel.Enable = 'on';

                if app.r.multiCoilFlag
                    app.CoilSpinner.Enable = 'on';
                    app.CoilSpinnerLabel.Enable = 'on';
                else
                    app.CoilSpinner.Enable = 'off';
                    app.CoilSpinnerLabel.Enable = 'off';
                end

                app.LogLinButton.Enable = 'on';
                app.CenterLabel.Enable = 'on';
                app.WidthLabel.Enable = 'on';
                app.HeartEditField.Enable = 'on';
                app.HeartEditFieldLabel.Enable = 'on';
                app.RespirationEditField.Enable = 'on';
                app.RespirationEditFieldLabel.Enable = 'on';
                app.HeartWidthEditField.Enable = 'on';
                app.RespirationWidthEditField.Enable = 'on';
                app.RespirationWindowEditField.Enable = 'on';
                app.RespirationWindowEditFieldLabel.Enable = 'on';
                app.RespirationEditFieldLabel.Enable = 'on';
                app.ResetFilterValuesButton.Enable = 'on';
                app.MouseButton.Enable = 'on';
                app.RatButton.Enable = 'on';
                app.ZebraFishButton.Enable = 'on';
                app.FilterOrderEditField.Enable = 'on';
                app.FilterOrderEditFieldLabel.Enable = 'on';
                app.MaxHeartRateEditField.Enable ='on';
                app.MaxHeartRateEditFieldLabel.Enable = 'on';
                app.MinHeartRateEditField.Enable ='on';
                app.MinHeartRateEditFieldLabel.Enable = 'on';
                app.MinRespRateEditField.Enable = 'on';
                app.MinRespRateEditFieldLabel.Enable = 'on';
                app.MaxRespRateEditField.Enable = 'on';
                app.MaxRespRateEditFieldLabel.Enable = 'on';
                app.RespShiftEditField.Enable = 'on';
                app.RespShiftEditFieldLabel.Enable = 'on';
                app.FilterPerSliceLabel.Enable = 'on';
                app.FilterPerSliceCheckBox.Enable = 'on';
                app.RespirationToggleCheckBox.Enable = 'on';
                app.RespirationToggleCheckBoxLabel.Enable = 'on';
                app.IgnoreRespirationToggleCheckBox.Enable = 'on';
                app.IgnoreRespirationToggleCheckBoxLabel.Enable = 'on';
               
            else

                app.NavigatorEndEditField.Enable = 'off';
                app.NavigatorEndEditFieldLabel.Enable = 'off';
                app.NavigatorNRPointsEditField.Enable = 'off';
                app.NavigatorNRPointsEditFieldLabel.Enable = 'off';
                app.NavigatorFlipSwitch.Enable = 'off';
                app.NavigatorFlipSwitchLabel.Enable = 'off';
                app.DiscardEditField.Enable = 'off';
                app.DiscardEditFieldLabel.Enable = 'off';
                app.SharingEditField.Enable = 'off';
                app.SharingEditFieldLabel.Enable = 'off';
                app.CoilSpinner.Enable = 'off';
                app.CoilSpinnerLabel.Enable = 'off';
                app.LogLinButton.Enable = 'off';
                app.CenterLabel.Enable = 'off';
                app.WidthLabel.Enable = 'off';
                app.HeartEditField.Enable = 'off';
                app.HeartEditFieldLabel.Enable = 'off';
                app.RespirationEditField.Enable = 'off';
                app.RespirationEditFieldLabel.Enable = 'off';
                app.HeartWidthEditField.Enable = 'off';
                app.RespirationWidthEditField.Enable = 'off';
                app.RespirationWindowEditField.Enable = 'off';
                app.RespirationWindowEditFieldLabel.Enable = 'off';
                app.ResetFilterValuesButton.Enable = 'off';
                app.MouseButton.Enable = 'off';
                app.RatButton.Enable = 'off';
                app.ZebraFishButton.Enable = 'off';
                app.FilterOrderEditField.Enable = 'off';
                app.FilterOrderEditFieldLabel.Enable = 'off';
                app.MaxHeartRateEditField.Enable ='off';
                app.MaxHeartRateEditFieldLabel.Enable = 'off';
                app.MinHeartRateEditField.Enable ='off';
                app.MinHeartRateEditFieldLabel.Enable = 'off';
                app.MinRespRateEditField.Enable = 'off';
                app.MinRespRateEditFieldLabel.Enable = 'off';
                app.MaxRespRateEditField.Enable = 'off';
                app.MaxRespRateEditFieldLabel.Enable = 'off';
                app.RespShiftEditField.Enable = 'off';
                app.RespShiftEditFieldLabel.Enable = 'off';
                app.FilterPerSliceLabel.Enable = 'off';
                app.FilterPerSliceCheckBox.Enable = 'off';
                app.RespirationToggleCheckBox.Enable = 'off';
                app.RespirationToggleCheckBoxLabel.Enable = 'off';
                app.IgnoreRespirationToggleCheckBox.Enable = 'off';
                app.IgnoreRespirationToggleCheckBoxLabel.Enable = 'off';

            end

            drawnow;

        end % Navigator selection buttons


        % Navigator viewing buttons
        function NavViewingButtonsFcn(app,on)

            if on

                app.StartEditField.Enable = 'on';
                app.startEditFieldLabel.Enable = 'on';
                app.RangeEditField.Enable = 'on';
                app.RangeEditFieldLabel.Enable = 'on';
                app.StartSlider.Enable = 'on';
                app.RangeSlider.Enable = 'on';
                app.NavStartButton.Enable = 'on';
                app.NavStopButton.Enable = 'on';
                app.NavFastSlowSwitch.Enable = 'on';
             
                if (app.DynamicsEditField.Value == 1) && ~app.r.multi2DFlag && ~app.r.vfaDataFlag
                    app.IncludeCheckBox.Enable = 'on';
                    app.IncludeCheckBoxLabel.Enable = 'on';
                    app.ExcludeCheckBox.Enable = 'on';
                    app.ExcludeCheckBoxLabel.Enable = 'on';
                else
                    app.IncludeCheckBox.Enable = 'off';
                    app.IncludeCheckBoxLabel.Enable = 'off';
                    app.ExcludeCheckBox.Enable = 'off';
                    app.ExcludeCheckBoxLabel.Enable = 'off';
                    app.IncludeStartSlider.Enable = 'off';
                    app.IncludeEndSlider.Enable = 'off';
                    app.ExcludeStartSlider.Enable = 'off';
                    app.ExcludeEndSlider.Enable = 'off';
                end

                if app.IncludeCheckBox.Value
                    app.IncludeStartSlider.Enable = 'on';
                    app.IncludeEndSlider.Enable = 'on';
                else
                    app.IncludeStartSlider.Enable = 'off';
                    app.IncludeEndSlider.Enable = 'off';
                end

                if app.ExcludeCheckBox.Value
                    app.ExcludeStartSlider.Enable = 'on';
                    app.ExcludeEndSlider.Enable = 'on';
                else
                    app.ExcludeStartSlider.Enable = 'off';
                    app.ExcludeEndSlider.Enable = 'off';
                end

            else

                app.StartEditField.Enable = 'off';
                app.startEditFieldLabel.Enable = 'off';
                app.RangeEditField.Enable = 'off';
                app.RangeEditFieldLabel.Enable = 'off';
                app.StartSlider.Enable = 'off';
                app.RangeSlider.Enable = 'off';
                app.NavStartButton.Enable = 'off';
                app.NavStopButton.Enable = 'off';
                app.NavFastSlowSwitch.Enable = 'off';
                app.IncludeCheckBox.Enable = 'off';
                app.IncludeCheckBoxLabel.Enable = 'off';
                app.IncludeStartSlider.Enable = 'off';
                app.IncludeEndSlider.Enable = 'off';
                app.ExcludeCheckBox.Enable = 'off';
                app.ExcludeCheckBoxLabel.Enable = 'off';
                app.ExcludeStartSlider.Enable = 'off';
                app.ExcludeEndSlider.Enable = 'off';

            end

            drawnow;

        end % Navigator viewing buttons


        % Cine reco buttons
        function CineRecoButtonsFcn(app,on)

            if on

                app.ScoutButton.Enable ='on';
                app.SystolicFunctionButton.Enable = 'on';
                app.DiastolicFunctionButton.Enable = 'on';
                app.RespiratoryFunctionButton.Enable = 'on';
                app.FramesEditField.Enable = 'on';
                app.FramesEditFieldLabel.Enable = 'on';

                app.DynamicsEditField.Enable = 'on';
                app.DynamicsEditFieldLabel.Enable = 'on';
                app.RealTimeFunctionButton.Enable = 'on';

                if app.r.multi2DFlag
                    app.DynamicsEditField.Enable = 'off';
                    app.DynamicsEditFieldLabel.Enable = 'off';
                    app.RealTimeFunctionButton.Enable = 'off';
                    app.DynamicsEditField.Value = 1;
                end

                switch app.r.dataType
                    case {'3D','3Dproud','2Dms','3Dute'}
                        app.RealTimeFunctionButton.Enable = 'off';
                end

                switch app.r.dataType
                    case {'2Dradial','2Dradialms','3Dute'}
                        app.SharingEditField.Enable = 'off';
                        app.SharingEditFieldLabel.Enable = 'off';
                    otherwise
                        app.SharingEditField.Enable = 'on';
                        app.SharingEditFieldLabel.Enable = 'on';
                end

                app.VfaFunctionButton.Enable = 'off';
                if app.r.vfaDataFlag
                    app.DynamicsEditField.Enable = 'off';
                    app.DynamicsEditFieldLabel.Enable = 'off';
                    app.VfaFunctionButton.Enable = 'on';
                    app.RealTimeFunctionButton.Enable = 'off';
                end

                app.RecoTypeDropDown.Enable = 'on';
                app.RecoTypeDropDownLabel.Enable = 'on';

                app.AutoGuessRecoCheckBox.Enable = 'on';
                app.AutoGuessRecoCheckBoxLabel.Enable = 'on';
                app.MatlabRecoCheckBox.Enable = 'on';
                app.MatlabRecoCheckBoxLabel.Enable = 'on';

                if app.aiDetectedFlag && (app.nrFrames > 4) && (strcmp(app.r.dataType,'2D') || strcmp(app.r.dataType,'2Dms'))
                    app.DeepLearningButton.Enable = 'on';
                    app.CompressedSensingButton.Enable = 'on';
                else
                    app.DeepLearningButton.Enable = 'off';
                    app.CompressedSensingButton.Enable = 'on';
                    app.CompressedSensingButton.Value = 1;
                    app.TextMessage('AI not available or #frames<5 ...');
                end
               
                % K-space data sorted and Bart is available
                if app.validSortFlag && app.bartDetectedFlag

                    app.WVxyzEditField.Enable = 'on';
                    app.WVxyzEditFieldLabel.Enable = 'on';
                    app.TVxyzEditField.Enable = 'on';
                    app.TVxyzEditFieldLabel.Enable = 'on';
                    app.LLRxyzEditField.Enable = 'on';
                    app.LLRxyzEditFieldLabel.Enable = 'on';
                    app.TVcineEditField.Enable = 'on';
                    app.TVcineEditFieldLabel.Enable = 'on';
                    app.TVdynEditField.Enable = 'on';
                    app.TVdynEditFieldLabel.Enable = 'on';

                    app.PCACheckBox.Enable = 'on';
                    app.PCACheckBoxLabel.Enable = 'on';
                    app.PCAwindowEditField.Enable = 'on';
                    app.RingRmCheckBox.Enable = 'on';
                    app.RingRmCheckBoxLabel.Enable = 'on';

                    app.LcurveStartButton.Enable = 'on';
                    app.LcurveStopButton.Enable = 'off';
                    app.Lambda1EditField.Enable = 'on';
                    app.Lambda1EditFieldLabel.Enable = 'on';
                    app.Lambda2EditField.Enable = 'on';
                    app.Lambda2EditFieldLabel.Enable = 'on';
                    app.LambdaStepsEditField.Enable = 'on';
                    app.LambdaStepsEditFieldLabel.Enable = 'on';
                    app.LambdaIterationsEditField.Enable = 'on';
                    app.LambdaIterationsEditFieldLabel.Enable = 'on';
                    app.LcurveWVxyzCheckBox.Enable = 'on';
                    app.LcurveWVxyzCheckBoxLabel.Enable = 'on';
                    app.LcurveTVxyzCheckBox.Enable = 'on';
                    app.LcurveTVxyzCheckBoxLabel.Enable = 'on';
                    app.LcurveLLRxyzCheckBox.Enable = 'on';
                    app.LcurveLLRxyzCheckBoxLabel.Enable = 'on';
                    app.LcurveTVcineCheckBox.Enable = 'on';
                    app.LcurveTVcineCheckBoxLabel.Enable = 'on';
                    app.LcurveTVdynCheckBox.Enable = 'on';
                    app.LcurveTVdynCheckBoxLabel.Enable = 'on';

                    if app.r.multiCoilFlag
                        app.ESPIRiTCheckBox.Enable = 'on';
                        app.ESPIRiTCheckBoxLabel.Enable = 'on';
                    else
                        app.ESPIRiTCheckBox.Enable = 'off';
                        app.ESPIRiTCheckBoxLabel.Enable = 'off';
                        app.ESPIRiTCheckBox.Value = 0;
                    end

                    switch app.r.dataType

                        case '3Dute'

                            app.GradDelayCalibrationCheckBox.Enable = 'on';
                            app.GradDelayCalibrationCheckBoxLabel.Enable = 'on';
                            app.RingMethodCheckBox.Enable = 'off';
                            app.RingMethodCheckBoxLabel.Enable = 'off';
                            app.GxDelayEditField.Enable = 'on';
                            app.GxdelayEditFieldLabel.Enable = 'on';
                            app.GyDelayEditField.Enable = 'on';
                            app.GydelayEditFieldLabel.Enable = 'on';
                            app.GzDelayEditField.Enable = 'on';
                            app.GzdelayEditFieldLabel.Enable = 'on';
                            app.DataOffsetRadialEditField.Enable = 'on';
                            app.DataOffsetRadialEditFieldLabel.Enable = 'on';
                            app.HalfCircleRadialButton.Enable = 'off';
                            app.FullCircleRadialButton.Enable = 'off';
                            app.UserDefinedRadialButton.Enable = 'off';
                            app.RadialAngleEditField.Enable = 'off';
                            app.CenterEchoCheckBox.Enable = 'off';
                            app.CenterEchoCheckBoxLabel.Enable = 'off';
                            app.PhaseCorrectCheckBox.Enable = 'on';
                            app.PhaseCorrectCheckBoxLabel.Enable = 'on';
                            app.DensityCorrectCheckBox.Enable = 'on';
                            app.DensityCorrectCheckBoxLabel.Enable = 'on';

                            app.DeepLearningButton.Enable = 'off';
                            app.CompressedSensingButton.Value = 1;

                            app.TrajMovieButtonsFcn(true);
                            app.Traj3DMovieButtonsFcn(true);

                        case {'2Dradial','2Dradialms'}

                            app.GradDelayCalibrationCheckBox.Enable = 'on';
                            app.GradDelayCalibrationCheckBoxLabel.Enable = 'on';
                            app.RingMethodCheckBox.Enable = 'on';
                            app.RingMethodCheckBoxLabel.Enable = 'on';
                            app.GxDelayEditField.Enable = 'on';
                            app.GxDelayEditField.Enable = 'on';
                            app.GyDelayEditField.Enable = 'on';
                            app.GyDelayEditField.Enable = 'on';
                            app.GzDelayEditField.Enable = 'on';
                            app.GzDelayEditField.Enable = 'on';
                            app.DataOffsetRadialEditField.Enable = 'off';
                            app.DataOffsetRadialEditFieldLabel.Enable = 'off';
                            app.HalfCircleRadialButton.Enable = 'on';
                            app.FullCircleRadialButton.Enable = 'on';
                            app.UserDefinedRadialButton.Enable = 'on';
                            app.RadialAngleEditField.Enable = 'on';
                            app.CenterEchoCheckBox.Enable = 'on';
                            app.CenterEchoCheckBoxLabel.Enable = 'on';
                            app.PhaseCorrectCheckBox.Enable = 'on';
                            app.PhaseCorrectCheckBoxLabel.Enable = 'on';
                            app.DensityCorrectCheckBox.Enable = 'on';
                            app.DensityCorrectCheckBoxLabel.Enable = 'on';

                            app.DeepLearningButton.Enable = 'off';
                            app.CompressedSensingButton.Value = 1;

                            app.TrajMovieButtonsFcn(true);
                            app.Traj3DMovieButtonsFcn(false);

                        otherwise

                            app.GradDelayCalibrationCheckBox.Enable = 'off';
                            app.GradDelayCalibrationCheckBoxLabel.Enable = 'off';
                            app.RingMethodCheckBox.Enable = 'off';
                            app.RingMethodCheckBoxLabel.Enable = 'off';
                            app.GxDelayEditField.Enable = 'off';
                            app.GxdelayEditFieldLabel.Enable = 'off';
                            app.GyDelayEditField.Enable = 'off';
                            app.GydelayEditFieldLabel.Enable = 'off';
                            app.GzDelayEditField.Enable = 'off';
                            app.GzdelayEditFieldLabel.Enable = 'off';
                            app.DataOffsetRadialEditField.Enable = 'off';
                            app.DataOffsetRadialEditFieldLabel.Enable = 'off';
                            app.HalfCircleRadialButton.Enable = 'off';
                            app.FullCircleRadialButton.Enable = 'off';
                            app.UserDefinedRadialButton.Enable = 'off';
                            app.RadialAngleEditField.Enable = 'off';
                            app.CenterEchoCheckBox.Enable = 'off';
                            app.CenterEchoCheckBoxLabel.Enable = 'off';
                            app.PhaseCorrectCheckBox.Enable = 'off';
                            app.PhaseCorrectCheckBox.Enable = 'off';
                            app.DensityCorrectCheckBox.Enable = 'off';
                            app.DensityCorrectCheckBoxLabel.Enable = 'off';
                       
                            app.TrajMovieButtonsFcn(false);
                            app.Traj3DMovieButtonsFcn(false);

                    end

                end

                % K-space data sorted and Bart is not available
                if app.validSortFlag && ~app.bartDetectedFlag

                    app.WVxyzEditField.Enable = 'off';
                    app.WVxyzEditFieldLabel.Enable = 'off';
                    app.TVxyzEditField.Enable = 'off';
                    app.TVxyzEditFieldLabel.Enable = 'off';
                    app.LLRxyzEditField.Enable = 'off';
                    app.LLRxyzEditFieldLabel.Enable = 'off';

                    app.PCACheckBox.Enable = 'on';
                    app.PCACheckBoxLabel.Enable = 'on';
                    app.PCAwindowEditField.Enable = 'on';
                    app.RingRmCheckBox.Enable = 'on';
                    app.RingRmCheckBoxLabel.Enable = 'on';

                    switch app.r.dataType
                        case {'2Dradial','2Dradialms','3Dute'}
                            app.TVcineEditField.Enable = 'off';
                            app.TVcineEditFieldLabel.Enable = 'off';
                        otherwise
                            app.TVcineEditField.Enable = 'on';
                            app.TVcineEditFieldLabel.Enable = 'on';
                    end

                    app.TVdynEditField.Enable = 'off';
                    app.TVdynEditFieldLabel.Enable = 'off';

                    app.LcurveStartButton.Enable = 'off';
                    app.LcurveStopButton.Enable = 'off';
                    app.Lambda1EditField.Enable = 'off';
                    app.Lambda1EditFieldLabel.Enable = 'off';
                    app.Lambda2EditField.Enable = 'off';
                    app.Lambda2EditFieldLabel.Enable = 'off';
                    app.LambdaStepsEditField.Enable = 'off';
                    app.LambdaStepsEditFieldLabel.Enable = 'off';
                    app.LambdaIterationsEditField.Enable = 'off';
                    app.LambdaIterationsEditFieldLabel.Enable = 'off';
                    app.LcurveWVxyzCheckBox.Enable = 'off';
                    app.LcurveWVxyzCheckBoxLabel.Enable = 'off';
                    app.LcurveTVxyzCheckBox.Enable = 'off';
                    app.LcurveTVxyzCheckBoxLabel.Enable = 'off';
                    app.LcurveLLRxyzCheckBox.Enable = 'off';
                    app.LcurveLLRxyzCheckBoxLabel.Enable = 'off';
                    app.LcurveTVcineCheckBox.Enable = 'off';
                    app.LcurveTVcineCheckBoxLabel.Enable = 'off';
                    app.LcurveTVdynCheckBox.Enable = 'off';
                    app.LcurveTVdynCheckBoxLabel.Enable = 'off';

                    switch app.r.dataType

                        case '3Dute'

                            app.GradDelayCalibrationCheckBox.Enable = 'off';
                            app.GradDelayCalibrationCheckBoxLabel.Enable = 'off';
                            app.RingMethodCheckBox.Enable = 'off';
                            app.RingMethodCheckBoxLabel.Enable = 'off';
                            app.GxDelayEditField.Enable = 'on';
                            app.GxDelayEditField.Enable = 'on';
                            app.GyDelayEditField.Enable = 'on';
                            app.GydelayEditFieldLabel.Enable = 'on';
                            app.GzDelayEditField.Enable = 'on';
                            app.GzdelayEditFieldLabel.Enable = 'on';
                            app.DataOffsetRadialEditField.Enable = 'on';
                            app.DataOffsetRadialEditFieldLabel.Enable = 'on';
                            app.HalfCircleRadialButton.Enable = 'off';
                            app.FullCircleRadialButton.Enable = 'off';
                            app.UserDefinedRadialButton.Enable = 'off';
                            app.RadialAngleEditField.Enable = 'off';
                            app.CenterEchoCheckBox.Enable = 'off';
                            app.CenterEchoCheckBoxLabel.Enable = 'off';
                            app.PhaseCorrectCheckBox.Enable = 'on';
                            app.PhaseCorrectCheckBoxLabel.Enable = 'on';
                            app.DensityCorrectCheckBox.Enable = 'off';
                            app.DensityCorrectCheckBoxLabel.Enable = 'off';

                            app.DeepLearningButton.Enable = 'off';
                            app.CompressedSensingButton.Value = 1;

                            app.TrajMovieButtonsFcn(true);
                            app.Traj3DMovieButtonsFcn(true);

                        case {'2Dradial','2Dradialms'}

                            app.GradDelayCalibrationCheckBox.Enable = 'off';
                            app.GradDelayCalibrationCheckBoxLabel.Enable = 'off';
                            app.RingMethodCheckBox.Enable = 'off';
                            app.RingMethodCheckBoxLabel.Enable = 'off';
                            app.GxDelayEditField.Enable = 'on';
                            app.GxdelayEditFieldLabel.Enable = 'on';
                            app.GyDelayEditField.Enable = 'on';
                            app.GydelayEditFieldLabel.Enable = 'on';
                            app.GzDelayEditField.Enable = 'on';
                            app.GzdelayEditFieldLabel.Enable = 'on';
                            app.DataOffsetRadialEditField.Enable = 'off';
                            app.DataOffsetRadialEditFieldLabel.Enable = 'off';
                            app.HalfCircleRadialButton.Enable = 'on';
                            app.FullCircleRadialButton.Enable = 'on';
                            app.UserDefinedRadialButton.Enable = 'on';
                            app.RadialAngleEditField.Enable = 'on';
                            app.CenterEchoCheckBox.Enable = 'on';
                            app.CenterEchoCheckBoxLabel.Enable = 'on';
                            app.PhaseCorrectCheckBox.Enable = 'on';
                            app.PhaseCorrectCheckBoxLabel.Enable = 'on';
                            app.DensityCorrectCheckBox.Enable = 'off';
                            app.DensityCorrectCheckBoxLabel.Enable = 'off';

                            app.DeepLearningButton.Enable = 'off';
                            app.CompressedSesnsingButton.Value = 1;

                            app.TrajMovieButtonsFcn(true);
                            app.Traj3DMovieButtonsFcn(false);

                        otherwise

                            app.GradDelayCalibrationCheckBox.Enable = 'off';
                            app.RingMethodCheckBox.Enable = 'off';
                            app.GxDelayEditField.Enable = 'off';
                            app.GyDelayEditField.Enable = 'off';
                            app.GzDelayEditField.Enable = 'off';
                            app.DataOffsetRadialEditField.Enable = 'off';
                            app.HalfCircleRadialButton.Enable = 'off';
                            app.FullCircleRadialButton.Enable = 'off';
                            app.UserDefinedRadialButton.Enable = 'off';
                            app.RadialAngleEditField.Enable = 'off';
                            app.CenterEchoCheckBox.Enable = 'off';
                            app.PhaseCorrectCheckBox.Enable = 'off';
                            app.DensityCorrectCheckBox.Enable = 'off';

                            app.TrajMovieButtonsFcn(false);
                            app.Traj3DMovieButtonsFcn(false);

                    end

                end

            end

            % K-space data not sorted
            if ~on

                app.RecoTypeDropDown.Enable = 'off';
                app.RecoTypeDropDownLabel.Enable = 'off';
                app.FramesEditField.Enable = 'off';
                app.FramesEditFieldLabel.Enable = 'off';
                app.DynamicsEditField.Enable = 'off';
                app.DynamicsEditFieldLabel.Enable = 'off';
  
                app.WVxyzEditField.Enable = 'off';
                app.WVxyzEditFieldLabel.Enable = 'off';
                app.TVxyzEditField.Enable = 'off';
                app.TVxyzEditFieldLabel.Enable = 'off';
                app.LLRxyzEditField.Enable = 'off';
                app.LLRxyzEditFieldLabel.Enable = 'off';
                app.TVcineEditField.Enable = 'off';
                app.TVcineEditFieldLabel.Enable = 'off';
                app.ESPIRiTCheckBox.Enable = 'off';
                app.ESPIRiTCheckBoxLabel.Enable = 'off';
                app.TVdynEditField.Enable = 'off';
                app.TVdynEditFieldLabel.Enable = 'off';
                app.DeepLearningButton.Enable = 'off';
                app.CompressedSensingButton.Enable = 'off';

                app.PCACheckBox.Enable = 'off';
                app.PCACheckBoxLabel.Enable = 'off';
                app.PCAwindowEditField.Enable = 'off';
                app.RingRmCheckBox.Enable = 'off';
                app.RingRmCheckBoxLabel.Enable = 'off';
                app.AutoGuessRecoCheckBox.Enable = 'off';
                app.AutoGuessRecoCheckBoxLabel.Enable = 'off';
                app.MatlabRecoCheckBox.Enable = 'off';
                app.MatlabRecoCheckBoxLabel.Enable = 'off';

                app.ScoutButton.Enable ='off';
                app.SystolicFunctionButton.Enable = 'off';
                app.DiastolicFunctionButton.Enable = 'off';
                app.RespiratoryFunctionButton.Enable = 'off';
                app.RealTimeFunctionButton.Enable = 'off';
                app.VfaFunctionButton.Enable = 'off';

                app.LcurveStartButton.Enable = 'off';
                app.LcurveStopButton.Enable = 'off';
                app.Lambda1EditField.Enable = 'off';
                app.Lambda1EditFieldLabel.Enable = 'off';
                app.Lambda2EditField.Enable = 'off';
                app.Lambda2EditFieldLabel.Enable = 'off';
                app.LambdaStepsEditField.Enable = 'off';
                app.LambdaStepsEditFieldLabel.Enable = 'off';
                app.LambdaIterationsEditField.Enable = 'off';
                app.LambdaIterationsEditFieldLabel.Enable = 'off';
                app.LcurveWVxyzCheckBox.Enable = 'off';
                app.LcurveWVxyzCheckBoxLabel.Enable = 'off';
                app.LcurveTVxyzCheckBox.Enable = 'off';
                app.LcurveTVxyzCheckBoxLabel.Enable = 'off';
                app.LcurveLLRxyzCheckBox.Enable = 'off';
                app.LcurveLLRxyzCheckBoxLabel.Enable = 'off';
                app.LcurveTVcineCheckBox.Enable = 'off';
                app.LcurveTVcineCheckBoxLabel.Enable = 'off';
                app.LcurveTVdynCheckBox.Enable = 'off';
                app.LcurveTVdynCheckBoxLabel.Enable = 'off';

                app.GradDelayCalibrationCheckBox.Enable = 'off';
                app.GradDelayCalibrationCheckBoxLabel.Enable = 'off';
                app.RingMethodCheckBox.Enable = 'off';
                app.RingMethodCheckBoxLabel.Enable = 'off';
                app.GxDelayEditField.Enable = 'off';
                app.GxdelayEditFieldLabel.Enable = 'off';
                app.GyDelayEditField.Enable = 'off';
                app.GydelayEditFieldLabel.Enable = 'off';
                app.GzDelayEditField.Enable = 'off';
                app.GzdelayEditFieldLabel.Enable = 'off';
                app.DataOffsetRadialEditField.Enable = 'off';
                app.DataOffsetRadialEditFieldLabel.Enable = 'off';

                app.HalfCircleRadialButton.Enable = 'off';
                app.FullCircleRadialButton.Enable = 'off';
                app.UserDefinedRadialButton.Enable = 'off';
                app.RadialAngleEditField.Enable = 'off';

                app.CenterEchoCheckBox.Enable = 'off';
                app.CenterEchoCheckBoxLabel.Enable = 'off';
                app.PhaseCorrectCheckBox.Enable = 'off';
                app.PhaseCorrectCheckBoxLabel.Enable = 'off';
                app.DensityCorrectCheckBox.Enable = 'off';
                app.DensityCorrectCheckBoxLabel.Enable = 'off';

                app.TrajMovieButtonsFcn(false);
                app.Traj3DMovieButtonsFcn(false);

            end
      
            drawnow;

        end % CineRecoButtonsFcn


        % 3D trajectory movie buttons
        function Traj3DMovieButtonsFcn(app, on)

            if on && app.validSortFlag

                switch app.r.dataType

                    case '3Dute'

                        if app.stop3DMovieFlag
                            app.TrajMovie3DStartButton.Enable = 'on';
                            app.TrajMovie3DStopButton.Enable = 'off';
                            app.SpokeEditField.Enable = 'on';
                            app.SpokeEditFieldLabel.Enable = 'on';
                        else
                            app.TrajMovie3DStartButton.Enable = 'off';
                            app.TrajMovie3DStopButton.Enable = 'on';
                            app.SpokeEditField.Enable = 'on';
                            app.SpokeEditField.Enable = 'on';
                        end

                    otherwise

                        app.TrajMovie3DStartButton.Enable = 'off';
                        app.TrajMovie3DStopButton.Enable = 'off';
                        app.SpokeEditField.Enable = 'off';
                        app.SpokeEditFieldLabel.Enable = 'off';
                        app.stop3DMovieFlag = true;
                end

            else

                app.TrajMovie3DStartButton.Enable = 'off';
                app.TrajMovie3DStopButton.Enable = 'off';
                app.SpokeEditField.Enable = 'off';
                app.SpokeEditFieldLabel.Enable = 'off';
                app.stop3DMovieFlag = true;

            end

        end % Traj3DMovieButtonsFcn


        % Radial movie buttons
        function TrajMovieButtonsFcn(app, on)

            if on && app.validSortFlag

                switch app.r.dataType

                    case {'2Dradial','2Dradialms'}

                        if app.stopRadialMovieFlag
                            app.TrajMovieStartButton.Enable = 'on';
                            app.TrajMovieStopButton.Enable = 'off';
                        else
                            app.TrajMovieStartButton.Enable = 'off';
                            app.TrajMovieStopButton.Enable = 'on';
                        end

                        app.TrajMovieFrameEditField.Enable = 'on';
                        app.TrajMovieFrameEditFieldLabel.Enable = 'on';
                        app.TrajMovieCheckBox1.Enable = 'on';

                        app.TrajMovieCheckBox2.Value = 0;
                        if app.r.multiSliceFlag
                            app.TrajMovieSliceEditField.Enable = 'on';
                            app.TrajMovieSliceEditFieldLabel.Enable = 'on';
                            app.TrajMovieCheckBox2.Enable = 'on';
                        end

                        app.TrajMovieCheckBox3.Value = 0;
                        if app.r.multiDynamicFlag
                            app.TrajMovieDynEditField.Enable = 'on';
                            app.TrajMovieDynEditFieldLabel.Enable = 'on';
                            app.TrajMovieCheckBox3.Enable = 'on';
                        end

                    case '3Dute'

                        if app.stopRadialMovieFlag
                            app.TrajMovieStartButton.Enable = 'on';
                            app.TrajMovieStopButton.Enable = 'off';
                        else
                            app.TrajMovieStartButton.Enable = 'off';
                            app.TrajMovieStopButton.Enable = 'on';
                        end

                        app.TrajMovieFrameEditField.Enable = 'on';
                        app.TrajMovieFrameEditFieldLabel.Enable = 'on';
                        app.TrajMovieCheckBox1.Enable = 'on';

                        app.TrajMovieCheckBox2.Value = 0;
                        app.TrajMovieSliceEditField.Value = 1;
                        app.TrajMovieSliceEditField.Enable = 'off';
                        app.TrajMovieSliceEditFieldLabel.Enable = 'off';
                        app.TrajMovieCheckBox2.Enable = 'off';
                       
                        app.TrajMovieCheckBox3.Value = 0;
                        if app.r.multiDynamicFlag
                            app.TrajMovieDynEditField.Enable = 'on';
                            app.TrajMovieDynEditFieldLabel.Enable = 'on';
                            app.TrajMovieCheckBox3.Enable = 'on';
                        end

                    otherwise

                        app.TrajMovieStartButton.Enable = 'off';
                        app.TrajMovieStopButton.Enable = 'off';
                        app.TrajMovieFrameEditField.Enable = 'off';
                        app.TrajMovieFrameEditFieldLabel.Enable = 'off';
                        app.TrajMovieSliceEditField.Enable = 'off';
                        app.TrajMovieSliceEditFieldLabel.Enable = 'off';
                        app.TrajMovieDynEditField.Enable = 'off';
                        app.TrajMovieDynEditFieldLabel.Enable = 'off';
                        app.TrajMovieCheckBox1.Enable = 'off';
                        app.TrajMovieCheckBox2.Enable = 'off';
                        app.TrajMovieCheckBox3.Enable = 'off';
                        app.stopRadialMovieFlag = true;

                end

            else

                app.TrajMovieStartButton.Enable = 'off';
                app.TrajMovieStopButton.Enable = 'off';
                app.TrajMovieFrameEditField.Enable = 'off';
                app.TrajMovieFrameEditFieldLabel.Enable = 'off';
                app.TrajMovieSliceEditField.Enable = 'off';
                app.TrajMovieSliceEditFieldLabel.Enable = 'off';
                app.TrajMovieDynEditField.Enable = 'off';
                app.TrajMovieDynEditFieldLabel.Enable = 'off';
                app.TrajMovieCheckBox1.Enable = 'off';
                app.TrajMovieCheckBox2.Enable = 'off';
                app.TrajMovieCheckBox3.Enable = 'off';
                app.stopRadialMovieFlag = true;
                
            end

        end % MovieRadialButtons


        % Movie buttons
        function MovieButtonsFcn(app, on)

            if on && app.validRecoFlag

                if app.stopMovieFlag
                    app.MovieStartButton.Enable = 'on';
                    app.MovieStopButton.Enable = 'off';
                else
                    app.MovieStartButton.Enable = 'off';
                    app.MovieStopButton.Enable = 'on';
                end

                app.MovieFrameEditField.Enable = 'on';
                app.MovieFrameEditFieldLabel.Enable = 'on';
                app.MovieCheckBox1.Enable = 'on';
                app.WindowScaleSlider.Enable = 'on';
                app.WindowScaleSliderLabel.Enable = 'on';
                app.LevelScaleSlider.Enable = 'on';
                app.LevelScaleSliderLabel.Enable = 'on';
                app.AutoWLButton.Enable = 'on';
                app.MovieRotateLabel.Enable = 'on';
                app.MoviecwButton.Enable = 'on';
                app.MovieccwButton.Enable = 'on';
                app.MovieFrameLabel.Enable = 'on';
                app.MovieFrameIncrButton.Enable = 'on';
                app.MovieFrameDecrButton.Enable = 'on';
                app.MovieButton.Enable = 'on';
                app.AveragesButton.Enable = 'on';
                app.kSpaceButton.Enable = 'on';
              
                if app.r.multiCoilFlag
                    app.MovieCoilSpinner.Enable = 'on';
                else
                    app.MovieCoilSpinner.Enable = 'off';
                end

                if app.validSenseMapFlag
                    app.SenseMapButton.Enable = 'on';
                    s1 = size(app.r.senseMap,4);
                    s2 = size(app.r.senseMap,5);
                    app.SenseMapSpinner1.Value = 1;
                    if s1>1
                        app.SenseMapSpinner1.Limits = [1,s1];
                        app.SenseMapSpinner1.Enable = 'on';
                    else
                        app.SenseMapSpinner1.Limits = [1,2];
                        app.SenseMapSpinner1.Enable = 'off';
                    end
                    app.SenseMapSpinner2.Value = 1;
                    if s2>1
                        app.SenseMapSpinner2.Limits = [1,s2];
                        app.SenseMapSpinner2.Enable = 'on';
                    else
                        app.SenseMapSpinner2.Limits = [1,2];
                        app.SenseMapSpinner2.Enable = 'off';
                    end
                else
                    app.SenseMapButton.Enable = 'off';
                    app.SenseMapSpinner1.Enable = 'off';
                    app.SenseMapSpinner2.Enable = 'off';
                end

                if app.r.multiSliceFlag
                    app.MovieSliceLabel.Enable = 'on';
                    app.MovieSliceEditField.Enable = 'on';
                    app.MovieSliceIncrButton.Enable = 'on';
                    app.MovieSliceDecrButton.Enable = 'on';
                    app.MovieCheckBox2.Enable = 'on';
                end

                if app.r.multiDynamicFlag
                    app.MovieDynLabel.Enable = 'on';
                    app.MovieDynEditField.Enable = 'on';
                    app.MovieDynIncrButton.Enable = 'on';
                    app.MovieDynDecrButton.Enable = 'on';
                    app.MovieCheckBox3.Enable = 'on';
                end

            else

                app.MovieStartButton.Enable = 'off';
                app.MovieStopButton.Enable = 'off';
                app.MovieFrameEditField.Enable = 'off';
                app.MovieFrameEditFieldLabel.Enable = 'off';
                app.MovieSliceEditField.Enable = 'off';
                app.MovieSliceEditFieldLabel.Enable = 'off';
                app.MovieDynEditField.Enable = 'off';
                app.MovieDynEditFieldLabel.Enable = 'off';
                app.MovieCheckBox1.Enable = 'off';
                app.MovieCheckBox2.Enable = 'off';
                app.MovieCheckBox3.Enable = 'off';
                app.WindowScaleSlider.Enable = 'off';
                app.WindowScaleSliderLabel.Enable = 'off';
                app.LevelScaleSlider.Enable = 'off';
                app.LevelScaleSliderLabel.Enable = 'off';
                app.AutoWLButton.Enable = 'off';
                app.MovieRotateLabel.Enable = 'off';
                app.MoviecwButton.Enable = 'off';
                app.MovieccwButton.Enable = 'off';
                app.MovieFrameLabel.Enable = 'off';
                app.MovieFrameIncrButton.Enable = 'off';
                app.MovieFrameDecrButton.Enable = 'off';
                app.MovieSliceLabel.Enable = 'off';
                app.MovieSliceIncrButton.Enable = 'off';
                app.MovieSliceDecrButton.Enable = 'off';
                app.MovieDynLabel.Enable = 'off';
                app.MovieDynIncrButton.Enable = 'off';
                app.MovieDynDecrButton.Enable = 'off';
                app.MovieButton.Enable = 'off';
                app.AveragesButton.Enable = 'off';
                app.kSpaceButton.Enable = 'off';
                app.MovieCoilSpinner.Enable = 'off';
                app.SenseMapButton.Enable = 'off';
                app.SenseMapSpinner1.Enable = 'off';
                app.SenseMapSpinner2.Enable = 'off';
                app.stopMovieFlag = true;

            end

            drawnow;

        end % MovieButtonsFcn


        % Switch of all buttons
        function SwitchOffAllButtonsFcn(app)

            % Stop the movies
            app.stopNavMovieFlag = true;
            app.stopMovieFlag = true;
            app.stopRadialMovieFlag = true;

            % Dashboard buttons
            app.SetDashboardButtonsFcn(false);

            % Navigator selection and filtering
            app.NavSelButtonsFcn(false);

            % Navigator viewing
            app.NavViewingButtonsFcn(false);

            % Cine reconstruction buttons
            app.CineRecoButtonsFcn(false);

            % Movie windows
            app.MovieButtonsFcn(false);
            app.TrajMovieButtonsFcn(false);
            app.Traj3DMovieButtonsFcn(false);

        end % SwitchOffAllButtonsFcn

        % Load icons
        function SetAppLayoutPropertiesFcn(app)

            app.Credits.Text = strcat("Gustav Strijkers, Amsterdam UMC, Version ",app.appVersion);

            % Icons and title
            app.TulipImage.ImageSource = 'AmsterdamUMC.png';
            app.MRSImage.ImageSource = 'MRSolutionsLogo.gif';
            app.RetrospectiveTitleImage.ImageSource = 'Retrospective.png';
       
            % Button images
            app.LoadMRDButton.Icon = 'disk.png';
            app.FilterButton.Icon = 'filter.png';
            app.SortKspaceButton.Icon = 'sort.gif';
            app.ReconstructButton.Icon = 'reconstruct.gif';
            app.ExportDCMButton.Icon = 'disk.png';
            app.ExportGIFButton.Icon = 'disk.png';
            app.ExportMRDButton.Icon = 'disk.png';
            app.ExitButton.Icon = 'exit.png';
            app.MovieStartButton.Icon = 'play.gif';
            app.NavStartButton.Icon = 'play.gif';
            app.TrajMovieStartButton.Icon = 'play.gif';
            app.TrajMovie3DStartButton.Icon = 'play.gif';
            app.MovieStopButton.Icon = 'stop.gif';
            app.NavStopButton.Icon = 'stop.gif';
            app.TrajMovieStopButton.Icon = 'stop.gif';
            app.TrajMovie3DStopButton.Icon = 'stop.gif';
            app.MoviecwButton.Icon = 'cw.png';
            app.MovieccwButton.Icon = 'ccw.png';
            app.StatusImage.ImageSource = 'statusokay.gif';
            app.StatusBackgroundImage.ImageSource = 'square.gif';

            % Define app colors
            app.brightred = 'Red';
            app.red = '#D95319';
            app.brightgreen = 'Green';
            app.green = '#6CB557';
            app.yellow = 'Yellow';
            app.orange = '#FDA400';
            app.blue = '#0073BE';
            app.black = 'Black';
            app.lightblue = '#DEE6EB';
            app.lightgrey = '#F0F0F0';

            app.guiBlack = [0.15,0.15,0.15];
            app.guiGrey = [0.94,0.94,0.94];
            app.guiMiddleGrey = [0.9,0.9,0.9];
            app.guiDarkGrey = [0.5,0.5,0.5];
            app.guiWhite = [1.0,1.0,1.0];
            app.guiBlackBlue = [0.02,0.13,0.3];
            
            % Set some plot properties
            app.CardiacNavFig.Toolbar.Visible = 'off';
            app.RespirationNavFig.Toolbar.Visible = 'off';
            app.RawNavFig.Toolbar.Visible = 'off';
            app.SpectrumFig.Toolbar.Visible = 'off';
            app.HeartRateTimeFig.Toolbar.Visible = 'off';
            app.RespRateTimeFig.Toolbar.Visible = 'off';
            app.MovieFig.Toolbar.Visible = 'off';
            app.TrajectoryFig.Toolbar.Visible = 'off';
            app.TrajectoryLargeFig.Toolbar.Visible = 'off';
            app.LcurveFig.Toolbar.Visible = 'off';
            app.LcurveModelErrorFig.Toolbar.Visible = 'off';

            % Switch off plot interactivity, except for movie plot
            app.CardiacNavFig.Interactions = [];
            app.RespirationNavFig.Interactions = [];
            app.RawNavFig.Interactions = [];
            app.SpectrumFig.Interactions = [];
            app.HeartRateTimeFig.Interactions = [];
            app.RespRateTimeFig.Interactions = [];
            app.MovieFig.Interactions = [zoomInteraction regionZoomInteraction];
            app.TrajectoryFig.Interactions = [];
            app.TrajectoryLargeFig.Interactions = [];
            app.LcurveFig.Interactions = [];
            app.LcurveModelErrorFig.Interactions = [];

            % Load sounds
            [app.warningSound,~] = audioread('Glass.aiff');
            [app.errorSound,~] = audioread('Ping.aiff');

            % Set initial colors
            app.ColorsButtonGroupSelectionChanged;

        end % SetAppLayoutPropertiesFcn


        % Species presets
        function SetSpeciesFcn(app)

            % Mouse preset settings
            if app.MouseButton.Value

                app.r.bandwidthHR = 60;
                app.HeartWidthEditField.Value = 60;
                app.r.bandwidthRR = 20;
                app.RespirationWidthEditField.Value = 40;
                app.r.respPercentage = 30;
                app.RespirationWindowEditField.Value = 30;
                app.MinHeartRateEditField.Value = 300;
                app.MaxHeartRateEditField.Value = 700;
                app.MinRespRateEditField.Value = 30;
                app.MaxRespRateEditField.Value = 90;
                app.FilterOrderEditField.Value = 4;
                app.RespShiftEditField.Value = 0;

            end

            % Rat preset settings
            if app.RatButton.Value

                app.r.bandwidthHR = 50;
                app.HeartWidthEditField.Value = 50;
                app.r.bandwidthRR = 20;
                app.RespirationWidthEditField.Value = 40;
                app.r.respPercentage = 30;
                app.RespirationWindowEditField.Value = 30;
                app.MinHeartRateEditField.Value = 200;
                app.MaxHeartRateEditField.Value = 500;
                app.MinRespRateEditField.Value = 30;
                app.MaxRespRateEditField.Value = 90;
                app.FilterOrderEditField.Value = 4;
                app.RespShiftEditField.Value = 0;
           
            end

            % Zebrafish preset settings
            if app.ZebraFishButton.Value

                app.r.bandwidthHR = 60;
                app.HeartWidthEditField.Value = 60;
                app.r.bandwidthRR = 20;
                app.RespirationWidthEditField.Value = 40;
                app.r.respPercentage = 30;
                app.RespirationWindowEditField.Value = 30;
                app.MinHeartRateEditField.Value = 60;
                app.MaxHeartRateEditField.Value = 300;
                app.MinRespRateEditField.Value = 10;
                app.MaxRespRateEditField.Value = 90;
                app.FilterOrderEditField.Value = 4;
                app.RespShiftEditField.Value = 0;
           
            end

        end % SetSpeciesFcn


        function SetRecoParametersFcn(app)

            % Settings for scout acquisition (e.g. 3 orthogonal slices)
            if app.ScoutButton.Value == 1

                app.RecoTypeDropDown.Value = 'cardiac';
                app.FramesEditField.Value = 1;
                if ~app.r.vfaDataFlag
                    app.DynamicsEditField.Value = 1;
                end
                app.SharingEditField.Value = 0;
                app.WVxyzEditField.Value = 0.001;
                app.TVxyzEditField.Value = 0.005;
                app.LLRxyzEditField.Value = 0.000;
                app.TVcineEditField.Value = 0.000;
                app.TVdynEditField.Value = 0.000;
                app.ESPIRiTCheckBox.Value = 0;
                if app.r.nr_coils > 2
                    app.ESPIRiTCheckBox.Value = 1;
                end
           
            end

            % Settings for systolic function, typical number of CINE frames = 12
            if app.SystolicFunctionButton.Value == 1

                app.RecoTypeDropDown.Value = 'cardiac';
                app.FramesEditField.Value = 12;
                if ~app.r.vfaDataFlag
                    app.DynamicsEditField.Value = 1;
                end
                app.SharingEditField.Value = 0;
                app.WVxyzEditField.Value = 0.001;
                app.TVxyzEditField.Value = 0.000;
                if contains(app.r.dataType,["3D","3Dp2roud","3Dute"])
                    app.TVcineEditField.Value = 0.010;
                    app.LLRxyzEditField.Value = 0.005;
                else
                    app.TVcineEditField.Value = 0.100;
                    app.LLRxyzEditField.Value = 0.005;
                end
                app.TVdynEditField.Value = 0.000;
                app.ESPIRiTCheckBox.Value = 0;
                if app.r.nr_coils > 2
                    app.ESPIRiTCheckBox.Value = 1;
                end

            end

            % Settings for diastolic function, typical number of CINE frames = 32
            if app.DiastolicFunctionButton.Value == 1

                app.RecoTypeDropDown.Value = 'cardiac';
                app.FramesEditField.Value = 32;
                if ~app.r.vfaDataFlag
                    app.DynamicsEditField.Value = 1;
                end
                app.SharingEditField.Value = 0;
                app.WVxyzEditField.Value = 0.001;
                app.TVxyzEditField.Value = 0.000;
                app.LLRxyzEditField.Value = 0.005;
                app.TVcineEditField.Value = 0.100;
                if contains(app.r.dataType,["3D","3Dp2roud","3Dute"])
                    app.TVcineEditField.Value = 0.010;
                    app.LLRxyzEditField.Value = 0.005;
                end
                app.TVdynEditField.Value = 0.000;
                app.ESPIRiTCheckBox.Value = 0;
                if app.r.nr_coils > 2
                    app.ESPIRiTCheckBox.Value = 1;
                end

            end

            % For reconstruction of respiratory motion
            if app.RespiratoryFunctionButton.Value == 1

                app.RecoTypeDropDown.Value = 'respiratory';
                app.FramesEditField.Value = 60;
                if ~app.r.vfaDataFlag
                    app.DynamicsEditField.Value = 1;
                end
                app.SharingEditField.Value = 0;
                app.WVxyzEditField.Value = 0.001;
                app.TVxyzEditField.Value = 0.000;
                app.LLRxyzEditField.Value = 0.005;
                app.TVcineEditField.Value = 0.100;
                if contains(app.r.dataType,["3D","3Dp2roud","3Dute"])
                    app.TVcineEditField.Value = 0.010;
                    app.LLRxyzEditField.Value = 0.005;
                end
                app.TVdynEditField.Value = 0.000;
                app.ESPIRiTCheckBox.Value = 0;
                if app.r.nr_coils > 2
                    app.ESPIRiTCheckBox.Value = 1;
                end

            end

            % For 'realtime' reconstruction of X frames / heartbeat
            if app.RealTimeFunctionButton.Value == 1

                app.RecoTypeDropDown.Value = 'realtime';
                app.FramesEditField.Value = 2;
                if ~app.r.vfaDataFlag
                    app.DynamicsEditField.Value = 1;
                end
                app.SharingEditField.Value = 3;
                app.WVxyzEditField.Value = 0.000;
                app.TVxyzEditField.Value = 0.01;
                app.LLRxyzEditField.Value = 0.05;
                app.TVcineEditField.Value = 0.001;
                app.TVdynEditField.Value = 0.200;
                app.ESPIRiTCheckBox.Value = 0;
                if app.r.nr_coils > 2
                    app.ESPIRiTCheckBox.Value = 1;
                end

                app.FilterButtonPushed;

            end

            % For variable flip-angle reconstruction for T1 mapping
            if app.VfaFunctionButton.Value == 1

                app.RecoTypeDropDown.Value = 'cardiac';
                app.FramesEditField.Value = 6;
                app.SharingEditField.Value = 0;
                app.WVxyzEditField.Value = 0.002;
                app.TVxyzEditField.Value = 0.000;
                app.LLRxyzEditField.Value = 0.005;
                app.TVcineEditField.Value = 0.010;
                app.TVdynEditField.Value = 0.000;
                app.ESPIRiTCheckBox.Value = 0;
                if app.r.nr_coils > 2
                    app.ESPIRiTCheckBox.Value = 1;
                end

            end

        end % SetRecoParametersFcn


        % ------------------------------------------
        % Image acquisition data parameters
        % ------------------------------------------

        % Set MRD paths
        function SetMRDimportPathFcn(app)

            % Try to find text file with mrd import path in app directory
            if app.AutoRecoCheckBox.Value
                
                if isdeployed
                    mrdPathFile = strcat(app.currentDir,filesep,'mrdpath.txt');
                else
                    mrdPathFile = 'mrdpath.txt';
                end
                
                if isfile(mrdPathFile)
                    try
                        folderName = fileread(mrdPathFile);
                        if exist(folderName, 'dir')
                            app.mrdImportPath = folderName;
                        end
                    catch
                        app.mrdImportPath = '';
                    end
                end
                
            end

            % On PC search for the path in the Windows registry
            if ispc
                try
                    app.mrdImportPath = winqueryreg('HKEY_LOCAL_MACHINE','Software\MRS\MRSClinicalUI\Scan','MrdDirectory');
                    app.mrdImportPath = [app.mrdImportPath,filesep];
                    app.TextMessage('MRD directory found in Windows registry ...');
                    app.TextMessage(strcat("MRD directory = ",app.mrdImportPath," ..."));
                    app.mrdAutoImportPath = app.mrdImportPath;
                catch
                    app.mrdImportPath = '';
                end
            end

            % If still nothing found, than empty the path names
            if isempty(app.mrdImportPath)
                app.mrdAutoImportPath = '';
            else
                app.mrdAutoImportPath = app.mrdImportPath;
            end

        end % SetMRDimportPathFcn


        % Define data parameters
        function DataParametersFcn(app)

            % Parameters
            app.r.dimz = size(app.r.data{1},2);                                     % nr of points in 3rd dimension (2D multi-slice or 3D)
            app.r.bandwidthHR = app.HeartWidthEditField.Value;                      % cardiac bandpass filter bandwidth, default = 100
            app.r.bandwidthRR = app.RespirationWidthEditField.Value;                % respiratory bandpass filter bandwidth, default = 50
            app.r.meanHeartRate = app.HeartEditField.Value;
            app.r.respPercentage = app.RespirationWindowEditField.Value;            % percentage of data discarded during respiration, default = 30%
            app.nrFrames = app.FramesEditField.Value;                               % default number of cardiac frames
            app.nrDynamics = app.DynamicsEditField.Value;                           % default number of dynamics
            app.validFilterFlag = false;                                            % reset flags
            app.validSenseMapFlag = false;
            app.validRecoFlag = false;
            app.validSortFlag = false;
            app.r.multiSliceFlag = false;
            app.r.multiDynamicFlag = false;
            
            % GUI parameters
            app.NavigatorEndEditField.Value = app.r.primaryNavigatorPoint;          % primary navigator point
            app.DiscardEditField.Value = app.r.nrNavPointsDiscarded;                % number of nav points discarded between navigator and echo
            app.NavigatorNRPointsEditField.Value = app.r.nrNavPointsUsed;           % number of nav points used
            app.StartEditField.Value = 1;                                           % reset the nav viewing start value
            app.RangeEditField.Value = 100;                                         % reset the nav viewing range
            app.StartSlider.Value = 1;                                              % reset the nav viewing start value slider
            app.RangeSlider.Value = 100;                                            % reset the nav viewing range slider
            app.navMovieSpeed = 10;                                                 % initial speed of the nav movie
            app.IncludeCheckBox.Value = 0;                                          % reset the include and exclude sliders
            app.ExcludeCheckBox.Value = 0;

            cla(app.TrajectoryFig);                                                 % clear trajectory figure

            switch app.r.dataType

                case {'2D','2Dms','3D','3Dp2roud'}

                    if app.DiscardEditField.Value > round((size(app.r.data{1},4) - app.r.primaryNavigatorPoint)/2)    % check that number of discard points is not bigger than 1/2 readout
                        app.DiscardEditField.Value = round((size(app.r.data{1},4) - app.r.primaryNavigatorPoint)/2);
                    end

                    app.r.dimx = size(app.r.data{1},4) - ...
                        app.r.primaryNavigatorPoint - app.r.nrNavPointsDiscarded;    % nr readout points (no_samples)
            
                    switch app.r.pe1_order

                        case 0

                            % Linear
                            app.TrajectoryViewField.Value = 'linear';
                            app.r.dimy = app.r.NO_VIEWS;                                                                % nr phase encoding steps (no_views)
                            app.r.nrKsteps = app.r.dimy;                                                                % nr k-space points per repetition = nr phase encoding steps
                            app.r.nrKlines = app.r.nr_repetitions*app.r.dimy*app.r.dimz;                                % total number of k-lines that are measured
                            app.r = app.r.getKspaceTrajectory(app);                                                     % get trajectory
                            
                            % Plot the trajectory
                            plot(app.TrajectoryFig, app.r.trajectory);                                                            
                            xlim(app.TrajectoryFig,[1 length(app.r.trajectory)-1]);
                            ylim(app.TrajectoryFig,[min(app.r.trajectory)-1 max(app.r.trajectory)+1]);
                       
                        case 2

                            % Zigzag
                            app.TrajectoryViewField.Value = 'zigzag';
                            app.r.dimy = app.r.NO_VIEWS;                                                                % nr phase encoding steps (no_views)
                            app.r.nrKsteps = app.r.dimy;                                                                % nr k-space points per repetition = nr phase encoding steps
                            app.r.nrKlines = app.r.nr_repetitions*app.r.dimy*app.r.dimz;                                % total number of k-lines that are measured
                            app.r = app.r.getKspaceTrajectory(app);                                                     % get trajectory
                            
                            % Plot the trajectory
                            plot(app.TrajectoryFig, app.r.trajectory);                                                            
                            xlim(app.TrajectoryFig,[1 length(app.r.trajectory)-1]);
                            ylim(app.TrajectoryFig,[min(app.r.trajectory)-1 max(app.r.trajectory)+1]);

                        case 3

                            % User defined
                            app.TrajectoryViewField.Value = 'user defined';
                            app.r.dimy = max(app.r.gp_var_mul)-min(app.r.gp_var_mul)+1;                                 % effective nr phase encoding steps (no_effective_views)
                            app.r.nrKsteps = app.r.NO_VIEWS;                                                            % nr k-space trajectory points (no_views) in table
                            app.r.nrKlines = app.r.nr_repetitions*app.r.nrKsteps*app.r.dimz;                            % total number of k-lines that are measured
                            app.r = app.r.getKspaceTrajectory(app);                                                     % get trajectory

                            % Plot the trajectory
                            plot(app.TrajectoryFig, app.r.trajectory);                                                            
                            xlim(app.TrajectoryFig,[1 length(app.r.trajectory)-1]);
                            ylim(app.TrajectoryFig,[min(app.r.trajectory)-1 max(app.r.trajectory)+1]);

                        case {4,5}

                            % P2ROUD acquisition, based on LUT file
                            app.TrajectoryViewField.Value = 'P2ROUD';
                            app.r.dimy = app.r.NO_VIEWS;                                                                % nr phase encoding steps (no_views)
                            app.r.nrKsteps = app.r.dimy;                                                                % nr k-space points per repetition = nr phase encoding steps
                            app.r.nrKlines = app.r.nr_repetitions*app.r.nrKsteps*app.r.dimz;                            % total number of k-lines that are measured
                            app.r = app.r.getKspaceTrajectory(app);                                                     % get trajectory
                     
                            % Plot the k-space filling trajectory in the app if available
                            if strcmp(app.r.dataType,'3Dp2roud')
                                scatter(app.TrajectoryFig, app.r.trajectory(1:2:end),app.r.trajectory(2:2:end),'s');            % plot the 3D trajectory
                                xlim(app.TrajectoryFig,[min(app.r.trajectory(1:2:end))-1,max(app.r.trajectory(1:2:end))+1]);
                                ylim(app.TrajectoryFig,[min(app.r.trajectory(2:2:end))-1,max(app.r.trajectory(2:2:end))+1]);
                            else
                                text(app.TrajectoryFig,-0.7,1,'MISSING');
                                text(app.TrajectoryFig,-2.4,-0.5,'TRAJECTORY');
                                xlim(app.TrajectoryFig,[-3 9]);
                                ylim(app.TrajectoryFig,[-3 3]);
                            end
                            
                            % Final check if the trajectory has the correct size matching the data
                            if length(app.r.trajectory) ~= 2*app.r.nrKsteps*app.r.dimz
                                app.TextMessage('ERROR: Missing or invalid trajectory file ...');
                                app.SetStatus(2);
                            end

                    end

                case {'2Dradial','2Dradialms'}

                    % Radial
                    app.TrajectoryViewField.Value = '2D radial';
                    app.r.pe1_order = 8;                                                % pe1_order = 8 for radial scanning
                    app.DiscardEditField.Value = 0;                                     % no discarded points
                    app.r.nrNavPointsDiscarded = 0;             
                    app.SharingEditField.Value = 0;                                     % view sharing not implemented for radial sequences
                    app.r.dimx = app.r.NO_SAMPLES;                                      % number of samples
                    app.r.dimy = app.r.dimx;                                            % y-dimension equal to number of samples
                    app.r.dimz = app.r.NO_SLICES;                                       % slices
                    app.r.nrKsteps = app.r.NO_VIEWS;                                    % number of radial spokes per repetition
                    app.r.nrKlines = app.r.nr_repetitions*app.r.nrKsteps*app.r.dimz;    % total number of spokes measured
                    app.r = app.r.getKspaceTrajectory(app);                             % get the k-space trajectory
                    app.RadialAngleEditField.Value = app.r.spoke_increment;             % radial angle
                    app.HalfCircleRadialButton.Value = 1;
                    if app.RadialAngleEditField.Value > 1
                        app.UserDefinedRadialButton.Value = 1;
                    end

                    % Set the parameters for the gradient calibration
                    app.GradDelayCalibrationCheckBox.Value = 1;
                    app.RingMethodCheckBox.Value = 1;
                    app.PhaseCorrectCheckBox.Value = 1;
                    app.CenterEchoCheckBox.Value = 0;
                    app.DensityCorrectCheckBox.Value = 1;

                    % Report on the trajectory in the app
                    text(app.TrajectoryFig,-0.7,1,'2D radial');
                    text(app.TrajectoryFig,-0.7,-0.5,'trajectory');
                    xlim(app.TrajectoryFig,[-3 9]);
                    ylim(app.TrajectoryFig,[-3 3]);

                case '3Dute'

                    % 3D UTE
                    app.TrajectoryViewField.Value = '3D UTE';
                    app.r.pe1_order = 9;                                % pe1_order = 9 for 3D UTE
                    app.DiscardEditField.Value = 0;                     % no discarded points
                    app.r.nrNavPointsDiscarded = 0; 
                    app.r.dimx = app.r.NO_SAMPLES;                      % number of samples
                    app.r.dimy = app.r.dimx;                            % y-dimension equal to number of samples
                    app.r.dimz = app.r.dimx;                            % z-dimension equal to number of samples
                    app.r.nrKsteps = app.r.NO_SAMPLES;                  % number of points in a radial spoke
                    app.r.nrKlines = app.r.nr_repetitions;              % number of total spokes
                    app.r = app.r.getKspaceTrajectory(app);             % get trajectory
                    
                    % Set the parameters for the gradient calibration
                    app.GradDelayCalibrationCheckBox.Value = 0;
                    app.RingMethodCheckBox.Value = 0;
                    app.PhaseCorrectCheckBox.Value = 1;
                    app.CenterEchoCheckBox.Value = 0;
                    app.DensityCorrectCheckBox.Value = 1;

                    % Report on the trajectory in the app
                    text(app.TrajectoryFig,-0.2,1,'3D UTE');
                    text(app.TrajectoryFig,-0.7,-0.5,'trajectory');
                    xlim(app.TrajectoryFig,[-3 9]);
                    ylim(app.TrajectoryFig,[-3 3]);

            end

            % Reset include / exclude window
            app.r.includeWindow = ones(app.r.nrKlines,1);
            app.r.excludeWindow = ones(app.r.nrKlines,1);

            % Total acquisition time in seconds
            app.acqDur = app.r.nrKlines*app.r.TR/1000;

            % Display the acquisition parameters in the app
            app.NxViewField.Value = app.r.dimx;
            app.NyViewField.Value = app.r.dimy;
            app.NzViewField.Value = app.r.dimz;
            app.NcViewField.Value = app.r.nr_coils;
            app.RepetitionsViewField.Value = app.r.nr_repetitions;
            app.FieldOfViewViewField.Value = app.r.FOV;
            app.NrKlinesViewField.Value = app.r.nrKlines;
            app.TRViewField.Value = app.r.TR;
            app.TEViewField.Value = app.r.TE;
            app.ScanTimeViewField.Value = string(duration(seconds(app.acqDur),'Format','hh:mm:ss.SSS')); 
            app.TimeDynViewField.Value = string(duration(seconds(app.acqDur/app.DynamicsEditField.Value),'Format','hh:mm:ss.SSS'));
            if app.r.VFA_size == 0
                app.FlipAnglesViewField.Value = num2str(app.r.alpha);
            else
                app.FlipAnglesViewField.Value = num2str(app.r.VFA_angles);
            end

            switch app.r.dataType
                case {'3D','3Dp2roud'}
                    app.TrajectorySizeViewField.Value = app.r.nrKsteps*app.r.dimz;
                otherwise
                    app.TrajectorySizeViewField.Value = app.r.nrKsteps;
            end

            % Geometry parameters
            app.FieldOfViewViewField2.Value = app.r.FOV;
            app.AspectRatioViewField.Value = app.r.aspectratio;
            app.SliceThicknessViewField.Value = app.r.SLICE_THICKNESS;
            app.SliceGapViewField.Value = app.r.SQLsliceGap;
            app.AspectRatioViewField.Value = app.r.aspectratio;
            app.PhaseOrientationViewField.Value = app.r.PHASE_ORIENTATION;
            app.OffsetXViewField.Value = app.r.SQLoffsetX;
            app.OffsetYViewField.Value = app.r.SQLoffsetY;
            app.OffsetZViewField.Value = app.r.SQLoffsetZ;
            app.AngleXViewField.Value = app.r.SQLangleX;
            app.AngleYViewField.Value = app.r.SQLangleY;
            app.AngleZViewField.Value = app.r.SQLangleZ;
      
            % Define the ranges of the display range sliders
            app.StartEditField.Limits = [1, app.r.nrKlines-1];
            app.StartSlider.Limits = [1, app.r.nrKlines-1];
            app.StartEditField.Value = round(app.r.nrKlines/2);
            app.StartSlider.Value = app.StartEditField.Value;
            maxRange = 2000;
            if maxRange > (round(app.r.nrKlines)-1)
                maxRange = (round(app.r.nrKlines)-1);
            end
            app.RangeEditField.Limits = [1 maxRange];
            app.RangeSlider.Limits = [1 maxRange];
            tmpRange = 600; % preferred range, but smaller if less data available
            tmpRange(tmpRange > (round(app.r.nrKlines/2)-1) ) = round(app.r.nrKlines/4);
            app.RangeEditField.Value = tmpRange;
            app.RangeSlider.Value = app.RangeEditField.Value;   

            % Filters
            %app.PCACheckBox.Value = 0;
            %app.PCAwindowEditField.Value = 8;
            app.RingRmCheckBox.Value = 0;

            % Data limits
            app.IncludeStartSlider.Limits = [0,app.acqDur];
            app.IncludeEndSlider.Limits = [0,app.acqDur];
            app.IncludeStartSlider.Value = 0;
            app.IncludeEndSlider.Value = app.acqDur;
            app.ExcludeStartSlider.Limits = [0,app.acqDur];
            app.ExcludeEndSlider.Limits = [0,app.acqDur];
            app.ExcludeStartSlider.Value = 0;
            app.ExcludeEndSlider.Value = app.acqDur;
            app.SenseMapSpinner1.Value = 1;
            app.SenseMapSpinner2.Value = 1;
            app.ESPIRiTCheckBox.Value = 0;
            app.WindowScaleSlider.Limits = [0, app.r.maxImageValue];
            app.LevelScaleSlider.Limits = [0, app.r.maxImageValue];

            % Case of multicoil data
            if app.r.multiCoilFlag
                if app.CoilSpinner.Value > app.r.nr_coils
                    app.CoilSpinner.Value.Value = 1;
                    app.MovieCoilSpinner.Value = 1;
                end
                app.CoilSpinner.Limits = [1, app.r.nr_coils];
                app.MovieCoilSpinner.Limits = [1, app.r.nr_coils];
            else
                app.CoilSpinner.Value = 1;
                app.CoilSpinner.Limits = [1, 2];
                app.MovieCoilSpinner.Value = 1;
                app.MovieCoilSpinner.Limits = [1, 2];
            end

            % Case of variable flip-angle data
            if app.r.vfaDataFlag
                app.DynamicsEditFieldLabel.Text = '# flip-angles';
                app.MovieDynLabel.Text = 'flip-angle';
                app.MovieDynEditFieldLabel.Text = 'flip-angle';
                app.DynamicsEditField.Editable = 'off';
                app.DynamicsEditField.Value = app.r.VFA_size;
                app.nrDynamics = app.r.VFA_size;
            else
                app.DynamicsEditFieldLabel.Text = '# dynamics';
                app.MovieDynLabel.Text = 'dynamic';
                app.MovieDynEditFieldLabel.Text = 'dynamic';
                app.DynamicsEditField.Editable = 'on';
                app.DynamicsEditField.Value = 1;
                app.nrDynamics = 1;
            end

            % Set initial reconstruction parameters, based on the data
            if app.AutoGuessRecoCheckBox.Value
                app.r = app.r.guessRecoType;
                switch app.r.recoGuess
                    case 'scout'
                        app.ScoutButton.Value = 1;
                    case 'systolic function'
                        app.SystolicFunctionButton.Value = 1;
                    case 'diastolic function'
                        app.DiastolicFunctionButton.Value = 1;
                    case 'variable flip-angle'
                        app.VfaFunctionButton.Value = 1;
                end
                app.SetRecoParametersFcn;
            end

        end % DataParametersFcn


        % ------------------------------------------
        % Other functions
        % ------------------------------------------


        % Calculates the power spectrum of the raw navigator signal within the boundaries of the data-window
        function CalculateNavigatorSpectrumFcn(app)

            % Determine the power spectrum of the navigator
            app.r.physioFilterSettings = [app.MinHeartRateEditField.Value app.MaxHeartRateEditField.Value app.MinRespRateEditField.Value app.MaxRespRateEditField.Value app.FilterOrderEditField.Value app.RespShiftEditField.Value];
            app.r = app.r.determinePowerSpectrumPCA;

            % Update the detected heart rate and respiration rate fields
            app.HeartEditField.Value = app.r.detectedHR;
            app.RespirationEditField.Value = app.r.detectedRR;

            % Heart rate window cannot be larger than the heart rate
            app.HeartWidthEditField.Value(app.HeartWidthEditField.Value > app.HeartEditField.Value) = max([app.HeartWidthEditField.Limits(1) app.HeartEditField.Value]);

            % Repiration rate window cannot be larger than the respiration rate
            app.RespirationWidthEditField.Value(app.RespirationWidthEditField.Value > app.RespirationEditField.Value) = max([app.RespirationWidthEditField.Limits(1) app.RespirationEditField.Value]);
      
        end % CalculateNavigatorSpectrumFcn


        % ------------------------------------------
        % Plotting figures and images
        % ------------------------------------------


        % Plot the raw navigator signals
        function PlotRawNavigatorFcn(app)

            % Display x-range
            startPoint = app.StartEditField.Value;
            endPoint = app.StartEditField.Value+app.RangeEditField.Value-1;

            % Extract part of the navigator that will be shown
            trace1 = app.r.navAmplitude{app.CoilSpinner.Value}(startPoint:endPoint);

            % Display y-range
            maxPlot = max(trace1);
            minPlot = min(trace1);

            % Plot the navigator
            plot(app.RawNavFig, trace1,'Color',app.blue);
            xlim(app.RawNavFig, [0 length(trace1)+1]);
            ylim(app.RawNavFig, [1.1*minPlot 1.1*maxPlot+1]);
            drawnow;

        end % PlotRawNavigatorFcn


        % Plot the navigator with cardiac filtering
        function PlotCardiacNavigatorFcn(app)

            % Clear the image
            cla(app.CardiacNavFig);
            hold(app.CardiacNavFig,'on');

            if app.validFilterFlag

                % Display x-range
                startPoint = app.StartEditField.Value;
                endPoint = app.StartEditField.Value+app.RangeEditField.Value-1;

                % Extract part of the navigator that will be shown
                trace1 = app.r.PCANav(startPoint:endPoint);
                trace2 = app.r.heartNav(startPoint:endPoint);

                % Display y-range
                maxPlot = 1.1*max(trace1);
                minPlot = 1.1*min(trace1);

                % Scale the filtered signal for viewing purposes
                trace2 = 0.25*max(trace1)*trace2;

                % Show the detected trigger points (only when movie is not running)
                if app.stopNavMovieFlag
                    triggerPoints = app.r.heartTrigPoints((app.r.heartTrigPoints>=startPoint) & (app.r.heartTrigPoints<=endPoint));
                    if length(triggerPoints) < 40
                        for i = 1:length(triggerPoints)
                            win=plot(app.CardiacNavFig,[triggerPoints(i)-startPoint+1,triggerPoints(i)-startPoint+1],[minPlot,maxPlot],'black','LineWidth',1);
                            win.Color(4) = 0.2; % transparancy
                        end
                    end
                end

                % Plot the raw navigator
                plot(app.CardiacNavFig, trace1,'Color',app.blue);

                % Plot the navigator filtered for cardiac motion
                plot(app.CardiacNavFig, trace2,'LineWidth',1,'Color',app.red);

                % Set plot axes
                xlim(app.CardiacNavFig, [0 length(trace1)+1]);
                ylim(app.CardiacNavFig, [minPlot maxPlot]);

            end

            hold(app.CardiacNavFig,'off');
            drawnow limitrate;

        end % PlotCardiacNavigatorFcn


        % Plot the navigator with respiratory filtering
        function PlotRespirationNavigatorFcn(app)

            % Clear the image
            cla(app.RespirationNavFig);
            hold(app.RespirationNavFig,'on');

            if app.validFilterFlag

                % Display x-range
                startpoint = app.StartEditField.Value;
                endpoint = app.StartEditField.Value+app.RangeEditField.Value-1;

                % Extract part of the navigator that will be shown
                trace1 = app.r.PCANav(startpoint:endpoint);
                trace2 = app.r.respNav(startpoint:endpoint);

                % Scale the filtered signal for viewing purposes
                trace2 = 0.25*max(trace1)*trace2;

                % Plot respiratory window masks in transparent red
                maxplt = 1.1*max(trace1);
                minplt = 1.1*min(trace1);
                if app.stopNavMovieFlag
                    steps = 1;
                else
                    steps = 4;
                end
                for i=startpoint:steps:endpoint
                    if app.r.respWindow(i)==1
                        win=line(app.RespirationNavFig,[i-app.StartEditField.Value+1;i-app.StartEditField.Value+1],[app.r.respWindow(i)*minplt;app.r.respWindow(i)*maxplt],'Color',app.red,'LineWidth',1);
                        win.Color(4) = 0.1*steps; % transparancy
                    end
                end

                % Show the detected trigger points (only when movie is not running)
                if app.stopNavMovieFlag
                    triggerpoints = app.r.respTrigPoints((app.r.respTrigPoints>=startpoint) & (app.r.respTrigPoints<=endpoint));
                    if length(triggerpoints) < 10
                        for i = 1:length(triggerpoints)
                            win=plot(app.RespirationNavFig,[triggerpoints(i)-startpoint+1,triggerpoints(i)-startpoint+1],[minplt,maxplt],'black','LineWidth',1);
                            win.Color(4) = 0.2; % transparancy
                        end
                    end
                end

                % Plot raw navigator
                plot(app.RespirationNavFig, trace1,'Color',app.blue);

                % Plot navigator signal filtered for respiratory motion
                plot(app.RespirationNavFig, trace2,'LineWidth',1,'Color',app.red);

                % Set plot axes
                xlim(app.RespirationNavFig, [0 length(trace1)]);
                ylim(app.RespirationNavFig, [minplt maxplt]);

            end

            hold(app.RespirationNavFig,'off');
            drawnow limitrate;

        end % PlotRespirationNavigatorFcn


        % Plot the time-heartrate and time-respirationrate plots
        function PlotRatesFcn(app)

            % Clear the images
            cla(app.HeartRateTimeFig);
            cla(app.RespRateTimeFig);

            if app.validFilterFlag

                hr = app.r.heartRateTime;
                hrf = app.r.heartRateTimeFiltered;
                resp = app.r.respRateTime;
                respf = app.r.respRateTimeFiltered;

                % Y-scale
                maxHeart = round(2*app.r.meanHeartRate);
                maxResp = round(2*app.r.meanRespRate);
                maxHeart(maxHeart<100) = 100;
                maxResp(maxResp<50) = 50;

                % Show the time-heartrate plot
                hold(app.HeartRateTimeFig,'on');
                plot(app.HeartRateTimeFig, app.r.TR*app.r.heartTrigPoints(1:end-1)'/1000, hr,'.','Color',app.blue);              % heart rate
                plot(app.HeartRateTimeFig, app.r.TR*app.r.heartTrigPoints(1:end-1)'/1000, hrf,'LineWidth',2,'Color',app.red);    % trend line
                axis(app.HeartRateTimeFig,[0 app.r.TR*app.r.heartTrigPoints(end)/1000 0 maxHeart]);
                hold(app.HeartRateTimeFig,'off');
               
                % Show the time-respirationrate plot
                hold(app.RespRateTimeFig,'on');
                plot(app.RespRateTimeFig, app.r.TR*app.r.respTrigPoints(1:end-1)'/1000,resp,'.','Color',app.blue);               % respiration rate
                plot(app.RespRateTimeFig, app.r.TR*app.r.respTrigPoints(1:end-1)'/1000,respf,'LineWidth',2,'Color',app.red);     % trend line
                axis(app.RespRateTimeFig,[0 app.r.TR*app.r.respTrigPoints(end)/1000 0 maxResp]);
                hold(app.RespRateTimeFig,'off');
             
                % Plot the dynamic or multi-slice windows
                app.PlotDynamicsWindowsFcn;
                app.PlotMultiSliceWindowsFcn;

            end

        end % PlotRatesFcn


        % Plot the separation into dynamics in the time-heart-rate and time-respiration-rate plots
        function PlotDynamicsWindowsFcn(app)

            % Delete previous windows
            delete(app.dPlot1);
            delete(app.dPlot2);
            delete(app.dPlot1Txt);
            delete(app.dPlot2Txt);

            if app.DynamicsEditField.Value > 1

                % Y-scale
                maxHeart = round(2*app.r.meanHeartRate);
                maxResp = round(2*app.r.meanRespRate);
                maxHeart(maxHeart<100) = 100;
                maxResp(maxResp<50) = 50;

                if app.r.vfaDataFlag
                    dynamicLabel = 'fa';
                else
                    dynamicLabel = 'd';
                end

                hold(app.HeartRateTimeFig,'on');

                % Show shaded areas in the time-heartrate plot
                heartRange = app.r.TR*app.r.heartTrigPoints(end-1)/1000;
                step = heartRange / app.DynamicsEditField.Value;
                pgon = polyshape();
                for i = 1:2:app.DynamicsEditField.Value
                    xStart = (i-1)*step;
                    xEnd = i*step;
                    pgon = union(pgon,polyshape([xStart xStart xEnd xEnd],[5 maxHeart-5 maxHeart-5 5]));
                end
                app.dPlot1 = plot(app.HeartRateTimeFig, pgon,'FaceColor',app.blue,'FaceAlpha',0.15,'LineStyle','none');

                % Add dynamic frame numbers
                if app.DynamicsEditField.Value < 15
                    idx = 1:app.DynamicsEditField.Value;
                    txtPosX = (idx - 0.5 - app.DynamicsEditField.Value / 25)*step;
                    txtPosY = 0.95*maxHeart*ones(app.DynamicsEditField.Value,1)';
                    txtStr = strcat(dynamicLabel,num2str(idx(:)));
                    app.dPlot1Txt = text(app.HeartRateTimeFig,txtPosX,txtPosY,txtStr,'FontSize',10,'Color',app.blue);
                end

                hold(app.HeartRateTimeFig,'off');

                hold(app.RespRateTimeFig,'on');

                % Show in the time-respirationrate plot
                heartRange = app.r.TR*app.r.respTrigPoints(end-1)/1000;
                step = heartRange / app.DynamicsEditField.Value;
                pgon = polyshape();
                for i = 1:2:app.DynamicsEditField.Value
                    xStart = (i-1)*step;
                    xEnd = i*step;
                    pgon = union(pgon,polyshape([xStart xStart xEnd xEnd],[0.5 maxResp-0.5 maxResp-0.5 0.5]));
                end
                app.dPlot2 = plot(app.RespRateTimeFig, pgon,'FaceColor',app.blue,'FaceAlpha',0.15,'LineStyle','none');

                % Add dynamic frame numbers
                if app.DynamicsEditField.Value < 15
                    idx = 1:app.DynamicsEditField.Value;
                    txtPosX = (idx - 0.5 - app.DynamicsEditField.Value / 25)*step;
                    txtPosY = 0.95*maxResp*ones(app.DynamicsEditField.Value,1)';
                    txtStr = strcat(dynamicLabel,num2str(idx(:)));
                    app.dPlot2Txt = text(app.RespRateTimeFig,txtPosX,txtPosY,txtStr,'FontSize',10,'Color',app.blue);
                end

                hold(app.RespRateTimeFig,'off');

            end

        end % PlotDynamicsWindowsFcn


        % Plot the separation into slices for the time-heartrate and time-respiratiorate plots
        function PlotMultiSliceWindowsFcn(app)

            % Delete previous windows
            delete(app.msPlot1);
            delete(app.msPlot2);
            delete(app.msPlot1Txt);
            delete(app.msPlot2Txt);

            if strcmp(app.r.dataType,'2Dms') || strcmp(app.r.dataType,'2Dradialms')

                % Y-scale
                maxHeart = round(2*app.r.meanHeartRate);
                maxResp = round(2*app.r.meanRespRate);
                maxHeart(maxHeart<100) = 100;
                maxResp(maxResp<50) = 50;

                hold(app.HeartRateTimeFig,'on');

                % Show shaded areas in the time-heartrate plot
                heartRange = app.r.TR*app.r.heartTrigPoints(end-1)/1000;
                step = heartRange / app.NzViewField.Value;
                pgon = polyshape();
                for i = 1:2:app.NzViewField.Value
                    xStart = (i-1)*step;
                    xEnd = i*step;
                    pgon = union(pgon,polyshape([xStart xStart xEnd xEnd],[5 maxHeart-5 maxHeart-5 5]));
                end
                app.msPlot1 = plot(app.HeartRateTimeFig, pgon,'FaceColor',app.blue,'FaceAlpha',0.15,'LineStyle','none');

                % Add dynamic frame numbers
                if app.NzViewField.Value < 15
                    idx = 1:app.NzViewField.Value;
                    txtPosX = (idx - 0.5 - app.NzViewField.Value / 50)*step;
                    txtPosY = 0.95*maxHeart*ones(app.NzViewField.Value,1)';
                    txtStr = strcat('s',num2str(idx(:)));
                    app.msPlot1Txt = text(app.HeartRateTimeFig,txtPosX,txtPosY,txtStr,'FontSize',10,'Color',app.blue);
                end

                hold(app.HeartRateTimeFig,'off');

                hold(app.RespRateTimeFig,'on');

                % Show in the time-respirationrate plot
                heartRange = app.r.TR*app.r.respTrigPoints(end-1)/1000;
                step = heartRange / app.NzViewField.Value;
                pgon = polyshape();
                for i = 1:2:app.NzViewField.Value
                    xStart = (i-1)*step;
                    xEnd = i*step;
                    pgon = union(pgon,polyshape([xStart xStart xEnd xEnd],[0.5 maxResp-0.5 maxResp-0.5 0.5]));
                end
                app.msPlot2 = plot(app.RespRateTimeFig, pgon,'FaceColor',app.blue,'FaceAlpha',0.15,'LineStyle','none');

                % Add dynamic frame numbers
                if app.NzViewField.Value < 15
                    idx = 1:app.NzViewField.Value;
                    txtPosX = (idx - 0.5 - app.NzViewField.Value / 50)*step;
                    txtPosY = 0.95*maxResp*ones(app.NzViewField.Value,1)';
                    txtStr = strcat('s',num2str(idx(:)));
                    app.msPlot2Txt = text(app.RespRateTimeFig,txtPosX,txtPosY,txtStr,'FontSize',10,'Color',app.blue);
                end

                hold(app.RespRateTimeFig,'off');

            end

        end % PlotMultiSliceWindowsFcn


        % Plot windows in time-heartrate and time-respirationrate plots which indicate the range of data shown in the navigator plots
        function PlotRateLimitsFcn(app)

            % Delete previous windows
            delete(app.cardRateWindow);
            delete(app.respRateWindow);

            % Start and ending of the window
            xStart = app.StartEditField.Value*app.r.TR/1000;
            xEnd = xStart + app.RangeEditField.Value*app.r.TR/1000;

            % Y-scale
            maxHeart = round(2*app.r.meanHeartRate);
            maxResp = round(2*app.r.meanRespRate);
            maxHeart(maxHeart<100) = 100;
            maxResp(maxResp<50) = 50;

            % Time-heartrate plot navigator inspection window
            hold(app.HeartRateTimeFig,'on');
            pgon = polyshape([xStart xStart xEnd xEnd],[5 maxHeart-5 maxHeart-5 5]);
            app.cardRateWindow = plot(app.HeartRateTimeFig, pgon,'FaceColor','black','FaceAlpha',0.25,'LineStyle','none');
            hold(app.HeartRateTimeFig,'off');

            % Time-respirationrate plot navigator inspection window
            hold(app.RespRateTimeFig,'on');
            pgon = polyshape([xStart xStart xEnd xEnd],[0.5 maxResp-0.5 maxResp-0.5 0.5]);
            app.respRateWindow = plot(app.RespRateTimeFig, pgon,'FaceColor','black','FaceAlpha',0.25,'LineStyle','none');
            hold(app.RespRateTimeFig,'off');

        end % PlotRateLimitsFcn


        % Plot the data window in time-heartrate and time-respirationrate plots
        function PlotDataWindowFcn(app)

            % Delete previous windows
            delete(app.dWin1);
            delete(app.dWin2);
            delete(app.dWin3);
            delete(app.dWin4);

            % Y-scale
            maxHeart = round(2*app.r.meanHeartRate);
            maxResp = round(2*app.r.meanRespRate);
            maxHeart(maxHeart<100) = 100;
            maxResp(maxResp<50) = 50;

            if app.IncludeCheckBox.Value == true

                % Time-heartrate plot window
                hold(app.HeartRateTimeFig,'on');
                pgon = polyshape([app.IncludeStartSlider.Value app.IncludeStartSlider.Value app.IncludeEndSlider.Value app.IncludeEndSlider.Value],[5 maxHeart-5 maxHeart-5 5]);
                app.dWin1 = plot(app.HeartRateTimeFig, pgon,'FaceColor',app.brightgreen,'FaceAlpha',0.15,'LineStyle','none');
                axis(app.HeartRateTimeFig,[0 app.r.TR*app.r.heartTrigPoints(end)/1000 0 maxHeart]);
                hold(app.HeartRateTimeFig,'off');
             
                % Time-respirationrate plot window
                hold(app.RespRateTimeFig,'on');
                pgon = polyshape([app.IncludeStartSlider.Value app.IncludeStartSlider.Value app.IncludeEndSlider.Value app.IncludeEndSlider.Value],[0.5 maxResp-0.5 maxResp-0.5 0.5]);
                app.dWin2 = plot(app.RespRateTimeFig, pgon,'FaceColor',app.brightgreen,'FaceAlpha',0.15,'LineStyle','none');
                axis(app.RespRateTimeFig,[0 app.r.TR*app.r.respTrigPoints(end)/1000 0 maxResp]);
                hold(app.RespRateTimeFig,'off');
                drawnow;

            end

            if app.ExcludeCheckBox.Value == true

                % Time-heartrate plot window
                hold(app.HeartRateTimeFig,'on');
                pgon = polyshape([app.ExcludeStartSlider.Value app.ExcludeStartSlider.Value app.ExcludeEndSlider.Value app.ExcludeEndSlider.Value],[5 maxHeart-5 maxHeart-5 5]);
                app.dWin3 = plot(app.HeartRateTimeFig, pgon,'FaceColor',app.red,'FaceAlpha',0.15,'LineStyle','none');
                axis(app.HeartRateTimeFig,[0 app.r.TR*app.r.heartTrigPoints(end)/1000 0 maxHeart]);
                hold(app.HeartRateTimeFig,'off');
              
                % Time-respirationrate plot window
                hold(app.RespRateTimeFig,'on');
                pgon = polyshape([app.ExcludeStartSlider.Value app.ExcludeStartSlider.Value app.ExcludeEndSlider.Value app.ExcludeEndSlider.Value],[0.5 maxResp-0.5 maxResp-0.5 0.5]);
                app.dWin4 = plot(app.RespRateTimeFig, pgon,'FaceColor',app.red,'FaceAlpha',0.15,'LineStyle','none');
                axis(app.RespRateTimeFig,[0 app.r.TR*app.r.respTrigPoints(end)/1000 0 maxResp]);
                hold(app.RespRateTimeFig,'off');
                drawnow;

            end

            % Set the acquisition parameters
            app.NrKlinesViewField.Value = nnz(app.r.includeWindow.*app.r.excludeWindow);
            app.RepetitionsViewField.Value = round(app.NrKlinesViewField.Value/app.TrajectorySizeViewField.Value);
            app.ScanTimeViewField.Value = string(duration(seconds(app.NrKlinesViewField.Value*app.r.TR/1000),'Format','hh:mm:ss.SSS'));
            app.TimeDynViewField.Value = string(duration(seconds(app.acqDur/app.DynamicsEditField.Value),'Format','hh:mm:ss.SSS'));

        end % PlotDataWindowFcn


        % Plot the power spectrum of the raw navigator signal
        function PlotNavigatorSpectrumFcn(app)

            % Limits
            startFreq = find(app.r.frequency > 10,1);
            endFreq   = find(app.r.frequency > 800,1);

            % Linear or log plot
            if strcmp(app.LogLinButton.Text,'lin')
                intensity = app.r.powerSpectrum;
            else
                intensity = log(app.r.powerSpectrum);
            end

            % Set the x and y limits of the plot
            yMax = 1.3*max(intensity(startFreq:endFreq));
            yMin = -0.05*yMax;
            if isinf(yMax) || isinf(yMin)
                yMin =0;
                yMax = 1;
            end
            yMax(yMax<yMin) = yMin+1;
            cla(app.SpectrumFig);
            hold(app.SpectrumFig,'on');
            xlim(app.SpectrumFig, [10 800]);
            ylim(app.SpectrumFig, [yMin, yMax+1]);

            % Plot the spectrum
            plot(app.SpectrumFig,app.r.frequency,intensity,'Color',app.blue);

            % Heart and respiration bandwidth values
            app.r.bandwidthHR = app.HeartWidthEditField.Value;
            app.r.bandwidthRR = app.RespirationWidthEditField.Value;

            % Plot the butter filter for cardiac motion filtering
            flt = app.r.filterShape(app, 'heart');
            area(app.SpectrumFig,flt(:,1),0.95*yMax*flt(:,2),'FaceColor',app.brightgreen,'FaceAlpha',0.1,'EdgeColor',app.brightgreen,'LineStyle', 'none');
            plot(app.SpectrumFig,[app.r.detectedHR,app.r.detectedHR],[0,yMax],'Color',app.brightgreen,'LineWidth',1,'LineStyle','--');
            text(app.SpectrumFig,app.r.detectedHR+10,0.9*yMax,[num2str(app.r.detectedHR),' bpm']);

            % Plot the butter filter for the repiration motion filtering
            try
                flt = app.r.filterShape(app, 'resp');
                area(app.SpectrumFig,flt(:,1),0.95*yMax*flt(:,2),'FaceColor',app.brightgreen,'FaceAlpha',0.1,'EdgeColor',app.brightgreen,'LineStyle', 'none');
                plot(app.SpectrumFig,[app.r.detectedRR,app.r.detectedRR],[0,yMax],'Color',app.brightgreen,'LineWidth',1,'LineStyle','--');
                text(app.SpectrumFig,app.r.detectedRR+10,0.9*yMax,[num2str(app.r.detectedRR),' bpm']);
            catch ME
                app.TextMessage(ME.message);
            end

            hold(app.SpectrumFig,'off');
            drawnow;

        end % PlotNavigatorSpectrumFcn


        % Plot the current movie frame
        function PlotMovieFrameFcn(app)

            try

                % Delete color bar
                delete(app.cbar);
               
                if app.MovieButton.Value == 1

                    % MRI movie
                    im = squeeze(app.r.movieApp(app.MovieFrameEditField.Value,:,:,app.MovieSliceEditField.Value,app.MovieDynEditField.Value));

                    % Rotate the image (cc and ccw rotation button)
                    for i=1:app.imageRot
                        im = rot90(im);
                    end

                    % Size of the image
                    [xl,yl] = size(im);
                    recoAspectRatio = (1/app.r.aspectratio)*yl/xl;
                    if app.r.PHASE_ORIENTATION
                        im = rot90(im,-1);
                        [xl,yl] = size(im);
                        recoAspectRatio = app.r.aspectratio*yl/xl;
                    end
                    
                    % Window and Level
                    if app.AutoWLButton.Value
                        im1 = squeeze(app.r.movieApp(:,:,:,app.MovieSliceEditField.Value,app.MovieDynEditField.Value));
                        maxIm = max(im1(:));
                        app.level = 0.4*maxIm;
                        app.LevelScaleSlider.Value = double(app.level);
                        app.window = 0.8*maxIm;
                        app.WindowScaleSlider.Value = double(app.window);
                    end
                    im = (1/app.window)*(im - app.level + app.window/2);
                    im(im<0) = 0;
                    im(im>1) = 1;

                    app.moviePlot = imshow(rot90(im),[0 1],'Colormap',gray,'Interpolation','bilinear','InitialMagnification','fit','Parent',app.MovieFig);

                    daspect(app.MovieFig,[1 recoAspectRatio 1]);
                    xlim(app.MovieFig,[0, xl + 1]);
                    ylim(app.MovieFig,[0, yl + 1]);

                    % Plot orientation labels
                    if app.orientationsFlag
                        app.OrientationLabel1.Text = app.r.orientationLabels(1);
                        app.OrientationLabel2.Text = app.r.orientationLabels(2);
                        app.OrientationLabel3.Text = app.r.orientationLabels(3);
                        app.OrientationLabel4.Text = app.r.orientationLabels(4);
                    else
                        app.OrientationLabel1.Text = '';
                        app.OrientationLabel2.Text = '';
                        app.OrientationLabel3.Text = '';
                        app.OrientationLabel4.Text = '';
                    end

                end

                % Averages
                if app.AveragesButton.Value == 1

                    maxScale = round(max(app.r.kSpaceAvg(:)));
                    c = [0 0 0 ; summer(maxScale)];

                    im = squeeze(app.r.kSpaceAvg(app.MovieFrameEditField.Value,:,:,app.MovieSliceEditField.Value,app.MovieDynEditField.Value));

                    % Size of the image
                    [xl,yl] = size(im);
                    if app.r.PHASE_ORIENTATION
                        im = rot90(im,-1);
                        [xl,yl] = size(im);
                    end
                    recoAspectRatio = yl/xl;

                    app.moviePlot = imshow(rot90(im),[0,maxScale],'ColorMap',c,'Parent',app.MovieFig);
                    app.cbar = colorbar(app.MovieFig,'location','eastoutside','TickLabelsMode','auto','FontSize',12);
                    
                    daspect(app.MovieFig,[1 recoAspectRatio 1]);
                    xlim(app.MovieFig,[0, xl + 1]);
                    ylim(app.MovieFig,[0, yl + 1]);

                end

                % Raw k-space
                if app.kSpaceButton.Value == 1

                    im = squeeze(abs(app.r.kSpace{app.MovieCoilSpinner.Value}(app.MovieFrameEditField.Value,:,:,app.MovieSliceEditField.Value,app.MovieDynEditField.Value)));
                    
                    % Size of the image
                    [xl,yl] = size(im);
                    if app.r.PHASE_ORIENTATION
                        im = rot90(im,-1);
                        [xl,yl] = size(im);
                    end
                    recoAspectRatio = yl/xl;
                    
                    % Window and Level
                    im = im/max(im(:));
                    if app.AutoWLButton.Value
                        lvl = 0.4;
                        wdw = 0.8;
                        app.WindowScaleSlider.Value = double(app.r.maxKspaceValue*wdw);
                        app.LevelScaleSlider.Value = double(app.r.maxKspaceValue*lvl);
                    else
                        lvl = app.LevelScaleSlider.Value/app.r.maxKspaceValue;
                        wdw = app.WindowScaleSlider.Value/app.r.maxKspaceValue;
                    end
                    im = (1/lvl)*(im - lvl + wdw/2);
                    im(im<0) = 0;
                    im(im>1) = 1;

                    app.moviePlot = imshow(rot90(im),[0 1],'Colormap',gray,'Parent',app.MovieFig);
                   
                    daspect(app.MovieFig,[1 recoAspectRatio 1]);
                    xlim(app.MovieFig,[0, xl + 1]);
                    ylim(app.MovieFig,[0, yl + 1]);

                end

                % Sense map
                if (app.SenseMapButton.Value == 1) && (app.validSenseMapFlag)

                    im = squeeze(app.r.senseMap(:,:,app.MovieSliceEditField.Value,app.SenseMapSpinner1.Value,app.SenseMapSpinner2.Value));

                    % Rotate the image (cc and ccw rotation button)
                    for i=1:app.imageRot
                        im = rot90(im);
                    end

                    % Size of the image
                    [xl,yl] = size(im);
                    recoAspectRatio = (1/app.r.aspectratio)*yl/xl;
                    if app.r.PHASE_ORIENTATION
                        im = rot90(im,-1);
                        [xl,yl] = size(im);
                        recoAspectRatio = app.r.aspectratio*yl/xl;
                    end
                    
                    % Window and Level
                    im = im/max(im(:));
                    if app.AutoWLButton.Value
                        lvl = 0.9;
                        wdw = 0.9;
                        app.WindowScaleSlider.Value = double(app.r.maxImageValue*wdw);
                        app.LevelScaleSlider.Value = double(app.r.maxImageValue*lvl);
                    else
                        lvl = app.LevelScaleSlider.Value/app.r.maxImageValue;
                        wdw = app.WindowScaleSlider.Value/app.r.maxImageValue;
                    end
                    im = (1/lvl)*(im - lvl + wdw/2);
                    im(im<0) = 0;
                    im(im>1) = 1;

                    app.moviePlot = imshow(rot90(im),[0 1],'Colormap',gray,'Parent',app.MovieFig);

                    daspect(app.MovieFig,[1 recoAspectRatio 1]);
                    xlim(app.MovieFig,[0, xl + 1]);
                    ylim(app.MovieFig,[0, yl + 1]);

                end

                xlim(app.MovieFig, [0 xl+1]);
                ylim(app.MovieFig, [0 yl+1]);

                drawnow;

            catch
            end

        end % PlotCurrentMovieFrameFcn


        % Fast update the current movie frame
        function UpdateMovieFrameFcn(app)

            % MRI movie
            if app.MovieButton.Value == 1
               
                im = squeeze(app.r.movieApp(app.MovieFrameEditField.Value,:,:,app.MovieSliceEditField.Value,app.MovieDynEditField.Value));

                % Rotate the image (cc and ccw rotation button)
                for i=1:app.imageRot
                    im = rot90(im);
                end

                % Phase orienation
                if app.r.PHASE_ORIENTATION
                    im = rot90(im,-1);
                end

                % Window and Level
                if app.AutoWLButton.Value
                    im1 = squeeze(app.r.movieApp(:,:,:,app.MovieSliceEditField.Value,app.MovieDynEditField.Value));
                    maxIm = max(im1(:));
                    app.level = 0.8*maxIm/2;
                    app.LevelScaleSlider.Value = double(app.level);
                    app.window = 0.8*maxIm;
                    app.WindowScaleSlider.Value = double(app.window);
                end
                im = (1/app.window)*(im - app.level + app.window/2);
                im(im<0) = 0;
                im(im>1) = 1;

                set(app.moviePlot, 'CData', rot90(im));
            end

            % Averages
            if app.AveragesButton.Value == 1
                im = squeeze(app.r.kSpaceAvg(app.MovieFrameEditField.Value,:,:,app.MovieSliceEditField.Value,app.MovieDynEditField.Value));
                if app.r.PHASE_ORIENTATION
                    im = rot90(im,-1);
                end
                set(app.moviePlot, 'CData', rot90(im));
            end

            % Raw k-space
            if app.kSpaceButton.Value == 1
                im = squeeze(abs(app.r.kSpace{app.MovieCoilSpinner.Value}(app.MovieFrameEditField.Value,:,:,app.MovieSliceEditField.Value,app.MovieDynEditField.Value)));
                if app.r.PHASE_ORIENTATION
                    im = rot90(im,-1);
                end

                % Window and Level
                im = im/max(im(:));
                if app.AutoWLButton.Value
                    lvl = 0.4;
                    wdw = 0.8;
                    app.WindowScaleSlider.Value = double(app.r.maxImageValue*wdw);
                    app.LevelScaleSlider.Value = double(app.r.maxImageValue*lvl);
                else
                    lvl = app.LevelScaleSlider.Value/app.r.maxImageValue;
                    wdw = app.WindowScaleSlider.Value/app.r.maxImageValue;
                end
                im = (1/lvl)*(im - lvl + wdw/2);
                im(im<0) = 0;
                im(im>1) = 1;

                set(app.moviePlot, 'CData', rot90(im));
            end

            % Sense map
            if (app.SenseMapButton.Value == 1) && (app.validSenseMapFlag)

                im = squeeze(abs(app.r.senseMap(:,:,app.MovieSliceEditField.Value,app.SenseMapSpinner1.Value,app.SenseMapSpinner2.Value)));
                
                % Rotate the image (cc and ccw rotation button)
                for i=1:app.imageRot
                    im = rot90(im);
                end
                
                % Phase orienation
                if app.r.PHASE_ORIENTATION
                    im = rot90(im,-1);
                end

                % Window and Level
                im = im/max(im(:));
                if app.AutoWLButton.Value
                    lvl = 0.9;
                    wdw = 0.9;
                    app.WindowScaleSlider.Value = double(app.r.maxImageValue*wdw);
                    app.LevelScaleSlider.Value = double(app.r.maxImageValue*lvl);
                else
                    lvl = app.LevelScaleSlider.Value/app.r.maxImageValue;
                    wdw = app.WindowScaleSlider.Value/app.r.maxImageValue;
                end
                im = (1/lvl)*(im - lvl + wdw/2);
                im(im<0) = 0;
                im(im>1) = 1;

                set(app.moviePlot, 'CData', rot90(im));
            end

            drawnow;

        end % UpdateMovieFrameFcn


    end % Methods (Access = private)


    % ----------------------------------------------
    % Public functions, available outside the app
    % ----------------------------------------------


    methods (Access = public)


        % Text messages in the app
        function TextMessage(app, message)

            try
                % Add the new text
                newText = [message ; app.MessageBox.Value];

                % Limit the history of the messages to 100 lines
                if size(newText,1)>100 
                    newText = newText(1:100,1);
                end

                % Write the message in the app
                app.MessageBox.Value = newText;
                drawnow;

            catch
            end

        end % TextMessage


        % Status icon
        function SetStatus(app,status)

            switch status

                case 0 % Status OK
                    app.StatusImage.Position = [609,65,30,30];
                    app.StatusImage.ImageSource = 'statusokay.gif';
                    app.StatusLabel.Text = 'OK';
                    app.StatusLabel.FontColor = app.green;
                    app.StatusLabel.FontWeight = 'bold';

                case 1 % Status Warning
                    app.StatusImage.Position = [609,65,30,30];
                    app.StatusImage.ImageSource = 'statuswarning.gif';
                    app.StatusLabel.Text = 'Warning';
                    app.StatusLabel.FontColor = app.orange;
                    app.StatusLabel.FontWeight = 'bold';
                    if app.AutoSoundsCheckBox.Value
                        sound(app.warningSound,48000);
                    end

                case 2 % Status Error
                    app.StatusImage.Position = [609,65,30,30];
                    app.StatusImage.ImageSource = 'statuserror.gif';
                    app.StatusLabel.Text = 'ERROR';
                    app.StatusLabel.FontColor = app.brightred;
                    app.StatusLabel.FontWeight = 'bold';
                    if app.AutoSoundsCheckBox.Value
                        sound(app.errorSound,48000);
                    end

                case 3 % Status Busy
                    app.StatusImage.Position = [607,63,33,33];
                    app.StatusImage.ImageSource = 'busy.gif';
                    app.StatusLabel.Text = 'BUSY';
                    app.StatusLabel.FontColor = app.blue;
                    app.StatusLabel.FontWeight = 'bold';

                case 4 % Status auto reconstruction
                    app.StatusImage.Position = [609,65,30,30];
                    app.StatusImage.ImageSource = 'auto.png';
                    app.StatusLabel.Text = 'AUTO';
                    app.StatusLabel.FontColor = app.blue;
                    app.StatusLabel.FontWeight = 'bold';

            end

            drawnow;

        end % SetStatus


        % Plot L curve
        function PlotLcurveFig(app,lambda,x,y)

            % Plot the L-curve and model-error curve
            if length(x)>1

                % Set the plot limits
                try
                    app.LcurveFig.XLim = [min(x) max(x)];
                    app.LcurveFig.YLim = [min(y) max(y)];
                    app.LcurveModelErrorFig.XLim = [app.Lambda1EditField.Value app.Lambda2EditField.Value];
                    app.LcurveModelErrorFig.YLim = [min(x) max(x)];
                catch ME
                    app.TextMessage(ME.message);
                end

                % Log-log plots of L-curve and model-error 
                loglog(app.LcurveFig,x,y,'o','MarkerEdgeColor',[0 .5 .5],'MarkerFaceColor',app.blue,'LineWidth',1.5);
                loglog(app.LcurveModelErrorFig,lambda(1:length(x)),x,'o','MarkerEdgeColor',[0 .5 .5],'MarkerFaceColor',app.blue,'LineWidth',1.5);

            end

        end % PlotLcurveFig


        % Plot trajectory for radial scanning
        function PlotTrajectoryMovieFrameFcn(app,xLines,yLines,zLines,dimx,firstTime)

            switch app.r.dataType

                case {'2Dradial','2Dradialms'}

                    if firstTime

                        % First time plot, set the plot limits and properties
                        reset(app.TrajectoryLargeFig);
                        app.trajPlot = plot(app.TrajectoryLargeFig,xLines,yLines,'LineWidth',0.001);
                        hold(app.TrajectoryLargeFig,'on');
                        xlim(app.TrajectoryLargeFig,[-0.55*dimx 0.55*dimx]);
                        ylim(app.TrajectoryLargeFig,[-0.55*dimx 0.55*dimx]);
                        set(app.TrajectoryLargeFig,'XTickLabel',[]);
                        set(app.TrajectoryLargeFig,'YTickLabel',[]);
                        set(app.TrajectoryLargeFig,'XTick',[]);
                        set(app.TrajectoryLargeFig,'YTick',[]);
                        app.TrajectoryLargeFig.Interactions = [];
                        app.TrajectoryLargeFig.Toolbar.Visible = 'off';

                        hold(app.TrajectoryLargeFig,'off');

                    else

                        % For all other times, only update the lines
                        for i = 1:app.maxNrSpokesPlot
                            app.trajPlot(i,1).XData = xLines(:,i);
                            app.trajPlot(i,1).YData = yLines(:,i);
                        end

                    end

                    % With limitrate the plotting is faster
                    drawnow limitrate;

                case '3Dute'

                    if firstTime
                        
                        % First time plot, set the plot limits and properties
                        app.trajPlot = plot3(app.TrajectoryLargeFig,xLines,yLines,zLines,'LineWidth',0.001);
                    
                        xlim(app.TrajectoryLargeFig,[-0.55*dimx 0.55*dimx]);
                        ylim(app.TrajectoryLargeFig,[-0.55*dimx 0.55*dimx]);
                        zlim(app.TrajectoryLargeFig,[-0.55*dimx 0.55*dimx]);

                        set(app.TrajectoryLargeFig,'XTickLabel',[]);
                        set(app.TrajectoryLargeFig,'YTickLabel',[]);
                        set(app.TrajectoryLargeFig,'ZTickLabel',[]);
                        box(app.TrajectoryLargeFig,'on');
                        app.TrajectoryLargeFig.Interactions = rotateInteraction;
                        app.TrajectoryLargeFig.Toolbar.Visible = 'off';

                    else

                        % For all other times, only update the lines
                        for i = 1:app.maxNrSpokesPlot
                            app.trajPlot(i,1).XData = xLines(:,i);
                            app.trajPlot(i,1).YData = yLines(:,i);
                            app.trajPlot(i,1).ZData = zLines(:,i);
                        end

                    end

                    % With limitrate the plotting is faster
                    drawnow limitrate;

            end

        end % PlotTrajectoryFcn

    end % Methods (Access = public)


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app)

            % Disable unnecessary warnings
            warning off;
            clc;

            % Start the parallel pool if it is not running already
            try
                numCores = feature('numcores');
                p = gcp('nocreate');
                if isempty(p)
                    if numCores >= 8
                        parpool(8);
                    else
                        parpool(numCores);
                    end
                end
            catch ME
                app.TextMessage(ME.message);
            end

            % Create empty objects of classes
            app.r = retro();
        
            % Empty file import and export paths
            app.mrdImportPath = '';
            app.mrdAutoImportPath = '';
            app.mrdAutoFile = '';
            app.dicomExportPath = '';
            app.gifExportPath = '';

            % Initalize flags
            app.validFilterFlag = false;
            app.validSortFlag = false;
            app.validRecoFlag = false;
            app.validSenseMapFlag = false;
            app.stopMovieFlag = true;
            app.stopLcurveFlag = true;
            app.stopNavMovieFlag = true;

            % Files to ignore during import
            app.ignoreFiles = ["cardtrigpoints" "resptrigpoints" "respwindow" "heartrate" "resprate" "recoparameters" "recoparmeters" "retro" "p2roud" "retroData"]; 

            % Add subfolders to search path, remove some unwanted folders
            if (~isdeployed)
                app.oldDir = pwd;
                info = dir(which('retrospective.mlapp'));
                app.appDir = info.folder;
                cd(app.appDir);
                addpath(genpath(pwd));
                try
                    rmpath(genpath(strcat(pwd,filesep,'Retrospective',filesep)));
                catch
                end
                try
                    rmpath(genpath(strcat(pwd,filesep,'Retrospective_resources',filesep)));
                catch
                end
                try
                    rmpath(genpath(strcat(pwd,filesep,'windows_standalone_installer',filesep)));
                catch
                end
                try
                    delete(strcat(pwd,filesep,'drim',filesep,'__pycache__',filesep,'*.pyc'));
                catch
                end
            end

            % Trick to automatically include the dll file during compilation
            try
                if ispc
                    import('libfftw3-3.dll');
                end
            catch
            end

            % Load all icons & colors
            app.SetAppLayoutPropertiesFcn;

            % Add the bart path to the environment variable if available
            try
                if ismac()
                    [~,result] = system('uname -v');
                    is_m1_mac = any(strfind(result,'ARM64'));
                    if is_m1_mac
                        app.TextMessage('Apple arm64 platform ...');
                    else
                        app.TextMessage('Apple Intel platform ...');
                    end
                elseif ispc()
                    app.TextMessage('PC Intel platform ...');
                elseif isunix()
                    app.TextMessage('Unix platform ...');
                end
            catch ME
                app.TextMessage(ME.message);
            end

            % Determine if BART is available by requesting its version number
            app.bartDetectedFlag = false;
            try
                bart(app,'version');
                if contains(app.bartVersion,'v')
                    app.bartDetectedFlag = true;
                end
            catch ME
                app.bartDetectedFlag = false;
                app.TextMessage(ME.message);
            end

            % Report whether BART is available or not
            if app.bartDetectedFlag
                app.TextMessage('BART toolbox detected ...');
            else
                app.TextMessage('WARNING: BART toolbox not available ...');
                app.SetStatus(1);
            end

            % Check whether python, pip and/or conda modules are available for deep learning reconstruction
            if ismac()

                try

                    % Kill open terminals
                    system('pkill -9 -a -U "$UID" Terminal');
                    try
                        system('rm -rf ~/Library/Saved\ Application\ State/com.apple.Terminal.savedState/');
                    catch
                    end

                    % Define temporary directory for reconstruction files
                    tmpDir = tempdir;

                    % Open a terminal and write installed pip and conda modules to text files
                    system(['echo -e "cd ',tmpDir,' \n pip list > pipmodules.txt \n conda list > condamodules.txt" > ',tmpDir,'/installedmodules.sh']);
                    system(['chmod +x ',tmpDir,'/installedmodules.sh']);
                    system(['open -a Terminal ',tmpDir,'/installedmodules.sh']);
                    system('osascript -e "tell application \"Finder\" to set visible of process \"Terminal\" to false"');
                    cmdout = 1;
                    while cmdout
                        [~,cmdout] = system('ps -ef | grep "conda list" | grep -v grep | wc -l | xargs');
                        cmdout = str2num(cmdout);
                        pause(0.2);
                    end

                    % Kill the terminal
                    system('pkill -9 -a -U "$UID" Terminal');
                    try
                        system('rm -rf ~/Library/Saved\ Application\ State/com.apple.Terminal.savedState/');
                    catch
                    end
                    figure(app.UIFigure);

                    % Read the installed module files and check whether all are present. If not, report missing modules.
                    try
                        pipModules = fileread([tmpDir,filesep,'pipmodules.txt']);
                    catch
                        pipModules = "empty";
                    end
                    try
                        condaModules = fileread([tmpDir,filesep,'condamodules.txt']);
                    catch
                        condaModules = "empty";
                    end
                    allModules = append(pipModules,condaModules);
                    missingModules = {};
                    for i = 1:length(app.r.neededModules)
                        if ~contains(allModules, app.r.neededModules{i})
                            missingModules{end+1} = app.r.neededModules{i}; %#ok<AGROW>
                        end
                    end
                    if isempty(missingModules)
                        app.aiDetectedFlag = true;
                        app.TextMessage('All necessary python modules for deep learning reconstruction detected ...');
                    else
                        app.aiDetectedFlag = false;
                        app.TextMessage(strcat("Missing modules for deep learning reconstruction = ",strjoin(string(missingModules))," ..."));
                    end

                catch ME

                    app.TextMessage(ME.message);
                    app.TextMessage("Failed to detect deep learning modules ...");
                    app.aiDetectedFlag = false;

                end

            end

            if ispc()

                try

                    % Kill open terminals
                    try
                        [~, tasks] = system('tasklist | find "cmd"');
                        splits = regexp(tasks, ' *', 'split');
                        pid = str2double(splits{2});
                        system(strcat("taskkill /pid ",num2str(pid)," /f"));
                    catch
                    end
                    try
                        [~, tasks] = system('tasklist | find "wsl.exe"');
                        splits = regexp(tasks, ' *', 'split');
                        pid = str2double(splits{2});
                        system(strcat("taskkill /pid ",num2str(pid)," /f"));
                    catch
                    end

                    % Define a common temp dir in Windows and WSL
                    try
                        tmpDir = 'C:\tmp\';
                        if ~exist(tmpDir, 'dir')
                            mkdir(tmpDir);
                        end
                    catch
                        tmpDir = tempdir;
                    end
                    tmpDirWSL = strrep(tmpDir,'\','/');
                    tmpDirWSL = strrep(tmpDirWSL,'C:','/mnt/c');
                    tmpDirWSL = strrep(tmpDirWSL,' ','_');

                    % Start WSL in a command window and wait to start
                    commandLine = 'cmd /c start /min cmd /k wsl';
                    h = actxserver('WScript.Shell');
                    pause(0.1);
                    h.Run(commandLine);
                    figure(app.UIFigure);
                    tasks = [];
                    while isempty(tasks)
                        [~, tasks] = system('tasklist | find "wsl.exe"');
                    end

                    % Activate WSL terminal and write all installed modules to a file
                    [~, tasks] = system('tasklist | find "cmd"');
                    splits = regexp(tasks, ' *', 'split');
                    pid = str2double(splits{2});
                    h.AppActivate(pid);
                    pause(0.4);
                    capsDetectFile = which(fullfile('CAPSLOCK.EXE'));
                    capsDetected = system(capsDetectFile);
                    if capsDetected
                        h.SendKeys(strcat("{CAPSLOCK}"));
                        app.TextMessage("Caps lock detected, switching off ...");
                    end
                    pause(0.4)
                    h.SendKeys(['cd ',tmpDirWSL,' ~']);
                    h.SendKeys('pip list > pipmodules.txt ~');
                    figure(app.UIFigure);
                    pause(2);
                    h.AppActivate(pid);
                    pause(0.4);
                    h.SendKeys('conda list > condamodules.txt ~');
                    figure(app.UIFigure);
                    pause(3);

                    % Read the installed module files and check whether all are present. If not, report missing modules.
                    try
                        pipModules = fileread([tmpDir,filesep,'pipmodules.txt']);
                    catch
                        pipModules = "empty";
                    end
                    try
                        condaModules = fileread([tmpDir,filesep,'condamodules.txt']);
                    catch
                        condaModules = "empty";
                    end
                    allModules = append(pipModules,condaModules);
                    missingModules = {};
                    for i = 1:length(app.r.neededModules)
                        if ~contains(allModules, app.r.neededModules{i})
                            missingModules{end+1} = app.r.neededModules{i}; %#ok<AGROW>
                        end
                    end
                    if isempty(missingModules)
                        app.aiDetectedFlag = true;
                        app.TextMessage('All necessary python modules for deep learning reconstruction detected ...');
                    else
                        app.aiDetectedFlag = false;
                        app.TextMessage(strcat("Missing modules for deep learning reconstruction = ",strjoin(string(missingModules))," ..."));
                    end

                    % Close the WSL terminal window
                    try
                        system(strcat("taskkill /pid ",num2str(pid)," /f"));
                    catch
                    end
                    try
                        [~, tasks] = system('tasklist | find "wsl.exe"');
                        splits = regexp(tasks, ' *', 'split');
                        pid = str2double(splits{2});
                        system(strcat("taskkill /pid ",num2str(pid)," /f"));
                    catch
                    end

                catch ME

                    app.TextMessage(ME.message);
                    app.TextMessage("Failed to detect deep learning modules ...");
                    app.aiDetectedFlag = false;

                end

            end

            % Determine the current directory in case of deployed app
            % One could put additional input files here
            if isdeployed
                try
                    [~,result] = system('path');
                    app.currentDir = char(regexpi(result, 'Path=(.*?);', 'tokens', 'once'));
                    app.TextMessage(strcat("Path: ",app.currentDir));
                catch
                    app.currentDir = '';
                end
            end

            % Read the graddelays.txt file
            try
                delays = load('graddelays.txt');
                app.GxDelayEditField.Value = delays(1);
                app.GyDelayEditField.Value = delays(2);
                app.GzDelayEditField.Value = delays(3);
                app.DataOffsetRadialEditField.Value = delays(4);
                app.TextMessage('Gradient delays file found ...');
            catch
                app.TextMessage('Gradient delays file not found or invalid gradient file ...');
            end
            
            % Set the import directories
            app.SetMRDimportPathFcn;

            % Check app version
            app.CheckVersionFnc;

            % Some mysterious Matlab bug is sometimes opening a figure
            % If that happens, close the figure
            try
                close(1);
            catch
            end

            % Set buttons
            app.LoadMRDButton.Enable = 'on';
            app.SetStatus(0);

        end

        % Selection changed function: ColorsButtonGroup
        function ColorsButtonGroupSelectionChanged(app, event)
            
            selectedButton = app.ColorsButtonGroup.SelectedObject;

            switch lower(selectedButton.Text)

                % COLORS:           backgroundColor, fontColor, boxColor, gaugeColor, gaugeBackgroundColor, figureColor, movieFigureColor, gridColor, autoRecoColor, manualRecoColor

                case "classic"

                    guiColors = {app.guiGrey, app.guiBlack, app.guiDarkGrey, app.blue, app.guiWhite, app.guiWhite, app.guiMiddleGrey, app.guiDarkGrey, app.lightblue, app.guiGrey};
                   
                case "black/white"

                    guiColors = {app.guiBlack, app.guiWhite, app.guiDarkGrey, app.blue, app.guiDarkGrey, app.guiGrey, app.guiDarkGrey, app.guiDarkGrey, app.guiBlackBlue, app.guiBlack};

            end

            app.SetAppColorsFcn(guiColors);
            
        end

        % Callback function: DataFile, LoadMRDButton
        function LoadMRDfileButtonPushed(app, event)

            % Indicate busy
            app.SetLightsFcn(0);
            app.SwitchOffAllButtonsFcn;
            app.Lamp1.Color = app.yellow;
            app.SetStatus(3);
        
            % Reset object of class retro
            clear app.r;
            app.r = retro();
            tempDir = app.mrdImportPath;

            % Reset flags & some parameters
            app.validFilterFlag = false;
            app.validSortFlag = false;
            app.validRecoFlag = false;
            app.validSenseMapFlag = false;
            app.stopMovieFlag = true;
            app.stopLcurveFlag = true;
            app.stopNavMovieFlag = true;
            app.orientationsFlag = true;
            app.NavigatorFlipSwitch.Value = 'Up';
            app.ProgressGauge.Value = 0;
            cla(app.TrajectoryLargeFig);
            app.ReconstructionTabGroup.SelectedTab = app.RecoTab;
            app.ParametersTabGroup.SelectedTab = app.SequenceTab;
            drawnow;

            % Bart total generalized variation (G) or total variation (T)
            if app.bartDetectedFlag
                app.r.totalVariation = 'T';
                try
                    picsHelp = bart(app,'pics -Rh');
                    if contains(lower(picsHelp),"total generalized variation")
                        app.r.totalVariation = 'G';
                        app.TextMessage('Using Bart total generalized variation ...');
                    end
                catch ME
                    app.TextMessage(ME.message);
                end
            end
            
            try

                % Select a DATA file automatically or manually
                if app.AutoRecoCheckBox.Value

                    [filePath,name,ext] = fileparts(app.mrdAutoFile);
                    app.mrdImportPath = filePath;
                    app.mrdFileName = strcat(name,ext);

                else
 
                    f = figure('CloseRequestFcn','','Position',[-100 -100 0 0]); % Needed to bring uigetfile in front
                    [app.mrdFileName,app.mrdImportPath] = uigetfile(strcat(app.mrdImportPath,filesep,{'*.mrd;*.orig;*.job0'}));
                    delete(f);
                    figure(app.UIFigure);  % Put the app back in the foreground

                end

                % Import the data
                if ischar(app.mrdFileName)

                    % File selected
                    app.mrdFile = [app.mrdImportPath, app.mrdFileName];

                    if strcmp(app.mrdFileName,"fid.orig") || strcmp(app.mrdFileName,"rawdata.job0")

                        % ------------------------------------------------------------------------
                        % B-type scanner data import
                        % ------------------------------------------------------------------------

                        [app.r, data, parameters] = app.r.importB(app);
                        app.DataFile.Value = app.mrdFileName;
                        app.r.rprFlag = false;

                        % Construct the retro parameter class
                        app.r = retro(parameters, data);

                        % Check if the data is a valid file with a motion navigator acquisition
                        app.r = app.r.acquisitionType(app);

                    else

                        % ------------------------------------------------------------------------
                        % MR Solutions data import
                        % ------------------------------------------------------------------------

                        mrdStart = extractBefore(app.mrdFileName,'_');
                        fList = dir(fullfile(app.mrdImportPath,[mrdStart,'*.MRD']));

                        % Check wether the filelist contains already reconstructed MRD file(s), remove these from the list
                        if ~isempty(fList)
                            for i = length(fList):-1:1
                                if contains(fList(i).name,app.ignoreFiles)
                                    fList(i) = [];
                                end
                            end
                        end

                        if ~isempty(fList)

                            % Filename tag
                            app.DataFile.Value = fList(1).name;
                            [~,app.tag,~] = fileparts([fList(1).folder,filesep,fList(1).name]);

                            % Load the data
                            app.TextMessage('Loading k-space data ...');
                            nrCoils = length(fList);
                            data = cell(nrCoils);
                            for i=1:nrCoils
                                app.TextMessage(strcat("Loading coil ",num2str(i)));
                                [data{i},~,parameters,~] = app.r.importMRD(fullfile(fList(i).folder, fList(i).name), 'seq', 'seq');
                            end
                            parameters.coil_scaling = ones(nrCoils,1);      % Assume coil scaling is 1 for all coils
                            parameters.nr_coils = nrCoils;                  % Number of RF receiver coil

                            % Construct the retro parameter class
                            app.r = retro(parameters, data);

                            % Read the full MRD file footer
                            app.r = app.r.readMrdFooter(app, fullfile(fList(1).folder, fList(1).name));

                            % Read the RPR file
                            app.r = app.r.readRPRfile(app, fList(1).folder);

                            % Read the SQL file
                            sqlFilename = [];
                            sqlFilenameList = dir(fullfile([fList(1).folder, filesep, '*.SQL']));
                            if ~isempty(sqlFilenameList)
                                sqlFilename = [sqlFilenameList(1).folder, filesep, sqlFilenameList(1).name];
                            end
                            app.r = app.r.readSQLfile(app, sqlFilename);

                            % Check if the data is a valid file with a motion navigator acquisition
                            app.r = app.r.acquisitionType(app);
                            app.NavigatorEndEditField.Value = app.r.primaryNavigatorPoint;

                        end

                    end

                    % ------------------------------------------------------------------------
                    % Some additional checks on the data
                    % ------------------------------------------------------------------------
 
                    % Check if there are sufficient number of experiments
                    app.r = app.r.checkNumberOfExperiments(app);
                   
                    % Check for averaging
                    app.r = app.r.checkNumberOfAverages(app);
                   
                    % 3D multi-slab data is not supported
                    app.r = app.r.checkForMultiSlab(app);
                   
                    % Determine whether 3D data has multiple flip-angles
                    app.r = app.r.checkForVFA(app);
                    
                    % Check that TR is not equal to zero
                    app.r = app.r.checkTR(app);

                    % Try to get some parameters from the SQL file if present
                    app.r = app.r.sqlParameters(app);
                    app.r = app.r.imageOrientLabels(app);
                  
                end

            catch ME

                % If something unknown goes wrong during reading of the data,
                % report the error to the app
                app.TextMessage(ME.message);
                app.r.validDataFlag = false;
         
            end

            if app.r.validDataFlag

                % Initialize
                app.SetSpeciesFcn;                                                          % Set initial filtering values for mouse/rat
                app.RespirationToggleCheckBox.Value = 0;                                    % Set reconstruction to expiration
                app.IgnoreRespirationToggleCheckBox.Value = 0;                              % Switch ignore respiration off
                app.DataParametersFcn;                                                      % Set intial data parameters
                app.r = app.r.autoDiscardNav(app);                                          % Automatically set the number of discarded points
                app.NavPointEditFieldValueChanged;                                          % Extract navigator

                % Reset buttons
                app.SetLightsFcn(1);
                app.SetDashboardButtonsFcn(131);
                app.NavViewingButtonsFcn(false);
                app.CineRecoButtonsFcn(false);
                app.MovieButtonsFcn(false);
                app.NavSelButtonsFcn(true);
                app.SetStatus(0);

            else

                % Reset buttons
                app.LoadMRDButton.Enable = 'on';
                app.ExitButton.Enable = 'on';
                app.Lamp1.Color = app.brightred;
                app.NavSelButtonsFcn(false);
                app.NavViewingButtonsFcn(false);
                app.CineRecoButtonsFcn(false);
                app.MovieButtonsFcn(false);
                app.SetStatus(2);

                % Reset some parameters
                app.mrdImportPath = tempDir;
                app.mrdAutoImportPath = tempDir;
                app.SetMRDimportPathFcn;

                % Error message
                app.TextMessage('ERROR: Not a valid data file ...');

            end

        end

        % Value changed function: DiscardEditField, NavigatorEndEditField, 
        % ...and 2 other components
        function NavPointEditFieldValueChanged(app, event)

            % Update the used navigator point and the number of navigator points used
            app.NavigatorNRPointsEditField.Value(app.NavigatorNRPointsEditField.Value > app.NavigatorEndEditField.Value) = app.NavigatorEndEditField.Value;
            app.r.primaryNavigatorPoint = app.NavigatorEndEditField.Value;
            app.r.nrNavPointsUsed = app.NavigatorNRPointsEditField.Value;
            app.r.nrNavPointsDiscarded = app.DiscardEditField.Value;

            % Update the data parameters
            app.DataParametersFcn;

            % Switch the data-windows off
            app.IncludeCheckBox.Value = false;
            app.ExcludeCheckBox.Value = false;
            app.PlotDataWindowFcn;

            % Determine the value of the switch to flip the navigator
            app.r = app.r.readFlipSwitch(app);

            % Extract the navigator signals
            app.r = app.r.extractNavigator;
           
            % Plot the raw navigator signal
            app.PlotRawNavigatorFcn;

            % Determine and plot the power spectrum of the raw navigator signal
            app.CalculateNavigatorSpectrumFcn;
            app.PlotNavigatorSpectrumFcn;

            % Plot or clear the other navigator graphs
            app.PlotCardiacNavigatorFcn;
            app.PlotRespirationNavigatorFcn;
            cla(app.HeartRateTimeFig);
            cla(app.RespRateTimeFig);

            % Reset buttons
            app.SetLightsFcn(1);
            app.SwitchOffAllButtonsFcn;
            app.SetDashboardButtonsFcn(131);
            app.NavSelButtonsFcn(true);
            app.ProgressGauge.Value = 0;

        end

        % Value changed function: CoilSpinner
        function CoilSpinnerValueChanged(app, event)

            % Plot the raw navigator signal
            app.PlotRawNavigatorFcn;

            % Show the samples-navigator plots
            app.PlotCardiacNavigatorFcn;
            app.PlotRespirationNavigatorFcn;

        end

        % Value changed function: HeartEditField, HeartWidthEditField, 
        % ...and 3 other components
        function FilterEditFieldValueChanged(app, event)

            % Reset the valid filter flag to false
            app.validFilterFlag = false;

            % Limit the filter width values
            app.HeartWidthEditField.Value(app.HeartWidthEditField.Value > app.HeartEditField.Value) = app.HeartEditField.Value; 
            app.RespirationWidthEditField.Value(app.RespirationWidthEditField.Value > app.RespirationEditField.Value) = app.RespirationEditField.Value;

            % Get the filter values from the app
            app.r.detectedHR = app.HeartEditField.Value;
            app.r.bandwidthHR = app.HeartWidthEditField.Value;
            app.r.detectedRR = app.RespirationEditField.Value;
            app.r.bandwidthRR = app.RespirationWidthEditField.Value;
            app.r.respPercentage = app.RespirationWindowEditField.Value;

            % Filter the navigator data
            app.FilterButtonPushed;

            % Plot the navigator spectrum
            app.PlotNavigatorSpectrumFcn;

        end

        % Button pushed function: ResetFilterValuesButton
        function ResetFilterValuesButtonPushed(app, event)

            % Reset filter values
            app.validFilterFlag = false;
            app.SetSpeciesFcn;
            app.CalculateNavigatorSpectrumFcn;
            app.FilterButtonPushed;
            app.PlotNavigatorSpectrumFcn;

        end

        % Selection changed function: SpeciesButton
        function SpeciesButtonSelectionChanged(app, event)

            % Execute the reset filter values function
            app.ResetFilterValuesButtonPushed;

        end

        % Button down function: SpeciesButton
        function SpeciesButtonButtonDown(app, event)

            % Execute the reset filter values function
            app.ResetFilterValuesButtonPushed;

        end

        % Value changed function: IgnoreRespirationToggleCheckBox, 
        % ...and 1 other component
        function RespirationToggleCheckBoxValueChanged(app, event)
        
            % Execute the reset filter values function
            app.ResetFilterValuesButtonPushed;

        end

        % Selection changed function: RecoPresetsButtonGroup
        function RecoPresetsButtonGroupSelectionChanged(app, event)

            % Update the reonstruction parameters
            app.SetRecoParametersFcn;
            app.CinePanelValueChanged;

        end

        % Button down function: RecoPresetsButtonGroup
        function RecoPresetsButtonGroupButtonDown(app, event)

            % Execute the reco preset change function
            app.RecoPresetsButtonGroupSelectionChanged;

        end

        % Value changed function: AutoRecoCheckBox
        function AutoRecoCheckBoxValueChanged(app, event)

            % Color the canvas for visual indication of auto-reconstruction
            if app.AutoRecoCheckBox.Value
                app.MovieStopButtonPushed;
                app.AutoSoundsCheckBox.Value = 0;
                app.SetMRDimportPathFcn;
            else
                app.AutoSoundsCheckBox.Value = 1;
                app.UIFigure.Color = app.manualRecoColor;
                app.SetStatus(0);
            end

            try % Catch error during while loop

                % Wait for new data
                while app.AutoRecoCheckBox.Value

                    % Show autostatus
                    app.TextMessage('Auto reco mode started ...');
                    app.TextMessage(strcat("Watching: ",app.mrdAutoImportPath," ..."));
                    app.UIFigure.Color = app.autoRecoColor;
                    app.SetStatus(4);

                    % Initial mrd files in directory + sub-directories
                    searchFiles = [app.mrdAutoImportPath,filesep,'**',filesep,'*.MRD'];
                    fut = parfeval(@dir, 1, searchFiles);
                    didFinish = wait(fut, 'finished', 5);  % Search directories and sub-directories for max 5 seconds
                    if didFinish
                        fns = fetchOutputs(fut);
                        fOld = {fns(1:end).name};
                    else
                        cancel(fut);
                        fOld = {''};
                    end
                    fIndx = 0;
                    clear('fut');
                    fDiff = [];

                    % Loop and wait for new mrd file
                    while app.AutoRecoCheckBox.Value && isempty(fDiff)

                        fut = parfeval(@dir, 1, searchFiles);
                        didFinish = wait(fut, 'finished', 5);  % Search directories and sub-directories for max 5 seconds
                        if didFinish
                            fns = fetchOutputs(fut);
                            fNew = {fns(1:end).name};
                        else
                            app.TextMessage('WARNING: Searching path takes too long, too many subdirectories ...');
                            cancel(fut);
                            fNew = {''};
                            app.AutoRecoCheckBox.Value = 0;
                            app.AutoSoundsCheckBox.Value = 1;
                            app.UIFigure.Color = app.manualRecoColor;
                            app.SetStatus(1);
                        end
                        clear('fut');

                        [fDiff,fIndx] = setdiff(fNew,fOld);

                        drawnow;

                    end

                    % Continue when new mrd file is detected or autorecocheckbox == false
                    if fIndx>0

                        app.TextMessage('New MRD file found ...');

                        app.mrdAutoFile = fullfile(fns(fIndx).folder,fns(fIndx).name);

                        pause(1);
                        app.LoadMRDfileButtonPushed;                    % Load data

                        if app.r.validDataFlag
                            app.FilterButtonPushed;                     % Filter the navigator data

                            if app.validFilterFlag
                                app.SortKspaceButtonPushed;             % Sort the data into k-space

                                if app.validSortFlag
                                    app.RecoMoviesButtonPushed;         % Reconstruct the images/movie

                                    if app.validRecoFlag
                                        app.ExportMRDButtonPushed;      % Export new MRD file

                                    end
                                end
                            end
                        end

                    end

                end

            catch
            end

        end

        % Value changed function: FilterOrderEditField, 
        % ...and 5 other components
        function FilterSettingsChanged(app, event)

            % Limit the values, min cannot be larger than max value
            app.MinRespRateEditField.Value(app.MinRespRateEditField.Value > app.MaxRespRateEditField.Value) = app.MaxRespRateEditField.Value;
            app.MinHeartRateEditField.Value(app.MinHeartRateEditField.Value > app.MaxHeartRateEditField.Value) = app.MaxHeartRateEditField.Value;
       
            % Calculate the new spectrum, filter the navigator, plot the spectrum
            app.CalculateNavigatorSpectrumFcn;
            app.FilterButtonPushed;
            app.PlotNavigatorSpectrumFcn;

        end

        % Button pushed function: LogLinButton
        function LogLinButtonPushed(app, event)

            % Switch between linear or logaritmic scale
            if strcmp(app.LogLinButton.Text,'log')
                app.LogLinButton.Text = 'lin';
            else
                app.LogLinButton.Text = 'log';
            end

            % Replot the spectrum
            app.PlotNavigatorSpectrumFcn;

        end

        % Button pushed function: FilterButton
        function FilterButtonPushed(app, event)

            % Indicate busy
            app.SetLightsFcn(1);
            app.Lamp2.Color = app.yellow;
            app.SetStatus(3);
            app.SwitchOffAllButtonsFcn;

            % Message the app
            app.TextMessage('Filtering the navigator ...');

            % Reset some parameters
            app.validFilterFlag = false;
            app.validSortFlag = false;
            app.validRecoFlag = false;
            app.stopMovieFlag = true;
            app.stopLcurveFlag = true;
            app.stopNavMovieFlag = true;
            app.ProgressGauge.Value = 0;

            try

                % Filter settings from app
                app.r.physioFilterSettings = [app.MinHeartRateEditField.Value ...
                    app.MaxHeartRateEditField.Value app.MinRespRateEditField.Value ...
                    app.MaxRespRateEditField.Value app.FilterOrderEditField.Value app.RespShiftEditField.Value];

                % Filter for cardiac and respiratory motion
                app.r = app.r.filterNavPCA(app);
                app.RespirationEditField.Value = app.r.detectedRR;
                app.RespirationWidthEditField.Value = app.r.bandwidthRR;
 
                % Extract cardiac trigger points
                app.r = app.r.trigPointsHeart;

                % Calculate heart-time curves, trend-lines, and mean heart-rate
                app.r = app.r.calcHeartRate;
                app.FinalHeartViewField.Value = app.r.meanHeartRate;
 
                % Extract respiratory trigger points
                app.r = app.r.trigPointsResp;
                
                % Calculate resp-time curves, trend-lines, and mean resp-rate
                app.r = app.r.calcRespRate;
                app.FinalRespirationViewField.Value = app.r.meanRespRate;

                % Make the respiration exclusion windows
                app.r = app.r.makeRespWindow(app);
              
                % Let the app know the nav data is filtered
                app.validFilterFlag = true;

                % Plot the datawindow in the time-heartrate and time-respirationrate plots and datawindow
                app.PlotRatesFcn;
                app.PlotRateLimitsFcn;
                app.PlotDataWindowFcn;
          
                % Show the samples-navigator plots
                app.PlotCardiacNavigatorFcn;
                app.PlotRespirationNavigatorFcn;

                % Reset buttons
                app.SetLightsFcn(3);
                app.SetDashboardButtonsFcn(135);
                app.NavSelButtonsFcn(true);
                app.NavViewingButtonsFcn(true);
                app.CineRecoButtonsFcn(true);
                app.MovieButtonsFcn(false);
                app.SetStatus(0);

            catch ME

                % Let the app know the nav data is not filtered
                app.validFilterFlag = false;

                % Reset buttons
                app.SetLightsFcn(1);
                app.SetDashboardButtonsFcn(131);
                app.NavSelButtonsFcn(true);
                app.NavViewingButtonsFcn(true);
                app.CineRecoButtonsFcn(true);
                app.MovieButtonsFcn(false);
                app.SetStatus(2);
              
                % Error message
                app.TextMessage('ERROR: Something went wrong during filtering of the navigator ...')
                app.TextMessage(ME.message);
     
            end

        end

        % Value changed function: RangeEditField, StartEditField
        function DisplayEditFieldValuesChanged(app, event)

            % Get the new range values from the app
            value1 = app.StartEditField.Value;
            value2 = app.RangeEditField.Value;

            % Range should be at least 20
            value2(value2<20) = 20;

            % Range should not exceed 2000, else plot gets really slow and cluttered
            value2(value2>2000) = 2000;

            % Start + range should not exceed the number of available k-lines
            if value1 + value2 > app.r.nrKlines
                value1 = app.r.nrKlines - value2;
            end

            % Report the values back to the user-interface
            app.StartEditField.Value = value1;
            app.RangeEditField.Value = value2;

            % Adjust the sliders also
            app.StartSlider.Value = app.StartEditField.Value;
            app.RangeSlider.Value = app.RangeEditField.Value;

            % Plot the raw navigator, cardiac filtered, and respiration filtered
            app.PlotRawNavigatorFcn;
            app.PlotCardiacNavigatorFcn;
            app.PlotRespirationNavigatorFcn;
            app.PlotRateLimitsFcn;

        end

        % Value changed function: RangeSlider, StartSlider
        function DisplaySlidersValueChanged(app, event)

            % Set the field values to match the sliders
            app.StartEditField.Value = round(app.StartSlider.Value);
            app.RangeEditField.Value = round(app.RangeSlider.Value);

            % Proceed as if the field values were changed
            app.DisplayEditFieldValuesChanged;

        end

        % Button pushed function: NavStartButton
        function NavStartButtonPushed(app, event)

            % Navigator movie is running
            app.stopNavMovieFlag = false;

            try % To catch error during exit, while movie is still running

                % Repeat until navigator movie is stopped
                while ~app.stopNavMovieFlag

                    value1 = app.StartEditField.Value;
                    value2 = app.RangeEditField.Value;

                    % Add app.navMovieSpeed value to display the next frame
                    value1 = value1 + app.navMovieSpeed;

                    % At the end of the data, reset to beginning
                    if value1 + value2 > app.r.nrKlines
                        value1 = 1;
                    end

                    % Show the values in the app
                    app.StartEditField.Value = value1;
                    app.StartSlider.Value = value1;

                    % Plot the range of navigators in the 4 plots
                    app.PlotRawNavigatorFcn;
                    app.PlotCardiacNavigatorFcn;
                    app.PlotRespirationNavigatorFcn;
                    app.PlotRateLimitsFcn;

                end

            catch
            end

        end

        % Button pushed function: NavStopButton
        function NavStopButtonPushed(app, event)

            % Set the stop navigator movie flag to true
            app.stopNavMovieFlag = true;

            % Plot the raw navigators, cardiac filtered, and respiration filtered
            app.PlotRawNavigatorFcn;
            app.PlotCardiacNavigatorFcn;
            app.PlotRespirationNavigatorFcn;
            app.PlotRateLimitsFcn;

        end

        % Value changed function: NavFastSlowSwitch
        function NavFastSlowSwitchValueChanged(app, event)

            % Navigator movie speed is slow or fast
            if app.NavFastSlowSwitch.Value == "slow"
                app.navMovieSpeed = 25;
            else
                app.navMovieSpeed = 100;
            end

        end

        % Value changed function: IncludeCheckBox
        function IncludeCheckBoxValueChanged(app, event)

            % Include window check-box on or off
            if app.IncludeCheckBox.Value == 1

                % Include window is on
                % Execute IncludeSliderValueChanged function
                app.IncludeSliderValueChanged;

            else

                % Reset the window to zeros
                app.r.includeWindow = ones(app.r.nrKlines,1);
                app.PlotDataWindowFcn;
                app.CalculateNavigatorSpectrumFcn;
                app.PlotNavigatorSpectrumFcn;

                % Reset buttons
                app.SetLightsFcn(1);
                app.SwitchOffAllButtonsFcn;
                app.SetDashboardButtonsFcn(131);
                app.NavSelButtonsFcn(true);
                app.NavViewingButtonsFcn(true);
                app.stopMovieFlag = true;
                app.SetStatus(0);
           
            end

        end

        % Value changed function: IncludeEndSlider, IncludeStartSlider
        function IncludeSliderValueChanged(app, event)

            % Read the sliders
            value1 = app.IncludeStartSlider.Value;      % Start of the include-window in units of seconds
            value2 = app.IncludeEndSlider.Value;        % End of the include-window in units of seconds

            % End should be bigger than Start and length of the window should be at least 10 (arbitrary) repetitions
            if (value2 - value1) < round(10 * app.r.nrKsteps * app.r.TR / 1000)
                value2 = value1 + round(10 * app.r.nrKsteps * app.r.TR / 1000);
            end

            % End should be smaller than total acquisition time and length of the window should be at least 10 (arbitrary) repetitions
            if value2 > app.acqDur
                value2 = app.acqDur;
                value1 = value2 - (10 * app.r.nrKsteps * app.r.TR / 1000);
            end

            % Set the values and sliders
            app.IncludeStartSlider.Value = value1;
            app.IncludeEndSlider.Value = value2;

            % Set the value of the include window
            app.r.includeWindow = zeros(app.r.nrKlines,1);
            value1k = round(1000*value1/app.r.TR)+1;    % Start of the window in nr k-lines
            value2k = round(1000*value2/app.r.TR);      % End of the window in nr k-lines
            value2k(value2k > app.r.nrKlines) = app.r.nrKlines;
            app.r.includeWindow(value1k:value2k) = 1;

            % Plot the datawindow in the time-heartrate and time-respirationrate plots
            app.PlotDataWindowFcn;

            % Calculate and plot the power spectrum of the raw navigator signal
            app.CalculateNavigatorSpectrumFcn;
            app.PlotNavigatorSpectrumFcn;

            % Reset buttons
            app.SetLightsFcn(1);
            app.SwitchOffAllButtonsFcn;
            app.SetDashboardButtonsFcn(131);
            app.NavSelButtonsFcn(true);
            app.NavViewingButtonsFcn(true);
            app.stopMovieFlag = true;
         
            % Check whether there is sufficient data left to do a reconstruction
            numberOfKlines = nnz(app.r.includeWindow.*app.r.excludeWindow);
            if numberOfKlines < 512
                app.TextMessage('ERROR: Not enough k-lines left for reconstruction, adjust data windows ...');
                app.FilterButton.Enable = 'off';
                app.SetStatus(2);
            else
                app.SetStatus(0);
            end

        end

        % Value changed function: ExcludeCheckBox
        function ExcludeCheckBoxValueChanged(app, event)

            % Exclude window check-box on or off
            if app.ExcludeCheckBox.Value == 1

                % Exclude window is on
                % Execute ExcludeSliderValueChanged function 
                app.ExcludeSliderValueChanged;

            else

                % Reset the window to zeros
                app.r.excludeWindow = ones(app.r.nrKlines,1);

                % Plot the new windows
                app.PlotDataWindowFcn;
                app.CalculateNavigatorSpectrumFcn;
                app.PlotNavigatorSpectrumFcn;

                % Reset buttons
                app.SetLightsFcn(1);
                app.SwitchOffAllButtonsFcn;
                app.SetDashboardButtonsFcn(131);
                app.NavSelButtonsFcn(true);
                app.NavViewingButtonsFcn(true);
                app.stopMovieFlag = true;
                app.SetStatus(0);
            
            end

        end

        % Value changed function: ExcludeEndSlider, ExcludeStartSlider
        function ExcludeSliderValueChanged(app, event)

            % Read the sliders
            value1 = app.ExcludeStartSlider.Value;      % Start of the exclude-window in units of seconds
            value2 = app.ExcludeEndSlider.Value;        % End of the exclude-window in units of seconds

            % End should be bigger than Start and length of the window should be at least 10 (arbitrary) repetitions
            if (value2 - value1) < round(10 * app.r.nrKsteps * app.r.TR / 1000)
                value2 = value1 + round(10 * app.r.nrKsteps * app.r.TR / 1000);
            end

            % End should be smaller than total acquisition time and length of the window should be at least 10 (arbitrary) repetitions
            if value2 > app.acqDur
                value2 = app.acqDur;
                value1 = value2 - (10 * app.r.nrKsteps * app.r.TR / 1000);
            end

            % Set the values and sliders
            app.ExcludeStartSlider.Value = value1;
            app.ExcludeEndSlider.Value = value2;

            % Set the value of the exclude window
            app.r.excludeWindow = ones(app.r.nrKlines,1);
            value1k = round(1000*value1/app.r.TR)+1;        % Start of the window in nr k-lines
            value2k = round(1000*value2/app.r.TR);          % End of the window in nr k-lines
            value2k(value2k > app.r.nrKlines) = app.r.nrKlines;
            app.r.excludeWindow(value1k:value2k) = 0;

            % Plot the datawindow in the time-heartrate and time-respirationrate plots
            app.PlotDataWindowFcn;

            % Calculate and plot the power spectrum of the raw navigator signal
            app.CalculateNavigatorSpectrumFcn;
            app.PlotNavigatorSpectrumFcn;

            % Reset buttons
            app.SetLightsFcn(1);
            app.SwitchOffAllButtonsFcn;
            app.SetDashboardButtonsFcn(131);
            app.NavSelButtonsFcn(true);
            app.NavViewingButtonsFcn(true);
            app.stopMovieFlag = true;
        
            % Check whether there is sufficient data left to do a reconstruction
            numberOfKlines = nnz(app.r.includeWindow.*app.r.excludeWindow);
            if numberOfKlines < 512
                app.TextMessage('ERROR: Not enough k-lines left for reconstruction, adjust data windows ...');
                app.FilterButton.Enable = 'off';
                app.SetStatus(2);
            else
                app.SetStatus(0);
            end

        end

        % Value changed function: DynamicsEditField, FramesEditField, 
        % ...and 2 other components
        function CinePanelValueChanged(app, event)

            % If running, stop any movies
            app.MovieStopButtonPushed;
            app.TrajMovieStopButtonPushed;
            app.TrajMovie3DStopButtonPushed;
            app.validRecoFlag = false;
            app.validSortFlag = false;
            app.validRecoFlag = false;
            app.SetDashboardButtonsFcn(135);
            app.Lamp3.Color = app.red;
            app.Lamp4.Color = app.red;

            % New number of frames
            app.nrFrames = app.FramesEditField.Value;
            app.nrDynamics = app.DynamicsEditField.Value;

            % No include and exclude windows for multi-dynamic reconstructions
            if app.nrDynamics > 1
                app.TimeDynViewField.Value = string(duration(seconds(app.acqDur/app.DynamicsEditField.Value),'Format','hh:mm:ss.SSS'));
                app.IncludeCheckBox.Value = 0;
                app.IncludeCheckBoxValueChanged;
                app.ExcludeCheckBox.Value = 0;
                app.ExcludeCheckBoxValueChanged;
                app.FilterButtonPushed;
            else
                app.TimeDynViewField.Value = app.ScanTimeViewField.Value;
            end

            % Plot the dynamic windows in the rate plots
            app.PlotDynamicsWindowsFcn;
        
        end

        % Button pushed function: SortKspaceButton
        function SortKspaceButtonPushed(app, event)

            % Indicate busy
            app.SetLightsFcn(3);
            app.Lamp3.Color = app.yellow;
            app.SetStatus(3);
            app.SwitchOffAllButtonsFcn;

            % Report sorting status to app
            app.TextMessage('Sorting ...');

            % Reset some parameters
            app.validSortFlag = false;
            app.validRecoFlag = false;
            app.stopMovieFlag = true;
            app.stopLcurveFlag = true;
            app.stopNavMovieFlag = true;
            app.ProgressGauge.Value = 0;
            app.GradDelayCalibrationCheckBox.Value = 1;

            try
      
                % Extract all the raw k-space imaging data
                app.r = app.r.extractData;

                % Sort by cardiac phase/relative binning, cardiac absolute binning, respiratory phase binning, or realtime
                switch app.RecoTypeDropDown.Value

                    case 'cardiac'
                        app.nrCardFrames = app.nrFrames;
                        app.nrRespFrames = 1;
                        app.r = app.r.assignBinTimes(app);
                        app.r = app.r.assignBinFrames(app);
                     
                    case 'cardiac abs'
                        app.nrCardFrames = app.nrFrames;
                        app.nrRespFrames = 1;
                        app.r = app.r.assignBinTimesAbs(app);
                        app.r = app.r.assignBinFramesAbs(app);

                    case 'respiratory'
                        app.nrCardFrames = 1;
                        app.nrRespFrames = app.nrFrames;
                        app.r = app.r.assignBinTimes(app);
                        app.r = app.r.assignBinFrames(app);

                    case 'realtime'
                        app.nrCardFrames = app.nrFrames;
                        app.nrRespFrames = 1;
                        app.nrDynamics = 1;    % Will be calculated later depending on the number of heartbeats
                        app.r = app.r.assignBinTimes(app);
                        app.r = app.r.assignBinFrames(app);

                end

                % Sort k-space
                switch app.r.dataType

                    case {'2D','2Dms'}

                        if strcmp(app.RecoTypeDropDown.Value,'realtime')
                            % Realtime sorting
                            app.r = app.r.fillKspace2Drealtime(app);
                            app.DynamicsEditField.Value = app.nrDynamics;
                            app.TimeDynViewField.Value = string(duration(seconds(app.acqDur/app.DynamicsEditField.Value),'Format','hh:mm:ss.SSS'));
                        else
                            % 2D and 2D multi-slice sorting
                            app.r = app.r.fillKspace2D(app);
                        end

                    case '3D'

                        % Sorting 3D data
                        app.r = app.r.fillKspace3D(app);

                    case '3Dp2roud'

                        % Sorting 3D proud data
                        app.r = app.r.fillKspace3Dp2roud(app);

                    case {'2Dradial','2Dradialms'}

                        % Sorting 2D radial data
                        if app.UserDefinedRadialButton.Value == 1
                            app.r = app.r.fillKspace2Dradial(app);
                        else
                            app.r = app.r.fillKspace2DradialReg(app);
                        end
                        
                        app.ReconstructionTabGroup.SelectedTab = app.RadialRecoTab;
                     
                    case '3Dute'

                        % Sorting 3D UTE data
                        app.r = app.r.fillKspace3Dute(app);
                        app.ReconstructionTabGroup.SelectedTab = app.RadialRecoTab;
                      
                end

                % Reshape to either cardiac or respiratory CINE
                app.r = app.r.reshapeKspace;

                % Normalize k-space
                app.r = app.r.normKspace;

                % Let the app know the data is sorted
                app.validSortFlag = true;
       
                % K-space statistics
                app.r = app.r.kSpaceStats(app);

                % Reset buttons
                app.SetLightsFcn(7);
                app.SetDashboardButtonsFcn(143);
                app.NavSelButtonsFcn(true);
                app.NavViewingButtonsFcn(true);
                app.CineRecoButtonsFcn(true);
                app.MovieButtonsFcn(false);
                app.TrajMovieButtonsFcn(true);
                app.SetStatus(0);

            catch ME

                % Let the app know the data is not sorted
                app.validSortFlag = false;

                % Reset buttons
                app.SetLightsFcn(3);
                app.SetDashboardButtonsFcn(135);
                app.NavSelButtonsFcn(true);
                app.NavViewingButtonsFcn(true);
                app.CineRecoButtonsFcn(false);
                app.MovieButtonsFcn(false);
                app.SetStatus(2);

                % Error message
                app.TextMessage('ERROR: Something went wrong during sorting of the data into k-space ...')
                app.TextMessage(ME.message);
            
            end

        end

        % Value changed function: ESPIRiTCheckBox, LLRxyzEditField, 
        % ...and 4 other components
        function RecoParEditFieldValueChanged(app, event)

            % Stop movie & reset progress bar
            app.stopMovieFlag = true;
            app.ProgressGauge.Value = 0;

            % Reset buttons
            app.SetLightsFcn(7);
            app.SwitchOffAllButtonsFcn;
            app.SetDashboardButtonsFcn(143);
            app.NavSelButtonsFcn(true);
            app.NavViewingButtonsFcn(true);
            app.CineRecoButtonsFcn(true);
            app.MovieButtonsFcn(false);
         
        end

        % Value changed function: CenterEchoCheckBox, 
        % ...and 2 other components
        function TrajectorySettingsEditFieldValueChanged(app, event)
            
            % Resort the data
            if app.validFilterFlag
                app.SortKspaceButtonPushed;
            end
      
        end

        % Selection changed function: TrajectoryButtonGroup
        function TrajectoryButtonGroupSelectionChanged(app, event)
            
            % Resort the data
            if app.validFilterFlag
                app.SortKspaceButtonPushed;
            end

        end

        % Button pushed function: LcurveStartButton
        function LcurveStartButtonPushed(app, event)

            % Indicate busy
            app.TextMessage('L-curve calculation in progress ...');
            app.SetStatus(3);
            app.stopMovieFlag = true;
            app.stopLcurveFlag = false;
            app.SwitchOffAllButtonsFcn;
            app.LcurveStartButton.Enable = 'off';
            app.LcurveStopButton.Enable = 'on';
            app.MatlabRecoCheckBox.Value = 0;
           
            % L-curve analysis only for simple 2D data
            if app.bartDetectedFlag && ~app.r.multiDynamicFlag && (strcmp(app.r.dataType,'2D')==true || strcmp(app.r.dataType,'2Dms')==true)

                try
                    % Do the L-curve reconstruction
                    app.r = app.r.recoLcurve(app);
                catch ME
                    app.TextMessage('ERROR: Something went wrong during L-curve calculation ...')
                    app.TextMessage(ME.message);
                end

            else

                app.TextMessage('ERROR: L-curve calculations require Bart toolbox and single-dynamic Cartesian 2D data...');
                app.SetStatus(2);

            end

            % Stopped before completion
            if app.stopLcurveFlag
                app.TextMessage('L-curve calculation stopped by user ...');
            end

            % Let the app know there is no valid reco
            app.validRecoFlag = false;

            % Reset buttons
            app.SetLightsFcn(7);
            app.SetDashboardButtonsFcn(143);
            app.NavSelButtonsFcn(true);
            app.NavViewingButtonsFcn(true);
            app.CineRecoButtonsFcn(true);
            app.MovieButtonsFcn(false);
            app.SetStatus(0);

        end

        % Button pushed function: LcurveStopButton
        function LcurveStopButtonPushed(app, event)

            % Stop the L-curve calculations
            app.stopLcurveFlag = true;
            drawnow;

        end

        % Button pushed function: ReconstructButton
        function RecoMoviesButtonPushed(app, event)

            % Indicate busy
            app.SetLightsFcn(7);
            app.Lamp4.Color = app.yellow;
            app.SetStatus(3);
            app.SwitchOffAllButtonsFcn;
            app.ReconstructionTabGroup.SelectedTab = app.RecoTab;

            % Reset some parameters
            app.stopMovieFlag = true;
            app.stopLcurveFlag = true;
            app.stopNavMovieFlag = true;
            app.ProgressGauge.Value = 0;
            app.validRecoFlag = false;
            app.r.multiSliceFlag = false;
            app.r.multiDynamicFlag = false;
            app.validSenseMapFlag = false;
           
            app.sliceNr = 1;
            app.dynamicNr = 1;
            app.MovieSliceEditField.Value = 1;
            app.MovieFrameEditField.Value = 1;
            app.MovieDynEditField.Value = 1;
            app.MovieCheckBox1.Value = 1;
            app.MovieCheckBox2.Value = 0;
            app.MovieCheckBox3.Value = 0;
            app.imageRot = 0;

            % Reconstruction
            try

                switch app.r.dataType

                    case {'2D','2Dms'}

                        % Reconstruction of 2D and 2D multi-slice data
                        if app.CompressedSensingButton.Value
                            app.r = app.r.reco2DCS(app);
                        else
                            app.r = app.r.reco2DAI(app);
                        end

                    case {'3D','3Dp2roud'}

                        % Reconstruction of 3D Cartesian data
                        app.r = app.r.reco3D(app);

                    case {'2Dradial','2Dradialms'}

                        % Reconstruction of 2D radial data
                        app.r = app.r.reco2Dradial(app);

                    case '3Dute'

                        % Reconstruction of 3D UTE data
                        app.r = app.r.reco3Dute(app);

                end

            catch ME

                app.TextMessage(ME.message);
                app.validRecoFlag = false;
                app.validSenseMapFlag = false;

            end

            % Successful reco or not
            if app.validRecoFlag

                % Gibss ringing suppression
                if app.RingRmCheckBox.Value
                    app.r = app.r.unRing(app);
                end

                % Denoising filter
                if app.PCACheckBox.Value
                    app.r = app.r.PCAdenoise(app);
                end

                % Shift image on sub-pixel level for certain data
                try
                    switch app.r.dataType
                        case {'2Dradial','2Dradialms'}
                            app.r = app.r.shiftImages2D(app);
                        case {'3D','3Dp2roud','3Dute'}
                            app.r = app.r.shiftImages3D(app);
                    end
                catch ME
                    app.TextMessage(ME.message);
                end

                % Normalize image intensity somewhat
                app.r = app.r.normImages;
                
                % Set multislice or multidynamic flags
                app.r = app.r.determineMultiDimensions;

                % Number of slices and starting movie slice
                app.nrSlices = size(app.r.movieApp,4);
                app.MovieSliceEditField.Limits = [1 app.nrSlices];
                app.MovieSliceEditField.Value = round(app.nrSlices/2);
                app.sliceNr = app.MovieSliceEditField.Value;

                % Info on phase orientation 
                app.PhaseOrientationViewField.Value = app.r.PHASE_ORIENTATION;
                if app.r.PHASE_ORIENTATION
                    app.TextMessage('INFO: phase orientation = 1 / horizontal ... ')
                else
                    app.TextMessage('INFO: phase orientation = 0 / vertical ... ')
                end
                app.r = app.r.imageOrientLabels(app);
              
                % Reset buttons
                app.SetLightsFcn(15);
                app.SetDashboardButtonsFcn(255);
                app.NavSelButtonsFcn(true);
                app.NavViewingButtonsFcn(true);
                app.CineRecoButtonsFcn(true);
                app.MovieButtonsFcn(true);
                app.SetStatus(0);

                % Start the movie
                app.AutoWLButton.Value = 1;
                app.MovieButton.Value = 1;
                app.MovieButtonGroupSelectionChanged;
                if ~app.AutoRecoCheckBox.Value
                    if app.nrFrames > 1
                        app.MovieStartButtonPushed;
                    end
                else
                    app.PlotMovieFrameFcn;
                end
             
            else

                % Inform the app that the reco was not successful
                app.validRecoFlag = false;
                app.validSenseMapFlag = false;

                % Reset buttons
                app.SetLightsFcn(7);
                app.SwitchOffAllButtonsFcn;
                app.SetDashboardButtonsFcn(135);
                app.NavSelButtonsFcn(true);
                app.NavViewingButtonsFcn(true);
                app.CineRecoButtonsFcn(true);
                app.MovieButtonsFcn(false);
            
                % Error message
                app.TextMessage('ERROR: There was a problem with reconstruction ...');
                app.SetStatus(2);
          
            end

        end

        % Button pushed function: MovieStartButton
        function MovieStartButtonPushed(app, event)

            % Movie is running
            app.stopMovieFlag = false;
            app.TextMessage('Movie playing ...');

            % Stop the other movies
            app.TrajMovieStopButtonPushed;
            app.TrajMovie3DStopButtonPushed;

            % Buttons
            app.MovieButtonsFcn(true);
            app.TrajMovieButtonsFcn(true);
            app.Traj3DMovieButtonsFcn(true);
    
            app.MovieFig.Position = [154,15,280,280];

            % Set the counters
            counter1 = app.MovieFrameEditField.Value;
            counter2 = app.sliceNr;
            counter3 = app.dynamicNr;

            % Show the movie frame
            app.MovieButtonGroupSelectionChanged;

            try % To catch error during exit while movie is still running

                % Play the movie continuously until app.stopMovieFlag==true
                while app.stopMovieFlag == false

                    % Time
                    tic

                    % Calculate movie speed
                    if strcmp(app.RecoTypeDropDown.Value,'realtime')
                        minTimePerFrame = 1/(app.MovieCheckBox1.Value * app.nrFrames + app.MovieCheckBox2.Value*app.nrSlices + app.MovieCheckBox3.Value * app.nrDynamics);
                    else
                        minTimePerFrame = 1/(app.MovieCheckBox1.Value * app.nrFrames);
                        if app.MovieCheckBox1.Value == 0
                            minTimePerFrame = 1/(app.MovieCheckBox2.Value * app.nrSlices + app.MovieCheckBox3.Value * app.nrDynamics);
                        end
                    end

                    % Show the movie frame
                    app.UpdateMovieFrameFcn;

                    chk = false;

                    if app.MovieCheckBox1.Value == 1
                        counter1 = counter1 + 1;
                        chk = true;
                        if counter1 > app.nrFrames
                            counter1 = 1;
                            chk = false;
                        end
                        app.MovieFrameEditField.Value = counter1;
                    end

                    if (app.MovieCheckBox2.Value == 1) && (chk == false)
                        counter2 = counter2 + 1;
                        chk = true;
                        if counter2 > app.r.dimz
                            counter2 = 1;
                            chk = false;
                        end
                        app.MovieSliceEditField.Value = counter2;
                        app.sliceNr = counter2;
                    end

                    if (app.MovieCheckBox3.Value == 1) && (chk == false)
                        counter3 = counter3 + 1;
                        chk = true; %#ok<*NASGU>
                        if counter3 > app.nrDynamics
                            counter3 = 1;
                            chk = false;
                        end
                        app.MovieDynEditField.Value = counter3;
                        app.dynamicNr = counter3;
                    end

                    % Slow movie a bit down with small number of frames
                    mvTime = toc;

                    if mvTime < minTimePerFrame
                        pause(minTimePerFrame-mvTime);
                    end

                end

                app.PlotMovieFrameFcn;

            catch
            end
         
        end

        % Button pushed function: MovieStopButton
        function MovieStopButtonPushed(app, event)

            % Stop the movie
            app.stopMovieFlag = true;
            app.TextMessage('Stopping the movie ...');

            % Reset buttons
            app.MovieButtonsFcn(true);
            app.ExitButton.Enable = 'on';
            drawnow;

        end

        % Value changed function: MovieFrameEditField
        function MovieFrameEditFieldValueChanged(app, event)

            value = app.MovieFrameEditField.Value;

            % If running, stop the movie
            app.MovieStopButtonPushed;

            % Check if input value <= number of cardiac frames
            if value > app.nrFrames
                app.MovieFrameEditField.Value = app.nrFrames;
            end

            % Show the movie frame
            app.PlotMovieFrameFcn;

        end

        % Value changed function: MovieSliceEditField
        function MovieSliceEditFieldValueChanged(app, event)

            value = app.MovieSliceEditField.Value;

            % Check if input value <= number of slices
            if value > app.nrSlices
                app.MovieSliceEditField.Value = app.nrSlices;
            end

            app.sliceNr = app.MovieSliceEditField.Value;

            % Show the movie frame
            app.PlotMovieFrameFcn;

        end

        % Value changed function: MovieDynEditField
        function MovieDynEditFieldValueChanged(app, event)

            value = app.MovieDynEditField.Value;

            % Check if input value <= number of dynamics
            if value > app.nrDynamics
                app.MovieDynEditField.Value = app.nrDynamics;
            end

            app.dynamicNr = app.MovieDynEditField.Value;

            % Show the movie frame
            app.PlotMovieFrameFcn;

        end

        % Value changed function: MovieCheckBox1, MovieCheckBox2, 
        % ...and 1 other component
        function MovieCheckBoxValueChanged(app, event)

            % At least frame movie checkbox values should be active
            if (app.MovieCheckBox1.Value == 0) && (app.MovieCheckBox2.Value == 0) && (app.MovieCheckBox3.Value == 0)
                app.MovieCheckBox1.Value = 1;
            end

        end

        % Value changed function: WindowScaleSlider
        function MovieWindowScaleSliderValueChanged(app, event)

            app.AutoWLButton.Value = 0;
            value = app.WindowScaleSlider.Value;
            app.window = value;
            app.PlotMovieFrameFcn;

        end

        % Value changed function: LevelScaleSlider
        function MovieLevelScaleSliderValueChanged(app, event)

            app.AutoWLButton.Value = 0;
            value = app.LevelScaleSlider.Value;
            app.level = value;
            app.PlotMovieFrameFcn;

        end

        % Value changed function: AutoWLButton
        function AutoWLButtonValueChanged(app, event)
            
            app.PlotMovieFrameFcn;

        end

        % Value changed function: MovieCoilSpinner, SenseMapSpinner1, 
        % ...and 1 other component
        function MovieCoilSpinnerValueChanged(app, event)

            % Show the movie frame
            app.PlotMovieFrameFcn;

        end

        % Button pushed function: MoviecwButton
        function MovieRotateMoviecwButtonPushed(app, event)

            % Rotate movie clock-wise
            app.r.aspectratio = 1/app.r.aspectratio;
            app.imageRot = app.imageRot - 1;
            app.imageRot(app.imageRot==-1) = 3;

            % Adjust orientation labels
            app.r.orientationLabels = circshift(app.r.orientationLabels,-1);

            % Show the movie frame
            app.PlotMovieFrameFcn;

            % Reset buttons
            app.Lamp5.Color = app.brightred;
            app.Lamp6.Color = app.brightred;

        end

        % Button pushed function: MovieccwButton
        function MovieRotateMovieccwButtonPushed(app, event)

            % Rotate movie counter-clock-wise
            app.r.aspectratio = 1/app.r.aspectratio;
            app.imageRot = app.imageRot + 1;
            app.imageRot(app.imageRot==4) = 0;

            % Adjust orientation labels
            app.r.orientationLabels = circshift(app.r.orientationLabels,1);

            % Show the movie frame
            app.PlotMovieFrameFcn;

            % Reset buttons
            app.Lamp5.Color = app.brightred;
            app.Lamp6.Color = app.brightred;

        end

        % Button pushed function: MovieFrameIncrButton
        function MovieFrameIncrButtonPushed(app, event)

            % Shift movie +1 frame along the time/frame axis

            switch app.r.dataType

                case {'2D','3D','2Dradial','3Dp2roud','3Dute'}

                    app.r.movieExp = circshift(app.r.movieExp,-1,1);
                    app.r.movieApp = circshift(app.r.movieApp,-1,1);
                    for i = 1:app.r.nr_coils
                        app.r.kSpace{i} = circshift(app.r.kSpace{i},-1,1);
                    end
                    app.r.kSpaceAvg = circshift(app.r.kSpaceAvg,-1,1);

                case {'2Dms','2Dradialms'}

                    % Shift for the reconstructed movie slice
                    shiftedMovieSlice = circshift(app.r.movieExp(:,:,:,app.sliceNr),-1,1);
                    app.r.movieExp(:,:,:,app.sliceNr) = shiftedMovieSlice;

                    % Shift for the movie slice
                    shiftedMovieSlice = circshift(app.r.movieApp(:,:,:,app.sliceNr),-1,1);
                    app.r.movieApp(:,:,:,app.sliceNr) = shiftedMovieSlice;

                    % Shift k-space movie for all coils
                    for i = 1:app.r.nr_coils
                        shiftedMovieSlice = circshift(app.r.kSpace{i}(:,:,:,app.sliceNr),-1,1);
                        app.r.kSpace{i}(:,:,:,app.sliceNr) = shiftedMovieSlice;
                    end

                    % Shift the averages movie
                    shiftedMovieSlice = circshift(app.r.kSpaceAvg(:,:,:,app.sliceNr),-1,1);
                    app.r.kSpaceAvg(:,:,:,app.sliceNr) = shiftedMovieSlice;

            end

            % Show the movie frame
            app.PlotMovieFrameFcn;

            % Reset buttons
            app.Lamp5.Color = app.brightred;
            app.Lamp6.Color = app.brightred;

        end

        % Button pushed function: MovieFrameDecrButton
        function MovieFrameDecrButtonPushed(app, event)

            % Shift movie -1 frame along the time/frame axis

            switch app.r.dataType

                case {'2D','3D','2Dradial','3Dp2roud','3Dute'}

                    app.r.movieExp = circshift(app.r.movieExp,1,1);
                    app.r.movieApp = circshift(app.r.movieApp,1,1);
                    for i = 1:app.r.nr_coils
                        app.r.kSpace{i} = circshift(app.r.kSpace{i},1,1);
                    end
                    app.r.kSpaceAvg = circshift(app.r.kSpaceAvg,1,1);

                case {'2Dms','2Dradialms'}

                    % Shift for the reconstructed movie slice
                    shiftedMovieSlice = circshift(app.r.movieExp(:,:,:,app.sliceNr),1,1);
                    app.r.movieExp(:,:,:,app.sliceNr) = shiftedMovieSlice;

                    % Shift for the movie slice
                    shiftedMovieSlice = circshift(app.r.movieApp(:,:,:,app.sliceNr),1,1);
                    app.r.movieApp(:,:,:,app.sliceNr) = shiftedMovieSlice;

                    % Shift k-space movie for all coils
                    for i = 1:app.r.nr_coils
                        shiftedMovieSlice = circshift(app.r.kSpace{i}(:,:,:,app.sliceNr),1,1);
                        app.r.kSpace{i}(:,:,:,app.sliceNr) = shiftedMovieSlice;
                    end

                    % Shift the averages movie
                    shiftedMovieSlice = circshift(app.r.kSpaceAvg(:,:,:,app.sliceNr),1,1);
                    app.r.kSpaceAvg(:,:,:,app.sliceNr) = shiftedMovieSlice;

            end

            % Show the movie frame
            app.PlotMovieFrameFcn;

            % Reset buttons
            app.Lamp5.Color = app.brightred;
            app.Lamp6.Color = app.brightred;

        end

        % Button pushed function: MovieSliceIncrButton
        function MovieSliceIncrButtonPushed(app, event)

            % Increment the slice number by 1
            app.sliceNr = app.sliceNr + 1;

            if app.kSpaceButton.Value == 1 || app.AveragesButton.Value == 1
                app.sliceNr(app.sliceNr > size(app.r.data{1},2)) = 1;
            else
                app.sliceNr(app.sliceNr > app.nrSlices) = 1;
            end

            app.MovieSliceEditField.Value = app.sliceNr;

            % Show the movie frame
            app.PlotMovieFrameFcn;

        end

        % Button pushed function: MovieSliceDecrButton
        function MovieSliceDecrButtonPushed(app, event)

            % Decrease the slice number by 1
            app.sliceNr = app.sliceNr - 1;

            if app.kSpaceButton.Value == 1 || app.AveragesButton.Value == 1
                app.sliceNr(app.sliceNr < 1) = size(app.r.data{1},2);
            else
                app.sliceNr(app.sliceNr < 1) = app.nrSlices;
            end

            app.MovieSliceEditField.Value = app.sliceNr;

            % Show the movie frame
            app.PlotMovieFrameFcn;

        end

        % Button pushed function: MovieDynIncrButton
        function MovieDynIncrButtonPushed(app, event)
   
            % Increment the dynamic number by 1
            app.dynamicNr = app.dynamicNr + 1;
            
            app.dynamicNr(app.dynamicNr > app.nrDynamics) = 1;

            app.MovieDynEditField.Value = app.dynamicNr;

            % Show the movie frame
            app.PlotMovieFrameFcn;

        end

        % Button pushed function: MovieDynDecrButton
        function MovieDynDecrButtonPushed(app, event)

            % Decrease the dynamic number by 1
            app.dynamicNr = app.dynamicNr - 1;

            app.dynamicNr(app.dynamicNr < 1) = app.nrDynamics;

            app.MovieDynEditField.Value = app.dynamicNr;

            % Show the movie frame
            app.PlotMovieFrameFcn;

        end

        % Selection changed function: ButtonGroup
        function MovieButtonGroupSelectionChanged(app, event)

            % Show the movie frame
            app.PlotMovieFrameFcn;

        end

        % Button pushed function: TrajMovieStartButton
        function TrajMovieStartButtonPushed(app, event)
            
            % Movie is running
            app.stopRadialMovieFlag = false;
            app.MovieStopButtonPushed;
            app.TrajMovie3DStopButtonPushed;
            app.TextMessage('Radial trajectory movie playing ...');

            % Buttons
            app.MovieButtonsFcn(true);
            app.TrajMovieButtonsFcn(true);
            app.Traj3DMovieButtonsFcn(true);
           
            % Determine limits of the dimensions
            nFrames = size(app.r.kSpaceTraj,1);
            nSlices = size(app.r.kSpaceTraj,4);
            nDynamics = size(app.r.kSpaceTraj,5);

            % Prefill the trajectory array to increase display speed
            app.trajPlotData = {};
            totalNrSpokes = 0;
            shownNrSpokes = 0;

            for counter1 = 1:nFrames
                for counter2 = 1:nSlices
                    for counter3 = 1:nDynamics

                        % The spokes from each dimension
                        xCrds = squeeze(app.r.kSpaceTraj(counter1,:,:,counter2,counter3,1))';
                        yCrds = squeeze(app.r.kSpaceTraj(counter1,:,:,counter2,counter3,2))';
                        zCrds = squeeze(app.r.kSpaceTraj(counter1,:,:,counter2,counter3,3))';
                       
                        % Remove all zero entries
                        zeroElements = ~((xCrds(:,end)==0) & (yCrds(:,end)==0) & (zCrds(:,end)==0));
                        xCrds = xCrds(zeroElements,:);
                        yCrds = yCrds(zeroElements,:);
                        zCrds = zCrds(zeroElements,:);
                        totalNrSpokes = totalNrSpokes + size(xCrds,1);

                        % Restrict number of visible spokes to maxNrSpokesPlot per frame
                        trajSize = size(xCrds,1);
                        skip = ceil(trajSize/app.maxNrSpokesPlot);
                        xCrds = xCrds(1:skip:end,[1 end])';
                        yCrds = yCrds(1:skip:end,[1 end])';
                        zCrds = zCrds(1:skip:end,[1 end])';
                        shownNrSpokes = shownNrSpokes + size(xCrds,2);

                        % Make sure that all dimensions are equal
                        xCrdsSl = zeros(2,app.maxNrSpokesPlot);
                        yCrdsSl = zeros(2,app.maxNrSpokesPlot);
                        zCrdsSl = zeros(2,app.maxNrSpokesPlot);
                        xCrdsSl(:,1:size(xCrds,2)) = xCrds(:,:);
                        yCrdsSl(:,1:size(yCrds,2)) = yCrds(:,:);
                        zCrdsSl(:,1:size(zCrds,2)) = zCrds(:,:);

                        % Fill the trajectory plot array
                        app.trajPlotData{counter1,counter2,counter3,1} = xCrdsSl;
                        app.trajPlotData{counter1,counter2,counter3,2} = yCrdsSl;
                        app.trajPlotData{counter1,counter2,counter3,3} = zCrdsSl;

                    end
                end
            end

            % Report number of spokes visualized in the movie
            app.TextMessage(strcat("Total number of spokes = ",num2str(totalNrSpokes)," ..."));
            app.TextMessage(strcat("Numnber of spokes visualized = ",num2str(shownNrSpokes), " ..."));

            % Set the counter
            counter1 = app.TrajMovieFrameEditField.Value;
            counter2 = app.TrajMovieSliceEditField.Value;
            counter3 = app.TrajMovieDynEditField.Value;

            % Reset to 1 if counters exceed max values
            counter1(counter1>nFrames) = 1;
            counter2(counter2>nSlices) = 1;
            counter3(counter3>nDynamics) = 1;

            % Set limits in app
            nFramesLimits = nFrames;     nFramesLimits(nFramesLimits == 1) = 2;
            nSlicesLimits = nSlices;     nSlicesLimits(nSlicesLimits == 1) = 2;
            nDynamicsLimits = nDynamics; nDynamicsLimits(nDynamicsLimits == 1) = 2;
            app.TrajMovieFrameEditField.Limits = [1 nFramesLimits];
            app.TrajMovieSliceEditField.Limits = [1 nSlicesLimits];
            app.TrajMovieDynEditField.Limits = [1 nDynamicsLimits];

            % Image dimension
            dimx = 2*max(app.r.kSpaceTraj(:));

            % Plot the first movie frame
            app.PlotTrajectoryMovieFrameFcn(app.trajPlotData{counter1,counter2,counter3,1},app.trajPlotData{counter1,counter2,counter3,2},app.trajPlotData{counter1,counter2,counter3,3},dimx,true);
       
            try % To catch error during exit while movie is running

                % Play the movie continuously until app.stopMovieRadialFlag==true
                while app.stopRadialMovieFlag == false

                    % Time
                    tic

                    % Calculate movie speed
                    if strcmp(app.RecoTypeDropDown.Value,'realtime')
                        minTimePerFrame = 1/(app.TrajMovieCheckBox1.Value * nFrames + app.TrajMovieCheckBox2.Value*nSlices + app.TrajMovieCheckBox3.Value * nDynamics);
                    else
                        minTimePerFrame = 1/(app.TrajMovieCheckBox1.Value * nFrames);
                        if app.TrajMovieCheckBox1.Value == 0
                            minTimePerFrame = 1/(app.TrajMovieCheckBox2.Value * nSlices + app.TrajMovieCheckBox3.Value * nDynamics);
                        end
                    end

                    chk = false;

                    if app.TrajMovieCheckBox1.Value == 1
                        counter1 = counter1 + 1;
                        chk = true;
                        if counter1 > nFrames
                            counter1 = 1;
                            chk = false;
                        end
                        app.TrajMovieFrameEditField.Value = counter1;
                    end

                    if (app.TrajMovieCheckBox2.Value == 1) && (chk == false)
                        counter2 = counter2 + 1;
                        chk = true;
                        if counter2 > nSlices
                            counter2 = 1;
                            chk = false;
                        end
                        app.TrajMovieSliceEditField.Value = counter2;
                    end

                    if (app.TrajMovieCheckBox3.Value == 1) && (chk == false)
                        counter3 = counter3 + 1;
                        chk = true; %#ok<*NASGU>
                        if counter3 > nDynamics
                            counter3 = 1;
                            chk = false;
                        end
                        app.TrajMovieDynEditField.Value = counter3;
                    end

                    % Plot the new movie frame
                    app.PlotTrajectoryMovieFrameFcn(app.trajPlotData{counter1,counter2,counter3,1},app.trajPlotData{counter1,counter2,counter3,2},app.trajPlotData{counter1,counter2,counter3,3},dimx,false);

                    % Slow movie a bit down with small number of frames
                    mvTime = toc;

                    if mvTime < minTimePerFrame
                        pause(minTimePerFrame-mvTime);
                    end

                end

            catch
            end

        end

        % Button pushed function: TrajMovieStopButton
        function TrajMovieStopButtonPushed(app, event)
            
            % Stop the radial trajectory movie
            app.stopRadialMovieFlag = true;
       
            % Reset buttons
            app.TrajMovieButtonsFcn(true);
            app.ExitButton.Enable = 'on';
            drawnow;

        end

        % Value changed function: TrajMovieDynEditField, 
        % ...and 2 other components
        function TrajMovieDimEditFieldValueChanged(app, event)
            
            if app.validSortFlag

                switch app.r.dataType

                    case {'2Dradial','2Dradialms','3Dute'}

                        % Image dimension
                        dimx = 2*max(app.r.kSpaceTraj(:));

                        % Set the counter
                        counter1 = app.TrajMovieFrameEditField.Value;
                        counter2 = app.TrajMovieSliceEditField.Value;
                        counter3 = app.TrajMovieDynEditField.Value;

                        % Check limits of the dimensions
                        nFrames = size(app.r.kSpaceTraj,1);
                        nSlices = size(app.r.kSpaceTraj,4);
                        nDynamics = size(app.r.kSpaceTraj,5);

                        counter1(counter1>nFrames) = 1;
                        counter2(counter2>nSlices) = 1;
                        counter3(counter3>nDynamics) = 1;

                        app.TrajMovieFrameEditField.Value = counter1;
                        app.TrajMovieSliceEditField.Value = counter2;
                        app.TrajMovieDynEditField.Value = counter3;

                        % Data
                        xc = squeeze(app.r.kSpaceTraj(counter1,:,:,counter2,counter3,1))';
                        yc = squeeze(app.r.kSpaceTraj(counter1,:,:,counter2,counter3,2))';
                        zc = squeeze(app.r.kSpaceTraj(counter1,:,:,counter2,counter3,3))';
                       
                        % Remove all zero entries
                        ze = ~((xc(:,end)==0) & (yc(:,end)==0) & (zc(:,end)==0));
                        xc = xc(ze,[1 end])';
                        yc = yc(ze,[1 end])';
                        zc = zc(ze,[1 end])';

                        % Message
                        app.TextMessage('Displaying all spokes ...');

                        % Plot the new movie frame
                        app.PlotTrajectoryMovieFrameFcn(xc,yc,zc,dimx,true);

                end

            end

        end

        % Value changed function: TrajMovieCheckBox1, TrajMovieCheckBox2, 
        % ...and 1 other component
        function TrajMovieCheckBoxValueChanged(app, event)

            % At least first dimension of the trajectory movie should be active
            if (app.TrajMovieCheckBox1.Value == 0) && (app.TrajMovieCheckBox2.Value == 0) && (app.TrajMovieCheckBox3.Value == 0)
                app.TrajMovieCheckBox1.Value = 1;
            end

        end

        % Button pushed function: TrajMovie3DStartButton
        function TrajMovie3DStartButtonPushed(app, event)
            
            % Stop movie is false
            app.stop3DMovieFlag = false;

            % Stop other movies
            app.TrajMovieStopButtonPushed;
            app.MovieStopButtonPushed;

            % Buttons
            app.MovieButtonsFcn(true);
            app.TrajMovieButtonsFcn(true);
            app.Traj3DMovieButtonsFcn(true);
        
            % Initialize
            numberOfPoints = 10;
            pointList = zeros(numberOfPoints,3);
            trajectoryPoints = app.r.trajectory'/max(app.r.trajectory(:));
            maxLength = size(trajectoryPoints,1);
            minTimePerFrame = 0.005;
         
            % Initialize figure
            cla(app.TrajectoryLargeFig);
            plot3(app.TrajectoryLargeFig,[],[],[]);
            view(app.TrajectoryLargeFig,[-37.5 30]);
            xlim(app.TrajectoryLargeFig,[-1.1 1.1]);
            ylim(app.TrajectoryLargeFig,[-1.1 1.1]);
            zlim(app.TrajectoryLargeFig,[-1.1 1.1]);
            set(app.TrajectoryLargeFig,'XTickLabel',[]);
            set(app.TrajectoryLargeFig,'YTickLabel',[]);
            set(app.TrajectoryLargeFig,'ZTickLabel',[]);
            box(app.TrajectoryLargeFig,'on');
            app.TrajectoryLargeFig.Interactions = rotateInteraction;
            app.TrajectoryLargeFig.Toolbar.Visible = 'off';

            % Create the plot elements
            hold(app.TrajectoryLargeFig,'on');
            traj3DPlot1a = plot3(app.TrajectoryLargeFig,pointList(1:5,1),pointList(1:5,2),pointList(1:5,3),'LineWidth',2,'Color',app.blue);
            traj3DPlot1b = plot3(app.TrajectoryLargeFig,pointList(5:8,1),pointList(5:8,2),pointList(5:8,3),'LineWidth',1,'Color',app.blue);
            traj3DPlot1c = plot3(app.TrajectoryLargeFig,pointList(8:10,1),pointList(8:10,2),pointList(8:10,3),'LineWidth',0.5,'Color',app.blue);
            traj3DPlot2 = plot3(app.TrajectoryLargeFig,[0 pointList(1,1)],[0 pointList(1,2)],[0 pointList(1,3)],'LineWidth',3,'Color',app.red);
            traj3DPlot3 = plot3(app.TrajectoryLargeFig,[-1.1 1.1],[0 0],[0 0],':','LineWidth',1,'Color',app.green);
            traj3DPlot4 = plot3(app.TrajectoryLargeFig,[0 0],[-1.1 1.1],[0 0],':','LineWidth',1,'Color',app.green);
            traj3DPlot5 = plot3(app.TrajectoryLargeFig,[0 0],[0 0],[-1.1 1.1],':','LineWidth',1,'Color',app.green);
            hold(app.TrajectoryLargeFig,'off');
            
            app.traj3Dcnt = app.SpokeEditField.Value;
            app.traj3Dcnt(app.traj3Dcnt > maxLength) = 1;

            try % To catch error during exit, while movie is still running

                while ~app.stop3DMovieFlag

                    % Timing
                    tic;

                    % FIFO
                    pointList = circshift(pointList,1);
                    pointList(1,:) = trajectoryPoints(app.traj3Dcnt,:);

                    % Update the animated plot elements
                    set(traj3DPlot1a ,'XData',pointList(1:5,1));
                    set(traj3DPlot1a ,'YData',pointList(1:5,2));
                    set(traj3DPlot1a ,'ZData',pointList(1:5,3));

                    set(traj3DPlot1b ,'XData',pointList(5:8,1));
                    set(traj3DPlot1b ,'YData',pointList(5:8,2));
                    set(traj3DPlot1b ,'ZData',pointList(5:8,3));

                    set(traj3DPlot1c ,'XData',pointList(8:10,1));
                    set(traj3DPlot1c ,'YData',pointList(8:10,2));
                    set(traj3DPlot1c ,'ZData',pointList(8:10,3));

                    set(traj3DPlot2 ,'XData',[0 pointList(1,1)]);
                    set(traj3DPlot2 ,'YData',[0 pointList(1,2)]);
                    set(traj3DPlot2 ,'ZData',[0 pointList(1,3)]);

                    app.traj3Dcnt = app.traj3Dcnt + 1;
                    app.traj3Dcnt(app.traj3Dcnt > maxLength) = 1;
                    app.SpokeEditField.Value = app.traj3Dcnt;

                    drawnow;
                    % Limit speed
                    mvTime = toc;
                    if mvTime < minTimePerFrame
                        pause(minTimePerFrame-mvTime);
                    end

                end

            catch
            end
            
        end

        % Button pushed function: TrajMovie3DStopButton
        function TrajMovie3DStopButtonPushed(app, event)
            
            app.stop3DMovieFlag = true;
        
            % Reset buttons
            app.Traj3DMovieButtonsFcn(true);
            app.ExitButton.Enable = 'on';
            drawnow;

        end

        % Button pushed function: ExportDCMButton
        function ExportDCMButtonPushed(app, event)

            % If running, stop the movie
            app.MovieStopButtonPushed;

            % Indicate busy
            app.SwitchOffAllButtonsFcn;
            app.Lamp5.Color = app.yellow;
            app.SetStatus(3);

            try

                % Search for dicom file
                app.TextMessage('DICOM export ...');

                % Assuming that MRD and Data directory share the same base-directory
                % Example: base / MRD / 343 / 8455 /

                % Base / data / 343 / DICOM / 8455 /
                dcmSpath{1} = replace(app.mrdImportPath,"MRD","Data");
                try
                    k = strfind(dcmSpath{1},"Data");
                    fs = strfind(dcmSpath{1}(k:end),filesep);
                    dcmSpath{1}= dcmSpath{1}(1:k+fs(3)-1);
                    dcmSpath{1} = insertAfter(dcmSpath{1},k+fs(2)-1,strcat("DICOM",filesep));
                    dcmIndx{1} = 2;
                catch
                    dcmSpath{1} = app.mrdImportPath;
                end

                % Base / data / 343 / DICOM / 8455 / X 
                cnt = 1;
                for i = 2:10
                    dcmSpath{i} = strcat(dcmSpath{1},num2str(cnt),filesep); %#ok<AGROW> 
                    dcmIndx{i} = 3; %#ok<AGROW> 
                    cnt = cnt + 1;
                end

                % B-type Base / pdata / X / dicom  
                cnt = 1;
                for i = 11:20
                    dcmSpath{i} = strcat(app.mrdImportPath,filesep,"pdata",filesep,num2str(cnt),filesep,"dicom",filesep); %#ok<AGROW> 
                    dcmIndx{i} = 1; %#ok<AGROW> 
                end

                % Check if one of the directories contains dicom files
                dcmExist = false;
                for i=1:length(dcmSpath)
                    if ~isempty(dir(strcat(dcmSpath{i},"*.dcm")))
                        dcmPath = dcmSpath{i};
                        dcmPath = strrep(dcmPath,[filesep,filesep],filesep);
                        dcmPath = convertStringsToChars(dcmPath);
                        app.TextMessage(strcat("Found DICOM information in ",dcmPath));
                        filesepIdx = strfind(dcmPath, filesep);
                        app.dicomExportPath = dcmPath(1:filesepIdx(end-dcmIndx{i}));
                        dcmExist = true;
                        break;
                    end
                end

                % Export to DICOM
                if dcmExist

                    % Export, reading dicom information from dcmPath
                    try
                        % Check if directory is actually writable
                        mkdir(strcat(app.dicomExportPath,filesep,"testdir"));
                        rmdir(strcat(app.dicomExportPath,filesep,"testdir"));
                    catch
                        f = figure('CloseRequestFcn','','Position',[-100 -100 0 0]); % Needed to bring uigetfile in front
                        app.dicomExportPath = uigetdir(app.dicomExportPath,"DICOM export folder");
                        delete(f);
                        figure(app.UIFigure);  % Put the app back in the foreground
                        drawnow;
                        if ~ischar(app.dicomExportPath)
                            ME = MException('Retrospective:userCancel','User cancel ...');
                            throw(ME);
                        end
                    end
                    app.r.exportDir = retroExportDicomDcm(app,dcmPath);
                    app.r.ExportRecoParametersFcn(app);

                else

                    % Export without image orientation information
                    f = figure('CloseRequestFcn','','Position',[-100 -100 0 0]); % Needed to bring uigetfile in front
                    app.dicomExportPath = uigetdir(app.dicomExportPath,"DICOM export folder");
                    delete(f);
                    figure(app.UIFigure);  % Put the app back in the foreground
                    drawnow;
                    if ~ischar(app.dicomExportPath)
                        ME = MException('Retrospective:userCancel','User cancel ...');
                        throw(ME);
                    end
                    app.TextMessage('WARNING: DICOM image orientation information not available ...');
                    app.SetStatus(1);
                    app.r.exportDir = retroExportDicomMat(app);
                    app.r.ExportRecoParametersFcn(app);

                end

                % Export successful
                app.Lamp5.Color = app.brightgreen;
                app.SetStatus(0);

            catch ME

                % Export not successful
                app.TextMessage('ERROR: problem writing DICOM files ...');
                app.TextMessage(ME.message);
                app.dicomExportPath = '';
                app.Lamp5.Color = app.brightred;
                app.SetStatus(2);

            end

            % Reset buttons
            app.SetDashboardButtonsFcn(255);
            app.NavSelButtonsFcn(true);
            app.NavViewingButtonsFcn(true);
            app.CineRecoButtonsFcn(true);
            app.MovieButtonsFcn(true);
         
        end

        % Button pushed function: ExportGIFButton
        function ExportGIFButtonPushed(app, event)

            % If running, stop the movie
            app.MovieStopButtonPushed;

            % Indicate busy
            app.SwitchOffAllButtonsFcn;
            app.Lamp6.Color = app.yellow;
            app.SetStatus(3);

            app.TextMessage('Animated GIF export ...');

            % Export to animated gif
            try

                try
                    gifExportDir = replace(app.mrdImportPath, "MRD", "Data");
                    k = strfind(gifExportDir,"Data");
                    fs = strfind(gifExportDir(k:end),filesep);
                    gifExportDir = gifExportDir(1:k+fs(2)-1);
                    % Check if directory is actually writable
                    mkdir(strcat(gifExportDir,filesep,"testdir"));
                    rmdir(strcat(gifExportDir,filesep,"testdir"));
                catch
                    f = figure('CloseRequestFcn','','Position',[-100 -100 0 0]); % Needed to bring uigetfile in front
                    gifExportDir = uigetdir(app.gifExportPath,'GIF export path');
                    delete(f);
                    figure(app.UIFigure);  % Put the app back in the foreground
                    if ~ischar(app.gifExportPath)
                        ME = MException('Retrospective:userCancel','User cancel ...');
                        throw(ME);
                    end
                end
                app.gifExportPath = gifExportDir;

                % Export to animated gif
                app.TextMessage('GIF export ...');
                app.r.exportDir = retroExportGifFcn(app);
                app.r.ExportRecoParametersFcn(app);
                app.Lamp6.Color = app.brightgreen;
                app.SetStatus(0);

            catch ME

                app.TextMessage('ERROR: problem writing GIF files ...');
                app.TextMessage(ME.message);
                app.gifExportPath = '';
                app.Lamp6.Color = app.brightred;
                app.SetStatus(2);

            end

            % Reset buttons
            app.SetDashboardButtonsFcn(255);
            app.NavSelButtonsFcn(true);
            app.NavViewingButtonsFcn(true);
            app.CineRecoButtonsFcn(true);
            app.MovieButtonsFcn(true);
        
        end

        % Button pushed function: ExportMRDButton
        function ExportMRDButtonPushed(app, event)

            % If running, stop the movie
            app.MovieStopButtonPushed;

            % Indicate busy
            app.SwitchOffAllButtonsFcn;
            app.Lamp7.Color = app.yellow;
            app.SetStatus(3);
            
            app.TextMessage('MRD and SUR file export ...');

            try

                % Make new k-space from the movie
                app.r = app.r.backToKspace;

                % New parameters for MRD footer
                [par.NoSamples,par.NoViews,par.NoViews2,par.NoSlices,par.NoEchoes,par.NoExperiments] = size(app.r.kSpaceMrd);
                par.NoAverages = round(app.AveragesViewField.Value);

                % Reconstruction info
                par.peorder = 0;
                par.slicenav = 0;
                par.radialon = 0;
                par.frameloopon = 1;
                par.pe2_centric_on = 0;
                par.batchslices = 0;
                par.View1order = "SEQUENTIAL";
                par.View2order = "SEQUENTIAL";
                par.channelCount = 1;
                par.channelWeights = 1;
                par.reconMethod = "FFT";
                par.viewsPerSegment = 1;

                % TR, TI and TE parameters
                if app.NrFlipAnglesViewField.Value > 1
                    par.tr = floor(app.r.TR);                                                   % Data for T1 mapping needs original TR
                    par.tr_extra_us = (app.r.TR - par.tr)*1000;
                    par.ti = round(1000*(60/app.r.meanHeartRate)/app.nrFrames,2);               % Use ti parameter for time between frames (will be recognized by T1 mapping app)
                else
                    if contains(app.RecoTypeDropDown.Value,'respiratory')
                        trTemp = 1000*(60/app.r.meanRespRate)/app.nrFrames;
                    else
                        trTemp = 1000*(60/app.r.meanHeartRate)/app.nrFrames;
                    end
                    par.tr = floor(trTemp);                                                     % CINE data needs TR adjusted to new frame rate
                    par.tr_extra_us = (trTemp - par.tr)*1000;
                    par.ti = app.r.TR;
                end
                par.te = 0;                                                                     % Set to zero, because no_echoes dimension is used for frames

                % Flip angles
                par.vfasize = app.r.VFA_size;
                vfaArraySize = par.vfasize;                                                     % Case of multiple flip angles
                vfaArraySize(vfaArraySize == 0) = length(app.r.VFA_angles);                     % Case of only 1 flip angle
                par.vfaangles = [vfaArraySize, app.r.VFA_angles];

                % Create new MRD footer
                app.r = app.r.makeMrdFooter(par);

                % Create new RPR file
                if app.r.rprFlag
                    app.r = app.r.makeRprFile(par);
                end

                % Export the new MRD and rpr files to the original MRD path
                app.mrdExportPath = app.mrdImportPath;
                
                % Check if directory is actually writable, else ask for different folder
                try
                    mkdir(strcat(app.mrdExportPath,filesep,"testdir"));
                    rmdir(strcat(app.mrdExportPath,filesep,"testdir"));
                catch
                    f = figure('CloseRequestFcn','','Position',[-100 -100 0 0]); % Needed to bring uigetfile in front
                    app.mrdExportPath = uigetdir(app.mrdExportPath,'MRD export folder');
                    delete(f);
                    figure(app.UIFigure);  % Put the app back in the foreground
                    if ~ischar(app.mrdExportPath)
                        ME = MException('Retrospective:userCancel','User cancel ...');
                        throw(ME);
                    end
                end

                mrdExportFilename = strcat(app.mrdExportPath,filesep,'retro_',num2str(par.NoExperiments),'_',num2str(par.NoEchoes),'_',app.tag,'.MRD');
                rprExportFilename = strcat(app.mrdExportPath,filesep,'retro_',num2str(par.NoExperiments),'_',num2str(par.NoEchoes),'_',app.tag,'.rpr');
              
                app.r.writeDataToMrd(mrdExportFilename, par);
                if app.r.rprFlag
                    app.r.writeToRprFile(rprExportFilename);
                end
                app.r.writePhysLog(app.mrdExportPath);
                app.r.exportDir = app.mrdExportPath;
                app.r = app.r.ExportRecoParametersFcn(app);

                % Try to autoreconstruct and export SUR files
                if ispc

                    % Make sure path name has trailing filesep
                    app.mrdImportPath = strcat(app.mrdImportPath,filesep);
                    dblSlash = strcat(filesep,filesep);
                    app.mrdImportPath = strrep(app.mrdImportPath,dblSlash,filesep);

                    % Determine SUR directory
                    surPathFound = false;
                    try
                        scanPos = strfind(app.mrdImportPath,filesep);
                        scanTag = strcat(app.mrdImportPath(scanPos(end-2)+1:scanPos(end-1)-1),filesep,'Image',filesep,app.mrdImportPath(scanPos(end-1)+1:scanPos(end)-1));
                        surPath = winqueryreg('HKEY_LOCAL_MACHINE','Software\MRS\MRSClinicalUI\Scan','BaseStorageDirectory');
                        surPath1 = strcat(surPath,filesep,scanTag,filesep);
                        surPath2 = strcat(surPath,filesep,scanTag,filesep,'1',filesep);
                        % Check if directory is actually writable
                        mkdir(strcat(surPath2,filesep,"testdir"));
                        rmdir(strcat(surPath2,filesep,"testdir"));
                        app.TextMessage('Image directory found in Windows registry ...');
                        surPathFound = true;
                    catch
                    end

                    % If not found automatically, try different way
                    if ~surPathFound
                        try
                            surPath = replace(app.mrdImportPath,"MRD","Data");
                            k = strfind(surPath,"Data");
                            fs = strfind(surPath(k:end),filesep);
                            surPath = surPath(1:k+fs(3)-1);
                            imageSlash = strcat("Image",filesep);
                            surPath1 = insertAfter(surPath,k+fs(2)-1,imageSlash);
                            surPath1 = strrep(surPath1,dblSlash,filesep);
                            surPath2 = strcat(surPath1,filesep,'1',filesep);
                            surPath2 = strrep(surPath2,dblSlash,filesep);
                            % Check if directory is actually writable
                            mkdir(strcat(surPath2,filesep,"testdir"));
                            rmdir(strcat(surPath2,filesep,"testdir"));
                            surPathFound = true;
                        catch
                        end
                    end

                    % If SUR / image path successfully constructed
                    if surPathFound
                        try

                            % prefer /1/ path
                            surPath = surPath1;
                            if (exist(surPath2, 'dir'))
                                surPath = surPath2;
                            end

                            app.TextMessage(strcat("SUR directory = ",surPath));

                            % SUR file names
                            surSuffix = extractBefore(app.tag,'_');

                            % Make and save the new SUR files
                            app.r.recoSurFiles(surPath,surSuffix,mrdExportFilename,rprExportFilename);

                        catch ME1
                            app.TextMessage('WARNING: Automatic reconstruction of SUR files failed ...');
                            app.TextMessage(ME1.message);
                            app.SetStatus(1);
                        end
                    end

                end

                % Export sorted k-Space   [ coils, frames, x, y, z/slices, dynamics ]
                kExportFilename = strcat(app.mrdExportPath,filesep,'retroData_',num2str(par.NoExperiments),'_',num2str(par.NoEchoes),'_',app.tag,'_f',num2str(app.FillingViewField.Value),'.mat');
                kData = complex(zeros([app.r.nr_coils size(app.r.kSpace{1})]));
                for coil = 1:app.r.nr_coils
                    kData(coil,:,:,:,:,:,:) = app.r.kSpace{coil};
                end
                iData = app.r.movieExp;
                aData = app.r.kSpaceAvg;
                fData = logical(app.r.kSpaceAvg);
                rData = app.r.recoPars;
                save(kExportFilename,'kData','iData','fData','aData','rData','-mat');

                % Completed MRD, SUR, and k-space export successfully
                app.Lamp7.Color = app.brightgreen;
                app.SetStatus(0);

            catch ME

                % No success
                app.TextMessage(ME.message)
                app.TextMessage('ERROR: Export failed ...');
                app.mrdExportPath = '';
                app.Lamp7.Color = app.brightred;
                app.SetStatus(2);

            end

            % Reset buttons
            app.SetDashboardButtonsFcn(255);
            app.NavSelButtonsFcn(true);
            app.NavViewingButtonsFcn(true);
            app.CineRecoButtonsFcn(true);
            app.MovieButtonsFcn(true);
         
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            
            key = event.Key;

            try

                switch key

                    case 'o'

                        % Toggle image orientations labels flag
                        app.orientationsFlag = ~app.orientationsFlag;
                        if app.orientationsFlag
                            app.TextMessage('Image orientation labels on ...');
                        else
                            app.TextMessage('Image Orientation labels off ...');
                        end

                    case 's'

                        % Save the app ui in a png image
                        app.TextMessage('Saving UI ...');
                        exportapp(app.UIFigure,"retrospectiveUI.png");

                    case 'a'

                        % Reset some parameters
                        app.stopMovieFlag = true;
                        app.stopLcurveFlag = true;
                        app.stopNavMovieFlag = true;
                        app.ProgressGauge.Value = 0;
                        app.validRecoFlag = false;
                        app.r.multiSliceFlag = false;
                        app.r.multiDynamicFlag = false;
                        app.validSenseMapFlag = false;

                        app.sliceNr = 1;
                        app.dynamicNr = 1;
                        app.MovieSliceEditField.Value = 1;
                        app.MovieFrameEditField.Value = 1;
                        app.MovieDynEditField.Value = 1;
                        app.MovieCheckBox1.Value = 1;
                        app.MovieCheckBox2.Value = 0;
                        app.MovieCheckBox3.Value = 0;
                        app.imageRot = 0;

                        % Load 3rd party reconstruction from *.mat file
                        app.TextMessage('Mat file recall');

                        try

                            f = figure('CloseRequestFcn','','Position',[-100 -100 0 0]); % Needed to bring uigetfile in front
                            [matFileName,matFilePath] = uigetfile(strcat(app.mrdImportPath,filesep,{'*.mat'}));
                            delete(f);
                            figure(app.UIFigure);  % Put the app back in the foreground

                            matPars = load(strcat(matFilePath,filesep,matFileName));

                            [app.r, err] = app.r.extractMatPar(app, matPars.rData);

                            if ~err

                                app.FilterButtonPushed;
                                app.SortKspaceButtonPushed;

                                mv = app.r.maxImageValue * matPars.dData / max(matPars.dData(:));
                                app.r.movieExp = mv;
                                app.r.movieApp = mv;

                                app.nrSlices = size(app.r.movieApp,4);
                                app.nrDynamics = size(app.r.movieApp,5);

                                app.validRecoFlag = true;
                                app.r.multiSliceFlag = app.nrSlices > 1;
                                app.r.multiDynamicFlag = app.nrDynamics > 1;

                                % Slices and dynamics
                                if app.nrSlices > 1
                                    app.MovieSliceEditField.Limits = [1 app.nrSlices];
                                    app.MovieSliceEditField.Value = round(app.nrSlices/2);
                                    app.sliceNr = app.MovieSliceEditField.Value;
                                else
                                    app.MovieSliceEditField.Limits = [1 2];
                                    app.MovieSliceEditField.Value = 1;
                                    app.sliceNr = 1;
                                end
                                if app.nrDynamics > 1
                                    app.MovieDynEditField.Limits = [1 app.nrDynamics];
                                else
                                    app.MovieDynEditField.Limits = [1 2];
                                end
                                app.MovieDynEditField.Value = 1;
                                app.dynamicNr = 1;

                                % Reset buttons
                                app.SetLightsFcn(15);
                                app.SetDashboardButtonsFcn(255);
                                app.NavSelButtonsFcn(true);
                                app.NavViewingButtonsFcn(true);
                                app.CineRecoButtonsFcn(true);
                                app.MovieButtonsFcn(true);
                                app.SetStatus(0);

                                % Start the movie
                                app.MovieButton.Value = 1;
                                app.MovieButtonGroupSelectionChanged;
                                if ~app.AutoRecoCheckBox.Value
                                    if app.nrFrames > 1
                                        app.MovieStartButtonPushed;
                                    end
                                else
                                    app.PlotMovieFrameFcn;
                                end

                            else

                                ME = MException('Retrospective:parError','Mat parameter file error ...');
                                throw(ME);

                            end

                        catch ME

                            % Problem with the MAT file
                            app.TextMessage(ME.message);

                            app.validRecoFlag = false;
                            app.validSenseMapFlag = false;

                            % Reset buttons
                            app.SetLightsFcn(1);
                            app.SetDashboardButtonsFcn(131);
                            app.NavViewingButtonsFcn(false);
                            app.CineRecoButtonsFcn(false);
                            app.MovieButtonsFcn(false);
                            app.NavSelButtonsFcn(true);
                            app.SetStatus(2);

                        end

                end


            catch ME

                app.TextMessage(ME.message);

            end

        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
           
            % Remove subfolders from search path
            if (~isdeployed)

                info = dir(which('retrospective.mlapp'));
                app.appDir = info.folder;

                try
                    delete(strcat(app.appDir,filesep,'drim',filesep,'__pycache__',filesep,'*.pyc'));
                catch
                end

                rmpath(genpath(app.appDir));
                cd(app.oldDir);
            end

            % Exit
            app.delete;
          
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Icon = fullfile(pathToMLAPP, 'Ricon.png');
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.Credits = uilabel(app.UIFigure);
            app.Credits.Tooltip = {'g.j.strijkers@amsterdamumc.nl'};
            app.Credits.Text = 'Gustav Strijkers, Amsterdam UMC, Version X.X.X';
            app.TulipImage = uiimage(app.UIFigure);
            app.MRSImage = uiimage(app.UIFigure);
            app.StatusBackgroundImage = uiimage(app.UIFigure);
            app.StatusBackgroundBorder = uieditfield(app.UIFigure, 'text');
            app.StatusImage = uiimage(app.UIFigure);
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = 'status';
            app.ReconstructionTabGroup = uitabgroup(app.UIFigure);
            app.RecoTab = uitab(app.ReconstructionTabGroup);
            app.RecoTab.Title = 'Reco';
            app.MovieFig = uiaxes(app.RecoTab);
            app.MovieFig.XLim = [0 255];
            app.MovieFig.YLim = [0 255];
            app.MovieFig.XTick = [];
            app.MovieFig.YTick = [];
            app.MovieStopButton = uibutton(app.RecoTab, 'push');
            app.MovieStopButton.ButtonPushedFcn = createCallbackFcn(app, @MovieStopButtonPushed, true);
            app.MovieStopButton.Enable = 'off';
            app.MovieStopButton.Tooltip = {'Stop the movie'};
            app.MovieStopButton.Text = 'Stop';
            app.MovieStartButton = uibutton(app.RecoTab, 'push');
            app.MovieStartButton.ButtonPushedFcn = createCallbackFcn(app, @MovieStartButtonPushed, true);
            app.MovieStartButton.Enable = 'off';
            app.MovieStartButton.Tooltip = {'Start the movie'};
            app.MovieStartButton.Text = 'Start';
            app.ScaleLabel = uilabel(app.RecoTab);
            app.ScaleLabel.Tooltip = {'Level and Window'};
            app.ScaleLabel.Text = 'Scale';
            app.MoviecwButton = uibutton(app.RecoTab, 'push');
            app.MoviecwButton.ButtonPushedFcn = createCallbackFcn(app, @MovieRotateMoviecwButtonPushed, true);
            app.MoviecwButton.Enable = 'off';
            app.MoviecwButton.Tooltip = {'Rotate movie cw'};
            app.MoviecwButton.Text = '';
            app.MovieccwButton = uibutton(app.RecoTab, 'push');
            app.MovieccwButton.ButtonPushedFcn = createCallbackFcn(app, @MovieRotateMovieccwButtonPushed, true);
            app.MovieccwButton.Enable = 'off';
            app.MovieccwButton.Tooltip = {'Rotate movie cc'};
            app.MovieccwButton.Text = '';
            app.MovieRotateLabel = uilabel(app.RecoTab);
            app.MovieRotateLabel.Enable = 'off';
            app.MovieRotateLabel.Tooltip = {'Rotate movie'};
            app.MovieRotateLabel.Text = 'rotate';
            app.MovieFrameIncrButton = uibutton(app.RecoTab, 'push');
            app.MovieFrameIncrButton.ButtonPushedFcn = createCallbackFcn(app, @MovieFrameIncrButtonPushed, true);
            app.MovieFrameIncrButton.Enable = 'off';
            app.MovieFrameIncrButton.Tooltip = {'Frame number +1'};
            app.MovieFrameIncrButton.Text = '+1';
            app.MovieFrameLabel = uilabel(app.RecoTab);
            app.MovieFrameLabel.Enable = 'off';
            app.MovieFrameLabel.Tooltip = {'Frame'};
            app.MovieFrameLabel.Text = 'frame';
            app.MovieFrameDecrButton = uibutton(app.RecoTab, 'push');
            app.MovieFrameDecrButton.ButtonPushedFcn = createCallbackFcn(app, @MovieFrameDecrButtonPushed, true);
            app.MovieFrameDecrButton.Enable = 'off';
            app.MovieFrameDecrButton.Tooltip = {'Frame number -1'};
            app.MovieFrameDecrButton.Text = '-1';
            app.MovieSliceIncrButton = uibutton(app.RecoTab, 'push');
            app.MovieSliceIncrButton.ButtonPushedFcn = createCallbackFcn(app, @MovieSliceIncrButtonPushed, true);
            app.MovieSliceIncrButton.Enable = 'off';
            app.MovieSliceIncrButton.Tooltip = {'Slice number +1'};
            app.MovieSliceIncrButton.Text = '+1';
            app.MovieSliceLabel = uilabel(app.RecoTab);
            app.MovieSliceLabel.Enable = 'off';
            app.MovieSliceLabel.Tooltip = {'Slice'};
            app.MovieSliceLabel.Text = 'slice';
            app.MovieSliceDecrButton = uibutton(app.RecoTab, 'push');
            app.MovieSliceDecrButton.ButtonPushedFcn = createCallbackFcn(app, @MovieSliceDecrButtonPushed, true);
            app.MovieSliceDecrButton.Enable = 'off';
            app.MovieSliceDecrButton.Tooltip = {'Slice number -1'};
            app.MovieSliceDecrButton.Text = '-1';
            app.ButtonGroup = uibuttongroup(app.RecoTab);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @MovieButtonGroupSelectionChanged, true);
            app.MovieButton = uiradiobutton(app.ButtonGroup);
            app.MovieButton.Enable = 'off';
            app.MovieButton.Tooltip = {'Movie display'};
            app.MovieButton.Text = 'movie';
            app.MovieButton.Value = true;
            app.AveragesButton = uiradiobutton(app.ButtonGroup);
            app.AveragesButton.Enable = 'off';
            app.AveragesButton.Tooltip = {'Map of the number of k-space signal averages'};
            app.AveragesButton.Text = 'averages';
            app.kSpaceButton = uiradiobutton(app.ButtonGroup);
            app.kSpaceButton.Enable = 'off';
            app.kSpaceButton.Tooltip = {'Absolute value of k-space'};
            app.kSpaceButton.Text = '|k-space|';
            app.SenseMapButton = uiradiobutton(app.ButtonGroup);
            app.SenseMapButton.Enable = 'off';
            app.SenseMapButton.Tooltip = {'Coil sensitivity maps'};
            app.SenseMapButton.Text = 'sense-map';
            app.MovieCoilSpinner = uispinner(app.RecoTab);
            app.MovieCoilSpinner.Limits = [1 16];
            app.MovieCoilSpinner.ValueDisplayFormat = '%.0f';
            app.MovieCoilSpinner.ValueChangedFcn = createCallbackFcn(app, @MovieCoilSpinnerValueChanged, true);
            app.MovieCoilSpinner.Enable = 'off';
            app.MovieCoilSpinner.Value = 1;
            app.SenseMapSpinner1 = uispinner(app.RecoTab);
            app.SenseMapSpinner1.Limits = [1 16];
            app.SenseMapSpinner1.ValueDisplayFormat = '%.0f';
            app.SenseMapSpinner1.ValueChangedFcn = createCallbackFcn(app, @MovieCoilSpinnerValueChanged, true);
            app.SenseMapSpinner1.Enable = 'off';
            app.SenseMapSpinner1.Value = 1;
            app.SenseMapSpinner2 = uispinner(app.RecoTab);
            app.SenseMapSpinner2.Limits = [1 16];
            app.SenseMapSpinner2.ValueDisplayFormat = '%.0f';
            app.SenseMapSpinner2.ValueChangedFcn = createCallbackFcn(app, @MovieCoilSpinnerValueChanged, true);
            app.SenseMapSpinner2.Enable = 'off';
            app.SenseMapSpinner2.Value = 1;
            app.MovieDynIncrButton = uibutton(app.RecoTab, 'push');
            app.MovieDynIncrButton.ButtonPushedFcn = createCallbackFcn(app, @MovieDynIncrButtonPushed, true);
            app.MovieDynIncrButton.Enable = 'off';
            app.MovieDynIncrButton.Tooltip = {'Dynamic number +1'};
            app.MovieDynIncrButton.Text = '+1';
            app.MovieDynLabel = uilabel(app.RecoTab);
            app.MovieDynLabel.Enable = 'off';
            app.MovieDynLabel.Tooltip = {'Dynamic'};
            app.MovieDynLabel.Text = 'dynamic';
            app.MovieDynDecrButton = uibutton(app.RecoTab, 'push');
            app.MovieDynDecrButton.ButtonPushedFcn = createCallbackFcn(app, @MovieDynDecrButtonPushed, true);
            app.MovieDynDecrButton.Enable = 'off';
            app.MovieDynDecrButton.Tooltip = {'Dynamic number -1'};
            app.MovieDynDecrButton.Text = '-1';
            app.MovieCheckBox3 = uicheckbox(app.RecoTab);
            app.MovieCheckBox3.ValueChangedFcn = createCallbackFcn(app, @MovieCheckBoxValueChanged, true);
            app.MovieCheckBox3.Enable = 'off';
            app.MovieCheckBox3.Text = '';
            app.MovieCheckBox2 = uicheckbox(app.RecoTab);
            app.MovieCheckBox2.ValueChangedFcn = createCallbackFcn(app, @MovieCheckBoxValueChanged, true);
            app.MovieCheckBox2.Enable = 'off';
            app.MovieCheckBox2.Text = '';
            app.MovieCheckBox1 = uicheckbox(app.RecoTab);
            app.MovieCheckBox1.ValueChangedFcn = createCallbackFcn(app, @MovieCheckBoxValueChanged, true);
            app.MovieCheckBox1.Enable = 'off';
            app.MovieCheckBox1.Text = '';
            app.MovieCheckBox1.Value = true;
            app.MovieFrameEditFieldLabel = uilabel(app.RecoTab);
            app.MovieFrameEditFieldLabel.Enable = 'off';
            app.MovieFrameEditFieldLabel.Tooltip = {'CINE frame number'};
            app.MovieFrameEditFieldLabel.Text = 'frame';
            app.MovieFrameEditField = uieditfield(app.RecoTab, 'numeric');
            app.MovieFrameEditField.Limits = [1 240];
            app.MovieFrameEditField.ValueDisplayFormat = '%.0f';
            app.MovieFrameEditField.ValueChangedFcn = createCallbackFcn(app, @MovieFrameEditFieldValueChanged, true);
            app.MovieFrameEditField.Enable = 'off';
            app.MovieFrameEditField.Tooltip = {'CINE frame number'};
            app.MovieFrameEditField.Value = 1;
            app.FinalHeartViewFieldLabel = uilabel(app.RecoTab);
            app.FinalHeartViewFieldLabel.Tooltip = {'Average heart rate.'};
            app.FinalHeartViewFieldLabel.Text = 'heart (bpm)';
            app.FinalHeartViewField = uieditfield(app.RecoTab, 'numeric');
            app.FinalHeartViewField.Editable = 'off';
            app.FinalHeartViewField.Tooltip = {'Heart rate (bpm)'};
            app.FinalRespirationViewFieldLabel = uilabel(app.RecoTab);
            app.FinalRespirationViewFieldLabel.Tooltip = {'Average respiration rate.'};
            app.FinalRespirationViewFieldLabel.Text = 'resp. (bpm)';
            app.FinalRespirationViewField = uieditfield(app.RecoTab, 'numeric');
            app.FinalRespirationViewField.Editable = 'off';
            app.FinalRespirationViewField.Tooltip = {'Respiration rate (bpm)'};
            app.MovieSliceEditFieldLabel = uilabel(app.RecoTab);
            app.MovieSliceEditFieldLabel.Enable = 'off';
            app.MovieSliceEditFieldLabel.Tooltip = {'Slice number'};
            app.MovieSliceEditFieldLabel.Text = 'slice';
            app.MovieSliceEditField = uieditfield(app.RecoTab, 'numeric');
            app.MovieSliceEditField.Limits = [1 1024];
            app.MovieSliceEditField.ValueDisplayFormat = '%.0f';
            app.MovieSliceEditField.ValueChangedFcn = createCallbackFcn(app, @MovieSliceEditFieldValueChanged, true);
            app.MovieSliceEditField.Enable = 'off';
            app.MovieSliceEditField.Tooltip = {'Slice number'};
            app.MovieSliceEditField.Value = 1;
            app.WindowScaleSliderLabel = uilabel(app.RecoTab);
            app.WindowScaleSliderLabel.Enable = 'off';
            app.WindowScaleSliderLabel.Tooltip = {'Intensity scale adjustment.'};
            app.WindowScaleSliderLabel.Text = 'W';
            app.WindowScaleSlider = uislider(app.RecoTab);
            app.WindowScaleSlider.Limits = [0 32767];
            app.WindowScaleSlider.MajorTicks = [];
            app.WindowScaleSlider.ValueChangedFcn = createCallbackFcn(app, @MovieWindowScaleSliderValueChanged, true);
            app.WindowScaleSlider.MinorTicks = [];
            app.WindowScaleSlider.Enable = 'off';
            app.WindowScaleSlider.Tooltip = {'Window'};
            app.WindowScaleSlider.Value = 16384;
            app.LevelScaleSliderLabel = uilabel(app.RecoTab);
            app.LevelScaleSliderLabel.Enable = 'off';
            app.LevelScaleSliderLabel.Tooltip = {'Intensity scale adjustment.'};
            app.LevelScaleSliderLabel.Text = 'L';
            app.LevelScaleSlider = uislider(app.RecoTab);
            app.LevelScaleSlider.Limits = [0 32767];
            app.LevelScaleSlider.MajorTicks = [];
            app.LevelScaleSlider.ValueChangedFcn = createCallbackFcn(app, @MovieLevelScaleSliderValueChanged, true);
            app.LevelScaleSlider.MinorTicks = [];
            app.LevelScaleSlider.Enable = 'off';
            app.LevelScaleSlider.Tooltip = {'Level'};
            app.LevelScaleSlider.Value = 16384;
            app.MovieDynEditField = uieditfield(app.RecoTab, 'numeric');
            app.MovieDynEditField.Limits = [1 1024];
            app.MovieDynEditField.ValueDisplayFormat = '%.0f';
            app.MovieDynEditField.ValueChangedFcn = createCallbackFcn(app, @MovieDynEditFieldValueChanged, true);
            app.MovieDynEditField.Enable = 'off';
            app.MovieDynEditField.Tooltip = {'Dynamic or flip-angle number'};
            app.MovieDynEditField.Value = 1;
            app.MovieDynEditFieldLabel = uilabel(app.RecoTab);
            app.MovieDynEditFieldLabel.Enable = 'off';
            app.MovieDynEditFieldLabel.Tooltip = {'Dynamic or flip-angle number'};
            app.MovieDynEditFieldLabel.Text = 'dynamic';
            app.AutoWLButton = uibutton(app.RecoTab, 'state');
            app.AutoWLButton.ValueChangedFcn = createCallbackFcn(app, @AutoWLButtonValueChanged, true);
            app.AutoWLButton.Enable = 'off';
            app.AutoWLButton.Tooltip = {'Auto window and level'};
            app.AutoWLButton.Text = 'Auto';
            app.AutoWLButton.Value = true;
            app.OrientationLabel2 = uilabel(app.RecoTab);
            app.OrientationLabel2.Text = '';
            app.OrientationLabel1 = uilabel(app.RecoTab);
            app.OrientationLabel1.Text = '';
            app.OrientationLabel3 = uilabel(app.RecoTab);
            app.OrientationLabel3.Text = '';
            app.OrientationLabel4 = uilabel(app.RecoTab);
            app.OrientationLabel4.Text = '';
            app.LcurveTab = uitab(app.ReconstructionTabGroup);
            app.LcurveTab.Title = 'L-curve';
            app.LcurveFig = uiaxes(app.LcurveTab);
            title(app.LcurveFig, 'L-curve')
            xlabel(app.LcurveFig, '|| F^{-1}x_{\lambda} - y ||_2')
            ylabel(app.LcurveFig, '|| TVcine x_{\lambda} ||_2')
            app.LcurveFig.XLim = [0 100];
            app.LcurveFig.YLim = [0 1000];
            app.LcurveModelErrorFig = uiaxes(app.LcurveTab);
            title(app.LcurveModelErrorFig, 'Model error')
            xlabel(app.LcurveModelErrorFig, '\lambda')
            ylabel(app.LcurveModelErrorFig, ' || F^{-1}x_{\lambda} - y ||_2')
            app.LcurveModelErrorFig.XLim = [0 100];
            app.LcurveModelErrorFig.YLim = [0 1000];
            app.LcurveStartButton = uibutton(app.LcurveTab, 'push');
            app.LcurveStartButton.ButtonPushedFcn = createCallbackFcn(app, @LcurveStartButtonPushed, true);
            app.LcurveStartButton.Enable = 'off';
            app.LcurveStartButton.Tooltip = {'Start L-curve calculation'};
            app.LcurveStartButton.Text = 'Start';
            app.LcurveStopButton = uibutton(app.LcurveTab, 'push');
            app.LcurveStopButton.ButtonPushedFcn = createCallbackFcn(app, @LcurveStopButtonPushed, true);
            app.LcurveStopButton.Enable = 'off';
            app.LcurveStopButton.Tooltip = {'Stop L-curve calculation'};
            app.LcurveStopButton.Text = 'Stop';
            app.LambdaLabel = uilabel(app.LcurveTab);
            app.LambdaLabel.Text = 'Lambda';
            app.Lambda1EditFieldLabel = uilabel(app.LcurveTab);
            app.Lambda1EditFieldLabel.Enable = 'off';
            app.Lambda1EditFieldLabel.Text = 'lower';
            app.Lambda1EditField = uieditfield(app.LcurveTab, 'numeric');
            app.Lambda1EditField.Limits = [0 0.1];
            app.Lambda1EditField.Enable = 'off';
            app.Lambda1EditField.Tooltip = {'Regularization lower bound'};
            app.Lambda1EditField.Value = 0.001;
            app.Lambda2EditFieldLabel = uilabel(app.LcurveTab);
            app.Lambda2EditFieldLabel.Enable = 'off';
            app.Lambda2EditFieldLabel.Text = 'upper';
            app.Lambda2EditField = uieditfield(app.LcurveTab, 'numeric');
            app.Lambda2EditField.Limits = [0.2 0.8];
            app.Lambda2EditField.Enable = 'off';
            app.Lambda2EditField.Tooltip = {'Regularization upper bound'};
            app.Lambda2EditField.Value = 0.5;
            app.LambdaStepsEditFieldLabel = uilabel(app.LcurveTab);
            app.LambdaStepsEditFieldLabel.Enable = 'off';
            app.LambdaStepsEditFieldLabel.Text = '#steps';
            app.LambdaStepsEditField = uieditfield(app.LcurveTab, 'numeric');
            app.LambdaStepsEditField.Limits = [6 30];
            app.LambdaStepsEditField.ValueDisplayFormat = '%.0f';
            app.LambdaStepsEditField.Enable = 'off';
            app.LambdaStepsEditField.Tooltip = {'Number of regularization values'};
            app.LambdaStepsEditField.Value = 12;
            app.LambdaIterationsEditFieldLabel = uilabel(app.LcurveTab);
            app.LambdaIterationsEditFieldLabel.Enable = 'off';
            app.LambdaIterationsEditFieldLabel.Text = '#iter';
            app.LambdaIterationsEditField = uieditfield(app.LcurveTab, 'numeric');
            app.LambdaIterationsEditField.Limits = [1 10];
            app.LambdaIterationsEditField.ValueDisplayFormat = '%.0f';
            app.LambdaIterationsEditField.Enable = 'off';
            app.LambdaIterationsEditField.Tooltip = {'Number of iterations'};
            app.LambdaIterationsEditField.Value = 1;
            app.RegularizationLabel = uilabel(app.LcurveTab);
            app.RegularizationLabel.Text = 'Regularization';
            app.LcurveWVxyzCheckBox = uicheckbox(app.LcurveTab);
            app.LcurveWVxyzCheckBox.Enable = 'off';
            app.LcurveWVxyzCheckBox.Tooltip = {'Wavelet regularization in spatial domain'};
            app.LcurveWVxyzCheckBox.Text = '';
            app.LcurveTVxyzCheckBox = uicheckbox(app.LcurveTab);
            app.LcurveTVxyzCheckBox.Enable = 'off';
            app.LcurveTVxyzCheckBox.Tooltip = {'Total variation regularization in spatial domain'};
            app.LcurveTVxyzCheckBox.Text = '';
            app.LcurveLLRxyzCheckBox = uicheckbox(app.LcurveTab);
            app.LcurveLLRxyzCheckBox.Enable = 'off';
            app.LcurveLLRxyzCheckBox.Tooltip = {'Locally-Low Rank regularization in spatial domain'};
            app.LcurveLLRxyzCheckBox.Text = '';
            app.LcurveTVcineCheckBox = uicheckbox(app.LcurveTab);
            app.LcurveTVcineCheckBox.Enable = 'off';
            app.LcurveTVcineCheckBox.Tooltip = {'Total variation regularizaiton in spatial domain'};
            app.LcurveTVcineCheckBox.Text = '';
            app.LcurveTVcineCheckBox.Value = true;
            app.LcurveTVdynCheckBox = uicheckbox(app.LcurveTab);
            app.LcurveTVdynCheckBox.Enable = 'off';
            app.LcurveTVdynCheckBox.Tooltip = {'Total variation regularizaiton in dynamics domain'};
            app.LcurveTVdynCheckBox.Text = '';
            app.LcurveWVxyzCheckBoxLabel = uilabel(app.LcurveTab);
            app.LcurveWVxyzCheckBoxLabel.Enable = 'off';
            app.LcurveWVxyzCheckBoxLabel.Tooltip = {'Wavelet regularization in spatial domain'};
            app.LcurveWVxyzCheckBoxLabel.Text = 'WVxyz';
            app.LcurveTVxyzCheckBoxLabel = uilabel(app.LcurveTab);
            app.LcurveTVxyzCheckBoxLabel.Enable = 'off';
            app.LcurveTVxyzCheckBoxLabel.Tooltip = {'Total variation regularization in spatial domain'};
            app.LcurveTVxyzCheckBoxLabel.Text = 'TVxyz';
            app.LcurveLLRxyzCheckBoxLabel = uilabel(app.LcurveTab);
            app.LcurveLLRxyzCheckBoxLabel.Enable = 'off';
            app.LcurveLLRxyzCheckBoxLabel.Tooltip = {'Locally-Low Rank regularization in spatial domain'};
            app.LcurveLLRxyzCheckBoxLabel.Text = 'LRxyz';
            app.LcurveTVcineCheckBoxLabel = uilabel(app.LcurveTab);
            app.LcurveTVcineCheckBoxLabel.Enable = 'off';
            app.LcurveTVcineCheckBoxLabel.Tooltip = {'Total variation regularizaiton in spatial domain'};
            app.LcurveTVcineCheckBoxLabel.Text = 'TVcine';
            app.LcurveTVdynCheckBoxLabel = uilabel(app.LcurveTab);
            app.LcurveTVdynCheckBoxLabel.Enable = 'off';
            app.LcurveTVdynCheckBoxLabel.Tooltip = {'Total variation regularizaiton in dynamics domain'};
            app.LcurveTVdynCheckBoxLabel.Text = 'TVdyn';
            app.RadialRecoTab = uitab(app.ReconstructionTabGroup);
            app.RadialRecoTab.Title = 'Radial';
            app.TrajectoryLargeFig = uiaxes(app.RadialRecoTab);
            app.TrajectoryLargeFig.XTick = [];
            app.TrajectoryLargeFig.YTick = [];
            app.TrajMovieDynEditFieldLabel = uilabel(app.RadialRecoTab);
            app.TrajMovieDynEditFieldLabel.Enable = 'off';
            app.TrajMovieDynEditFieldLabel.Tooltip = {'Dynamic number'};
            app.TrajMovieDynEditFieldLabel.Text = 'dynamic';
            app.TrajMovieSliceEditFieldLabel = uilabel(app.RadialRecoTab);
            app.TrajMovieSliceEditFieldLabel.Enable = 'off';
            app.TrajMovieSliceEditFieldLabel.Tooltip = {'Slice number'};
            app.TrajMovieSliceEditFieldLabel.Text = 'slice';
            app.TrajMovieFrameEditFieldLabel = uilabel(app.RadialRecoTab);
            app.TrajMovieFrameEditFieldLabel.Enable = 'off';
            app.TrajMovieFrameEditFieldLabel.Tooltip = {'CINE frame number'};
            app.TrajMovieFrameEditFieldLabel.Text = 'frame';
            app.TrajMovieCheckBox1 = uicheckbox(app.RadialRecoTab);
            app.TrajMovieCheckBox1.ValueChangedFcn = createCallbackFcn(app, @TrajMovieCheckBoxValueChanged, true);
            app.TrajMovieCheckBox1.Enable = 'off';
            app.TrajMovieCheckBox1.Text = '';
            app.TrajMovieCheckBox1.Value = true;
            app.TrajMovieCheckBox2 = uicheckbox(app.RadialRecoTab);
            app.TrajMovieCheckBox2.ValueChangedFcn = createCallbackFcn(app, @TrajMovieCheckBoxValueChanged, true);
            app.TrajMovieCheckBox2.Enable = 'off';
            app.TrajMovieCheckBox2.Text = '';
            app.TrajMovieCheckBox3 = uicheckbox(app.RadialRecoTab);
            app.TrajMovieCheckBox3.ValueChangedFcn = createCallbackFcn(app, @TrajMovieCheckBoxValueChanged, true);
            app.TrajMovieCheckBox3.Enable = 'off';
            app.TrajMovieCheckBox3.Text = '';
            app.TrajMovieFrameEditField = uieditfield(app.RadialRecoTab, 'numeric');
            app.TrajMovieFrameEditField.Limits = [1 240];
            app.TrajMovieFrameEditField.ValueDisplayFormat = '%.0f';
            app.TrajMovieFrameEditField.ValueChangedFcn = createCallbackFcn(app, @TrajMovieDimEditFieldValueChanged, true);
            app.TrajMovieFrameEditField.Enable = 'off';
            app.TrajMovieFrameEditField.Tooltip = {'CINE frame number'};
            app.TrajMovieFrameEditField.Value = 1;
            app.TrajMovieSliceEditField = uieditfield(app.RadialRecoTab, 'numeric');
            app.TrajMovieSliceEditField.Limits = [1 1024];
            app.TrajMovieSliceEditField.ValueDisplayFormat = '%.0f';
            app.TrajMovieSliceEditField.ValueChangedFcn = createCallbackFcn(app, @TrajMovieDimEditFieldValueChanged, true);
            app.TrajMovieSliceEditField.Enable = 'off';
            app.TrajMovieSliceEditField.Tooltip = {'Slice number'};
            app.TrajMovieSliceEditField.Value = 1;
            app.TrajMovieDynEditField = uieditfield(app.RadialRecoTab, 'numeric');
            app.TrajMovieDynEditField.Limits = [1 1024];
            app.TrajMovieDynEditField.ValueDisplayFormat = '%.0f';
            app.TrajMovieDynEditField.ValueChangedFcn = createCallbackFcn(app, @TrajMovieDimEditFieldValueChanged, true);
            app.TrajMovieDynEditField.Enable = 'off';
            app.TrajMovieDynEditField.Tooltip = {'Dynamic number'};
            app.TrajMovieDynEditField.Value = 1;
            app.TrajMovieStartButton = uibutton(app.RadialRecoTab, 'push');
            app.TrajMovieStartButton.ButtonPushedFcn = createCallbackFcn(app, @TrajMovieStartButtonPushed, true);
            app.TrajMovieStartButton.Enable = 'off';
            app.TrajMovieStartButton.Tooltip = {'Start the trajectory movie'};
            app.TrajMovieStartButton.Text = 'Start';
            app.TrajMovieStopButton = uibutton(app.RadialRecoTab, 'push');
            app.TrajMovieStopButton.ButtonPushedFcn = createCallbackFcn(app, @TrajMovieStopButtonPushed, true);
            app.TrajMovieStopButton.Enable = 'off';
            app.TrajMovieStopButton.Tooltip = {'Stop the trajectory movie'};
            app.TrajMovieStopButton.Text = 'Stop';
            app.SpokeEditField = uieditfield(app.RadialRecoTab, 'numeric');
            app.SpokeEditField.Limits = [1 Inf];
            app.SpokeEditField.ValueDisplayFormat = '%.0f';
            app.SpokeEditField.Enable = 'off';
            app.SpokeEditField.Tooltip = {'Spoke number'};
            app.SpokeEditField.Value = 1;
            app.SpokeEditFieldLabel = uilabel(app.RadialRecoTab);
            app.SpokeEditFieldLabel.Enable = 'off';
            app.SpokeEditFieldLabel.Text = 'spoke';
            app.Traj3DLabel = uilabel(app.RadialRecoTab);
            app.Traj3DLabel.Tooltip = {'2D radial trajectory'};
            app.Traj3DLabel.Text = '3D trajectory animation';
            app.TrajMovie3DStartButton = uibutton(app.RadialRecoTab, 'push');
            app.TrajMovie3DStartButton.ButtonPushedFcn = createCallbackFcn(app, @TrajMovie3DStartButtonPushed, true);
            app.TrajMovie3DStartButton.Enable = 'off';
            app.TrajMovie3DStartButton.Tooltip = {'Start the trajectory movie'};
            app.TrajMovie3DStartButton.Text = 'Start';
            app.TrajMovie3DStopButton = uibutton(app.RadialRecoTab, 'push');
            app.TrajMovie3DStopButton.ButtonPushedFcn = createCallbackFcn(app, @TrajMovie3DStopButtonPushed, true);
            app.TrajMovie3DStopButton.Enable = 'off';
            app.TrajMovie3DStopButton.Tooltip = {'Stop the trajectory movie'};
            app.TrajMovie3DStopButton.Text = 'Stop';
            app.ParametersTabGroup = uitabgroup(app.UIFigure);
            app.SequenceTab = uitab(app.ParametersTabGroup);
            app.SequenceTab.Title = 'Sequence';
            app.TrajectoryFig = uiaxes(app.SequenceTab);
            app.TrajectoryFig.XTick = [];
            app.TrajectoryFig.YTick = [];
            app.DataFile = uitextarea(app.SequenceTab);
            app.DataFile.ValueChangedFcn = createCallbackFcn(app, @LoadMRDfileButtonPushed, true);
            app.DataFile.Editable = 'off';
            app.DataFile.Tooltip = {'Data file'};
            app.DataFile.Value = {' '};
            app.DataFileLabel = uilabel(app.SequenceTab);
            app.DataFileLabel.Text = 'data file';
            app.NyViewFieldLabel = uilabel(app.SequenceTab);
            app.NyViewFieldLabel.Tooltip = {'Number of pixels in phase-encoding direction'};
            app.NyViewFieldLabel.Text = 'phase  Ny';
            app.NyViewField = uieditfield(app.SequenceTab, 'numeric');
            app.NyViewField.Limits = [0 Inf];
            app.NyViewField.ValueDisplayFormat = '%.0f';
            app.NyViewField.Editable = 'off';
            app.NyViewField.Tooltip = {'Number of pixels in phase-encoding direction'};
            app.NxViewFieldLabel = uilabel(app.SequenceTab);
            app.NxViewFieldLabel.Tooltip = {'Number of pixels in readout direction'};
            app.NxViewFieldLabel.Text = 'freq  Nx';
            app.NxViewField = uieditfield(app.SequenceTab, 'numeric');
            app.NxViewField.Limits = [0 Inf];
            app.NxViewField.ValueDisplayFormat = '%.0f';
            app.NxViewField.Editable = 'off';
            app.NxViewField.Tooltip = {'Number of pixels in readout direction'};
            app.TrajectorySizeViewFieldLabel = uilabel(app.SequenceTab);
            app.TrajectorySizeViewFieldLabel.Tooltip = {'Length of the trajectory'};
            app.TrajectorySizeViewFieldLabel.Text = 'size';
            app.TrajectorySizeViewField = uieditfield(app.SequenceTab, 'numeric');
            app.TrajectorySizeViewField.Limits = [0 Inf];
            app.TrajectorySizeViewField.ValueDisplayFormat = '%.0f';
            app.TrajectorySizeViewField.Editable = 'off';
            app.TrajectorySizeViewField.Tooltip = {'Size of the trajectory'};
            app.RepetitionsViewFieldLabel = uilabel(app.SequenceTab);
            app.RepetitionsViewFieldLabel.Tooltip = {'Number of k-space repetitions'};
            app.RepetitionsViewFieldLabel.Text = '#repetitions';
            app.RepetitionsViewField = uieditfield(app.SequenceTab, 'numeric');
            app.RepetitionsViewField.Limits = [0 Inf];
            app.RepetitionsViewField.RoundFractionalValues = 'on';
            app.RepetitionsViewField.ValueDisplayFormat = '%.0f';
            app.RepetitionsViewField.Editable = 'off';
            app.RepetitionsViewField.Tooltip = {'Number of k-space repetitions'};
            app.NrKlinesViewFieldLabel = uilabel(app.SequenceTab);
            app.NrKlinesViewFieldLabel.Tooltip = {'Total number of acquired/reconstructed k-lines'};
            app.NrKlinesViewFieldLabel.Text = ' # k-lines';
            app.NrKlinesViewField = uieditfield(app.SequenceTab, 'numeric');
            app.NrKlinesViewField.Limits = [0 Inf];
            app.NrKlinesViewField.ValueDisplayFormat = '%.0f';
            app.NrKlinesViewField.Editable = 'off';
            app.NrKlinesViewField.Tooltip = {'Total number of acquired/reconstructed k-lines'};
            app.AveragesViewFieldLabel = uilabel(app.SequenceTab);
            app.AveragesViewFieldLabel.Tooltip = {'Mean number of signal averages'};
            app.AveragesViewFieldLabel.Text = 'averages';
            app.AveragesViewField = uieditfield(app.SequenceTab, 'numeric');
            app.AveragesViewField.Limits = [0 Inf];
            app.AveragesViewField.ValueDisplayFormat = '%.2f';
            app.AveragesViewField.Editable = 'off';
            app.AveragesViewField.Tooltip = {'Mean number of signal averages'};
            app.FillingViewFieldLabel = uilabel(app.SequenceTab);
            app.FillingViewFieldLabel.Tooltip = {'K-space filling percentage'};
            app.FillingViewFieldLabel.Text = 'filling (%)';
            app.FillingViewField = uieditfield(app.SequenceTab, 'numeric');
            app.FillingViewField.Limits = [0 100];
            app.FillingViewField.ValueDisplayFormat = '%.0f';
            app.FillingViewField.Editable = 'off';
            app.FillingViewField.Tooltip = {'K-space filling percentage'};
            app.TRViewFieldLabel = uilabel(app.SequenceTab);
            app.TRViewFieldLabel.Tooltip = {'Repetition time'};
            app.TRViewFieldLabel.Text = 'TR (ms)';
            app.TRViewField = uieditfield(app.SequenceTab, 'numeric');
            app.TRViewField.Limits = [0 Inf];
            app.TRViewField.ValueDisplayFormat = '%.2f';
            app.TRViewField.Editable = 'off';
            app.TRViewField.Tooltip = {'Repetition time'};
            app.TrajectoryViewFieldLabel = uilabel(app.SequenceTab);
            app.TrajectoryViewFieldLabel.Tooltip = {'K-space filling trajectory'};
            app.TrajectoryViewFieldLabel.Text = 'trajectory';
            app.TrajectoryViewField = uieditfield(app.SequenceTab, 'text');
            app.TrajectoryViewField.Editable = 'off';
            app.TrajectoryViewField.Tooltip = {'K-space filling trajectory'};
            app.TrajectoryViewField.Value = '-';
            app.NzViewFieldLabel = uilabel(app.SequenceTab);
            app.NzViewFieldLabel.Tooltip = {'Number of slices'};
            app.NzViewFieldLabel.Text = 'slices  Nz';
            app.NzViewField = uieditfield(app.SequenceTab, 'numeric');
            app.NzViewField.Limits = [0 Inf];
            app.NzViewField.ValueDisplayFormat = '%.0f';
            app.NzViewField.Editable = 'off';
            app.NzViewField.Tooltip = {'Number of slices / 2nd phase-encoding direction'};
            app.NcViewFieldLabel = uilabel(app.SequenceTab);
            app.NcViewFieldLabel.Tooltip = {'Number of receiver coils'};
            app.NcViewFieldLabel.Text = 'coils  Nc';
            app.NcViewField = uieditfield(app.SequenceTab, 'numeric');
            app.NcViewField.Limits = [0 Inf];
            app.NcViewField.ValueDisplayFormat = '%.0f';
            app.NcViewField.Editable = 'off';
            app.NcViewField.Tooltip = {'Number of receiver coils'};
            app.ScanTimeViewFieldLabel = uilabel(app.SequenceTab);
            app.ScanTimeViewFieldLabel.Tooltip = {'Total scan time'};
            app.ScanTimeViewFieldLabel.Text = 'scan time';
            app.ScanTimeViewField = uieditfield(app.SequenceTab, 'text');
            app.ScanTimeViewField.Editable = 'off';
            app.ScanTimeViewField.Tooltip = {'Total scan time'};
            app.ScanTimeViewField.Value = '-';
            app.TimeDynViewFieldLabel = uilabel(app.SequenceTab);
            app.TimeDynViewFieldLabel.Tooltip = {'Scan time per dynamic'};
            app.TimeDynViewFieldLabel.Text = 'time / dynamic';
            app.TimeDynViewField = uieditfield(app.SequenceTab, 'text');
            app.TimeDynViewField.Editable = 'off';
            app.TimeDynViewField.Tooltip = {'Scan time per dynamic'};
            app.TimeDynViewField.Value = '-';
            app.NrFlipAnglesViewFieldLabel = uilabel(app.SequenceTab);
            app.NrFlipAnglesViewFieldLabel.Tooltip = {'Number of flip-angles for T1-mapping'};
            app.NrFlipAnglesViewFieldLabel.Text = '#flip angles';
            app.NrFlipAnglesViewField = uieditfield(app.SequenceTab, 'numeric');
            app.NrFlipAnglesViewField.Limits = [0 100];
            app.NrFlipAnglesViewField.ValueDisplayFormat = '%.0f';
            app.NrFlipAnglesViewField.Editable = 'off';
            app.NrFlipAnglesViewField.Tooltip = {'Number of flip-angles for T1-mapping'};
            app.FieldOfViewViewFieldLabel = uilabel(app.SequenceTab);
            app.FieldOfViewViewFieldLabel.Tooltip = {'Field of view'};
            app.FieldOfViewViewFieldLabel.Text = 'FOV (mm)';
            app.FieldOfViewViewField = uieditfield(app.SequenceTab, 'numeric');
            app.FieldOfViewViewField.Limits = [0 Inf];
            app.FieldOfViewViewField.ValueDisplayFormat = '%.2f';
            app.FieldOfViewViewField.Editable = 'off';
            app.FieldOfViewViewField.Tooltip = {'Field of view'};
            app.TEViewFiedLabel = uilabel(app.SequenceTab);
            app.TEViewFiedLabel.Tooltip = {'Echo time'};
            app.TEViewFiedLabel.Text = 'TE (ms)';
            app.TEViewField = uieditfield(app.SequenceTab, 'numeric');
            app.TEViewField.Limits = [0 Inf];
            app.TEViewField.ValueDisplayFormat = '%.2f';
            app.TEViewField.Editable = 'off';
            app.TEViewField.Tooltip = {'Echo time'};
            app.FlipAnglesViewFieldLabel = uilabel(app.SequenceTab);
            app.FlipAnglesViewFieldLabel.Tooltip = {'Flip angle(s)'};
            app.FlipAnglesViewFieldLabel.Text = 'flip angle(s)';
            app.FlipAnglesViewField = uieditfield(app.SequenceTab, 'text');
            app.FlipAnglesViewField.Editable = 'off';
            app.FlipAnglesViewField.Tooltip = {'Flip angle(s)'};
            app.FlipAnglesViewField.Value = '-';
            app.GeometryTab = uitab(app.ParametersTabGroup);
            app.GeometryTab.Title = 'Geometry';
            app.PhaseOrientationViewFieldLabel = uilabel(app.GeometryTab);
            app.PhaseOrientationViewFieldLabel.Tooltip = {'Field of view'};
            app.PhaseOrientationViewFieldLabel.Text = 'phase orientation';
            app.PhaseOrientationViewField = uieditfield(app.GeometryTab, 'numeric');
            app.PhaseOrientationViewField.Limits = [0 Inf];
            app.PhaseOrientationViewField.ValueDisplayFormat = '%.0f';
            app.PhaseOrientationViewField.Editable = 'off';
            app.PhaseOrientationViewField.Tooltip = {'Phase orientation horizontal (1) or vertical (0)'};
            app.SliceThicknessViewFieldLabel = uilabel(app.GeometryTab);
            app.SliceThicknessViewFieldLabel.Tooltip = {'Field of view'};
            app.SliceThicknessViewFieldLabel.Text = 'slice thickness (mm)';
            app.SliceThicknessViewField = uieditfield(app.GeometryTab, 'numeric');
            app.SliceThicknessViewField.Limits = [0 Inf];
            app.SliceThicknessViewField.ValueDisplayFormat = '%.2f';
            app.SliceThicknessViewField.Editable = 'off';
            app.SliceThicknessViewField.Tooltip = {'Slice thickness'};
            app.AspectRatioViewFieldLabel = uilabel(app.GeometryTab);
            app.AspectRatioViewFieldLabel.Tooltip = {'Field of view'};
            app.AspectRatioViewFieldLabel.Text = 'aspect ratio';
            app.AspectRatioViewField = uieditfield(app.GeometryTab, 'numeric');
            app.AspectRatioViewField.Limits = [0 Inf];
            app.AspectRatioViewField.ValueDisplayFormat = '%.2f';
            app.AspectRatioViewField.Editable = 'off';
            app.AspectRatioViewField.Tooltip = {'Image aspect ratio'};
            app.FieldOfViewViewField2Label = uilabel(app.GeometryTab);
            app.FieldOfViewViewField2Label.Tooltip = {'Field of view'};
            app.FieldOfViewViewField2Label.Text = 'FOV (mm)';
            app.FieldOfViewViewField2 = uieditfield(app.GeometryTab, 'numeric');
            app.FieldOfViewViewField2.Limits = [0 Inf];
            app.FieldOfViewViewField2.ValueDisplayFormat = '%.2f';
            app.FieldOfViewViewField2.Editable = 'off';
            app.FieldOfViewViewField2.Tooltip = {'Field of view'};
            app.AngleZViewFieldLabel = uilabel(app.GeometryTab);
            app.AngleZViewFieldLabel.Tooltip = {'Field of view'};
            app.AngleZViewFieldLabel.Text = 'angle Z (deg)';
            app.AngleZViewField = uieditfield(app.GeometryTab, 'numeric');
            app.AngleZViewField.ValueDisplayFormat = '%.0f';
            app.AngleZViewField.Editable = 'off';
            app.AngleZViewField.Tooltip = {'Z angulation'};
            app.AngleYViewFieldLabel = uilabel(app.GeometryTab);
            app.AngleYViewFieldLabel.Tooltip = {'Field of view'};
            app.AngleYViewFieldLabel.Text = 'angle Y (deg)';
            app.AngleYViewField = uieditfield(app.GeometryTab, 'numeric');
            app.AngleYViewField.ValueDisplayFormat = '%.0f';
            app.AngleYViewField.Editable = 'off';
            app.AngleYViewField.Tooltip = {'Y angulation'};
            app.AngleXViewFieldLabel = uilabel(app.GeometryTab);
            app.AngleXViewFieldLabel.Tooltip = {'Field of view'};
            app.AngleXViewFieldLabel.Text = 'angle X (deg)';
            app.AngleXViewField = uieditfield(app.GeometryTab, 'numeric');
            app.AngleXViewField.ValueDisplayFormat = '%.0f';
            app.AngleXViewField.Editable = 'off';
            app.AngleXViewField.Tooltip = {'X angulation'};
            app.OffsetZViewFieldLabel = uilabel(app.GeometryTab);
            app.OffsetZViewFieldLabel.Tooltip = {'Field of view'};
            app.OffsetZViewFieldLabel.Text = 'offset Z (mm)';
            app.OffsetZViewField = uieditfield(app.GeometryTab, 'numeric');
            app.OffsetZViewField.ValueDisplayFormat = '%.2f';
            app.OffsetZViewField.Editable = 'off';
            app.OffsetZViewField.Tooltip = {'Slice / 2nd phase-encoding offset'};
            app.OffsetYViewFieldLabel = uilabel(app.GeometryTab);
            app.OffsetYViewFieldLabel.Tooltip = {'Field of view'};
            app.OffsetYViewFieldLabel.Text = 'offset Y (mm)';
            app.OffsetYViewField = uieditfield(app.GeometryTab, 'numeric');
            app.OffsetYViewField.ValueDisplayFormat = '%.2f';
            app.OffsetYViewField.Editable = 'off';
            app.OffsetYViewField.Tooltip = {'Phase-encoding offset'};
            app.OffsetXViewFieldlabel = uilabel(app.GeometryTab);
            app.OffsetXViewFieldlabel.Tooltip = {'Field of view'};
            app.OffsetXViewFieldlabel.Text = 'offset X (mm)';
            app.OffsetXViewField = uieditfield(app.GeometryTab, 'numeric');
            app.OffsetXViewField.ValueDisplayFormat = '%.2f';
            app.OffsetXViewField.Editable = 'off';
            app.OffsetXViewField.Tooltip = {'Readout offset'};
            app.SliceGapViewFieldLabel = uilabel(app.GeometryTab);
            app.SliceGapViewFieldLabel.Tooltip = {'Field of view'};
            app.SliceGapViewFieldLabel.Text = 'slice gap (mm)';
            app.SliceGapViewField = uieditfield(app.GeometryTab, 'numeric');
            app.SliceGapViewField.ValueDisplayFormat = '%.2f';
            app.SliceGapViewField.Editable = 'off';
            app.SliceGapViewField.Tooltip = {'Slice gap'};
            app.RecoParametersTab = uitab(app.ParametersTabGroup);
            app.RecoParametersTab.Title = 'Reco Parameters';
            app.WVxyzEditFieldLabel = uilabel(app.RecoParametersTab);
            app.WVxyzEditFieldLabel.Enable = 'off';
            app.WVxyzEditFieldLabel.Tooltip = {'Wavelet L1-norm regularization in the spatial dimensions'};
            app.WVxyzEditFieldLabel.Text = 'WVxyz';
            app.WVxyzEditField = uieditfield(app.RecoParametersTab, 'numeric');
            app.WVxyzEditField.Limits = [0 20];
            app.WVxyzEditField.ValueDisplayFormat = '%.4f';
            app.WVxyzEditField.ValueChangedFcn = createCallbackFcn(app, @RecoParEditFieldValueChanged, true);
            app.WVxyzEditField.Enable = 'off';
            app.WVxyzEditField.Tooltip = {'Wavelet L1 regularization in spatial domain'};
            app.TVxyzEditField = uieditfield(app.RecoParametersTab, 'numeric');
            app.TVxyzEditField.Limits = [0 20];
            app.TVxyzEditField.ValueDisplayFormat = '%.4f';
            app.TVxyzEditField.ValueChangedFcn = createCallbackFcn(app, @RecoParEditFieldValueChanged, true);
            app.TVxyzEditField.Enable = 'off';
            app.TVxyzEditField.Tooltip = {'Total Variation regularization in spatial domain'};
            app.LLRxyzEditField = uieditfield(app.RecoParametersTab, 'numeric');
            app.LLRxyzEditField.Limits = [0 20];
            app.LLRxyzEditField.ValueDisplayFormat = '%.4f';
            app.LLRxyzEditField.ValueChangedFcn = createCallbackFcn(app, @RecoParEditFieldValueChanged, true);
            app.LLRxyzEditField.Enable = 'off';
            app.LLRxyzEditField.Tooltip = {'Locally Low-Rank regularization in spatial domain'};
            app.TVcineEditField = uieditfield(app.RecoParametersTab, 'numeric');
            app.TVcineEditField.Limits = [0 20];
            app.TVcineEditField.ValueDisplayFormat = '%.4f';
            app.TVcineEditField.ValueChangedFcn = createCallbackFcn(app, @RecoParEditFieldValueChanged, true);
            app.TVcineEditField.Enable = 'off';
            app.TVcineEditField.Tooltip = {'Total Variation regularization in CINE dimension'};
            app.TVcineEditField.Value = 0.1;
            app.TVdynEditField = uieditfield(app.RecoParametersTab, 'numeric');
            app.TVdynEditField.Limits = [0 20];
            app.TVdynEditField.ValueDisplayFormat = '%.4f';
            app.TVdynEditField.ValueChangedFcn = createCallbackFcn(app, @RecoParEditFieldValueChanged, true);
            app.TVdynEditField.Enable = 'off';
            app.TVdynEditField.Tooltip = {'Total Variation regularization in dynamic dimension'};
            app.ESPIRiTCheckBox = uicheckbox(app.RecoParametersTab);
            app.ESPIRiTCheckBox.ValueChangedFcn = createCallbackFcn(app, @RecoParEditFieldValueChanged, true);
            app.ESPIRiTCheckBox.Enable = 'off';
            app.ESPIRiTCheckBox.Tooltip = {'ESPIRiT reconstruction of multi-coil data'};
            app.ESPIRiTCheckBox.Text = '';
            app.PCACheckBox = uicheckbox(app.RecoParametersTab);
            app.PCACheckBox.Enable = 'off';
            app.PCACheckBox.Tooltip = {'PCA denoising'};
            app.PCACheckBox.Text = '';
            app.PCAwindowEditField = uieditfield(app.RecoParametersTab, 'numeric');
            app.PCAwindowEditField.Enable = 'off';
            app.PCAwindowEditField.Tooltip = {'PCA denoising window (pixels)'};
            app.PCAwindowEditField.Value = 8;
            app.RingRmCheckBox = uicheckbox(app.RecoParametersTab);
            app.RingRmCheckBox.Enable = 'off';
            app.RingRmCheckBox.Tooltip = {'Gibbs ringing suppression'};
            app.RingRmCheckBox.Text = '';
            app.SharingEditField = uieditfield(app.RecoParametersTab, 'numeric');
            app.SharingEditField.Limits = [0 30];
            app.SharingEditField.ValueDisplayFormat = '%.0f';
            app.SharingEditField.ValueChangedFcn = createCallbackFcn(app, @CinePanelValueChanged, true);
            app.SharingEditField.Enable = 'off';
            app.SharingEditField.Tooltip = {'Sharing of k-lines from neighboring frames to improve k-space filling at the expense of temporal smoothing.'};
            app.AutoGuessRecoCheckBox = uicheckbox(app.RecoParametersTab);
            app.AutoGuessRecoCheckBox.Tooltip = {'Automatically guess of type of reconstruction'};
            app.AutoGuessRecoCheckBox.Text = '';
            app.AutoGuessRecoCheckBox.Value = true;
            app.RecoPresetsButtonGroup = uibuttongroup(app.RecoParametersTab);
            app.RecoPresetsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @RecoPresetsButtonGroupSelectionChanged, true);
            app.RecoPresetsButtonGroup.Tooltip = {'Preset reconstruction settings'};
            app.RecoPresetsButtonGroup.ButtonDownFcn = createCallbackFcn(app, @RecoPresetsButtonGroupButtonDown, true);
            app.ScoutButton = uiradiobutton(app.RecoPresetsButtonGroup);
            app.ScoutButton.Enable = 'off';
            app.ScoutButton.Tooltip = {'Single frame scout reconstruction'};
            app.ScoutButton.Text = 'scout';
            app.SystolicFunctionButton = uiradiobutton(app.RecoPresetsButtonGroup);
            app.SystolicFunctionButton.Enable = 'off';
            app.SystolicFunctionButton.Tooltip = {'Suggested reconstruction settings for measuring systolic function'};
            app.SystolicFunctionButton.Text = 'systolic function';
            app.SystolicFunctionButton.Value = true;
            app.DiastolicFunctionButton = uiradiobutton(app.RecoPresetsButtonGroup);
            app.DiastolicFunctionButton.Enable = 'off';
            app.DiastolicFunctionButton.Tooltip = {'Suggested reconstruction settings for measuring diastolic function'};
            app.DiastolicFunctionButton.Text = 'diastolic function';
            app.RespiratoryFunctionButton = uiradiobutton(app.RecoPresetsButtonGroup);
            app.RespiratoryFunctionButton.Enable = 'off';
            app.RespiratoryFunctionButton.Tooltip = {'Suggested reconstruction settings for measuring respiratory motion'};
            app.RespiratoryFunctionButton.Text = 'respiratory function';
            app.RealTimeFunctionButton = uiradiobutton(app.RecoPresetsButtonGroup);
            app.RealTimeFunctionButton.Enable = 'off';
            app.RealTimeFunctionButton.Tooltip = {'Suggested reconstruction settings for realtime imaging = CINE movie per heartbeat'};
            app.RealTimeFunctionButton.Text = 'realtime';
            app.VfaFunctionButton = uiradiobutton(app.RecoPresetsButtonGroup);
            app.VfaFunctionButton.Enable = 'off';
            app.VfaFunctionButton.Tooltip = {'Suggested reconstruction settings for T1-mapping with the variable-flip-angle method'};
            app.VfaFunctionButton.Text = 'variable flip-angle';
            app.ReconstructionpresetsLabel = uilabel(app.RecoParametersTab);
            app.ReconstructionpresetsLabel.Tooltip = {'Preset reconstruction settings'};
            app.ReconstructionpresetsLabel.Text = 'Reconstruction presets';
            app.AutoGuessRecoCheckBoxLabel = uilabel(app.RecoParametersTab);
            app.AutoGuessRecoCheckBoxLabel.Tooltip = {'Automatically guess of type of reconstruction'};
            app.AutoGuessRecoCheckBoxLabel.Text = 'guess reco';
            app.MatlabRecoCheckBoxLabel = uilabel(app.RecoParametersTab);
            app.MatlabRecoCheckBoxLabel.Tooltip = {'Force Matlab reconstruction'};
            app.MatlabRecoCheckBoxLabel.Text = 'Matlab reco';
            app.MatlabRecoCheckBox = uicheckbox(app.RecoParametersTab);
            app.MatlabRecoCheckBox.Tooltip = {'Force Matlab reconstruction'};
            app.MatlabRecoCheckBox.Text = '';
            app.FramesharingLabel = uilabel(app.RecoParametersTab);
            app.FramesharingLabel.Text = 'Frame sharing';
            app.SharingEditFieldLabel = uilabel(app.RecoParametersTab);
            app.SharingEditFieldLabel.Enable = 'off';
            app.SharingEditFieldLabel.Text = '# frames';
            app.PCACheckBoxLabel = uilabel(app.RecoParametersTab);
            app.PCACheckBoxLabel.Enable = 'off';
            app.PCACheckBoxLabel.Tooltip = {'PCA image denoising'};
            app.PCACheckBoxLabel.Text = 'PCA';
            app.RingRmCheckBoxLabel = uilabel(app.RecoParametersTab);
            app.RingRmCheckBoxLabel.Enable = 'off';
            app.RingRmCheckBoxLabel.Tooltip = {'Gibbs ringing suppression'};
            app.RingRmCheckBoxLabel.Text = 'ringing';
            app.CSrecoLabel = uilabel(app.RecoParametersTab);
            app.CSrecoLabel.Text = 'CS reco';
            app.VariousLabel = uilabel(app.RecoParametersTab);
            app.VariousLabel.Text = 'Various';
            app.ESPIRiTCheckBoxLabel = uilabel(app.RecoParametersTab);
            app.ESPIRiTCheckBoxLabel.Enable = 'off';
            app.ESPIRiTCheckBoxLabel.Tooltip = {'ESPIRiT reconstruction of multi-coil data'};
            app.ESPIRiTCheckBoxLabel.Text = 'ESPIRiT';
            app.TVcineEditFieldLabel = uilabel(app.RecoParametersTab);
            app.TVcineEditFieldLabel.Enable = 'off';
            app.TVcineEditFieldLabel.Tooltip = {'Total-variation regularization in the CINE dimension'};
            app.TVcineEditFieldLabel.Text = 'TVcine';
            app.TVxyzEditFieldLabel = uilabel(app.RecoParametersTab);
            app.TVxyzEditFieldLabel.Enable = 'off';
            app.TVxyzEditFieldLabel.Tooltip = {'Total-variation regularization in the spatial dimensions'};
            app.TVxyzEditFieldLabel.Text = 'TVxyz';
            app.TVdynEditFieldLabel = uilabel(app.RecoParametersTab);
            app.TVdynEditFieldLabel.Enable = 'off';
            app.TVdynEditFieldLabel.Tooltip = {'Total-variation regularization in the dynamic dimension'};
            app.TVdynEditFieldLabel.Text = 'TVdyn';
            app.LLRxyzEditFieldLabel = uilabel(app.RecoParametersTab);
            app.LLRxyzEditFieldLabel.Enable = 'off';
            app.LLRxyzEditFieldLabel.Tooltip = {'Total-variation regularization in the spatial dimensions'};
            app.LLRxyzEditFieldLabel.Text = 'LLRxyz';
            app.RadialTab = uitab(app.ParametersTabGroup);
            app.RadialTab.Title = 'Radial';
            app.GradDelayCalibrationCheckBox = uicheckbox(app.RadialTab);
            app.GradDelayCalibrationCheckBox.Enable = 'off';
            app.GradDelayCalibrationCheckBox.Tooltip = {'Automatic calibration of gradient delays'};
            app.GradDelayCalibrationCheckBox.Text = '';
            app.GradDelayCalibrationCheckBoxLabel = uilabel(app.RadialTab);
            app.GradDelayCalibrationCheckBoxLabel.Enable = 'off';
            app.GradDelayCalibrationCheckBoxLabel.Tooltip = {'Automatic calibration of gradient delays'};
            app.GradDelayCalibrationCheckBoxLabel.Text = 'calibration';
            app.GradientdelaysLabel = uilabel(app.RadialTab);
            app.GradientdelaysLabel.Tooltip = {'Gradient delay settings'};
            app.GradientdelaysLabel.Text = 'Gradient delays';
            app.GxdelayEditFieldLabel = uilabel(app.RadialTab);
            app.GxdelayEditFieldLabel.Enable = 'off';
            app.GxdelayEditFieldLabel.Text = 'Gx delay';
            app.GxDelayEditField = uieditfield(app.RadialTab, 'numeric');
            app.GxDelayEditField.Limits = [-10 10];
            app.GxDelayEditField.ValueDisplayFormat = '%.5f';
            app.GxDelayEditField.Enable = 'off';
            app.GxDelayEditField.Tooltip = {'X-gradient delay'};
            app.GydelayEditFieldLabel = uilabel(app.RadialTab);
            app.GydelayEditFieldLabel.Enable = 'off';
            app.GydelayEditFieldLabel.Text = 'Gy delay';
            app.GyDelayEditField = uieditfield(app.RadialTab, 'numeric');
            app.GyDelayEditField.Limits = [-10 10];
            app.GyDelayEditField.ValueDisplayFormat = '%.5f';
            app.GyDelayEditField.Enable = 'off';
            app.GyDelayEditField.Tooltip = {'Y-gradient delay'};
            app.GzdelayEditFieldLabel = uilabel(app.RadialTab);
            app.GzdelayEditFieldLabel.Enable = 'off';
            app.GzdelayEditFieldLabel.Text = 'Gz delay';
            app.GzDelayEditField = uieditfield(app.RadialTab, 'numeric');
            app.GzDelayEditField.Limits = [-10 10];
            app.GzDelayEditField.ValueDisplayFormat = '%.5f';
            app.GzDelayEditField.Enable = 'off';
            app.GzDelayEditField.Tooltip = {'Z-gradient delay'};
            app.DataOffsetRadialEditFieldLabel = uilabel(app.RadialTab);
            app.DataOffsetRadialEditFieldLabel.Enable = 'off';
            app.DataOffsetRadialEditFieldLabel.Tooltip = {'Number of discarded datapoints at the beginning of UTE readout'};
            app.DataOffsetRadialEditFieldLabel.Text = 'data offset';
            app.DataOffsetRadialEditField = uieditfield(app.RadialTab, 'numeric');
            app.DataOffsetRadialEditField.Limits = [0 10];
            app.DataOffsetRadialEditField.ValueDisplayFormat = '%.0f';
            app.DataOffsetRadialEditField.ValueChangedFcn = createCallbackFcn(app, @TrajectorySettingsEditFieldValueChanged, true);
            app.DataOffsetRadialEditField.Enable = 'off';
            app.DataOffsetRadialEditField.Tooltip = {'Number of discarded datapoints at the beginning of UTE readout'};
            app.RingMethodCheckBox = uicheckbox(app.RadialTab);
            app.RingMethodCheckBox.Enable = 'off';
            app.RingMethodCheckBox.Tooltip = {'Ring method calibration [Rosenzweig et al., MRM 81:1898-1906 (2019)]'};
            app.RingMethodCheckBox.Text = '';
            app.RingMethodCheckBoxLabel = uilabel(app.RadialTab);
            app.RingMethodCheckBoxLabel.Enable = 'off';
            app.RingMethodCheckBoxLabel.Tooltip = {'Ring method calibration [Rosenzweig et al., MRM 81:1898-1906 (2019)]'};
            app.RingMethodCheckBoxLabel.Text = 'ring method';
            app.RadialTrajectoryLabel = uilabel(app.RadialTab);
            app.RadialTrajectoryLabel.Tooltip = {'2D radial trajectory'};
            app.RadialTrajectoryLabel.Text = 'Radial trajectory';
            app.TrajectoryButtonGroup = uibuttongroup(app.RadialTab);
            app.TrajectoryButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TrajectoryButtonGroupSelectionChanged, true);
            app.UserDefinedRadialButton = uiradiobutton(app.TrajectoryButtonGroup);
            app.UserDefinedRadialButton.Enable = 'off';
            app.UserDefinedRadialButton.Tooltip = {'User-defined angle increment per k-step'};
            app.UserDefinedRadialButton.Text = 'user defined';
            app.FullCircleRadialButton = uiradiobutton(app.TrajectoryButtonGroup);
            app.FullCircleRadialButton.Enable = 'off';
            app.FullCircleRadialButton.Tooltip = {'Spokes 0 - 360 degrees'};
            app.FullCircleRadialButton.Text = 'full circle';
            app.HalfCircleRadialButton = uiradiobutton(app.TrajectoryButtonGroup);
            app.HalfCircleRadialButton.Enable = 'off';
            app.HalfCircleRadialButton.Tooltip = {'Spokes 0 - 180 degrees'};
            app.HalfCircleRadialButton.Text = 'half circle';
            app.HalfCircleRadialButton.Value = true;
            app.RadialAngleEditField = uieditfield(app.TrajectoryButtonGroup, 'numeric');
            app.RadialAngleEditField.Limits = [1 360];
            app.RadialAngleEditField.ValueDisplayFormat = '%.2f';
            app.RadialAngleEditField.Enable = 'off';
            app.RadialAngleEditField.Tooltip = {'K-space angle increment (degrees)'};
            app.RadialAngleEditField.Value = 1;
            app.PhaseCorrectCheckBox = uicheckbox(app.RadialTab);
            app.PhaseCorrectCheckBox.ValueChangedFcn = createCallbackFcn(app, @TrajectorySettingsEditFieldValueChanged, true);
            app.PhaseCorrectCheckBox.Enable = 'off';
            app.PhaseCorrectCheckBox.Tooltip = {'Check to apply phase correction'};
            app.PhaseCorrectCheckBox.Text = '';
            app.PhaseCorrectCheckBox.Value = true;
            app.PhaseCorrectCheckBoxLabel = uilabel(app.RadialTab);
            app.PhaseCorrectCheckBoxLabel.Enable = 'off';
            app.PhaseCorrectCheckBoxLabel.Tooltip = {'Check to apply phase correction'};
            app.PhaseCorrectCheckBoxLabel.Text = 'phase correction';
            app.CenterEchoCheckBox = uicheckbox(app.RadialTab);
            app.CenterEchoCheckBox.ValueChangedFcn = createCallbackFcn(app, @TrajectorySettingsEditFieldValueChanged, true);
            app.CenterEchoCheckBox.Enable = 'off';
            app.CenterEchoCheckBox.Tooltip = {'Check to center the echo'};
            app.CenterEchoCheckBox.Text = '';
            app.CenterEchoCheckBoxLabel = uilabel(app.RadialTab);
            app.CenterEchoCheckBoxLabel.Enable = 'off';
            app.CenterEchoCheckBoxLabel.Tooltip = {'Check to center the echo'};
            app.CenterEchoCheckBoxLabel.Text = 'center echo';
            app.DensityCorrectCheckBox = uicheckbox(app.RadialTab);
            app.DensityCorrectCheckBox.Enable = 'off';
            app.DensityCorrectCheckBox.Tooltip = {'Check to apply k-space density correction'};
            app.DensityCorrectCheckBox.Text = '';
            app.DensityCorrectCheckBoxLabel = uilabel(app.RadialTab);
            app.DensityCorrectCheckBoxLabel.Enable = 'off';
            app.DensityCorrectCheckBoxLabel.Tooltip = {'Check to apply k-space density correction'};
            app.DensityCorrectCheckBoxLabel.Text = 'density correction';
            app.NavigatorAnalysisTabGroup = uitabgroup(app.UIFigure);
            app.NavigatoranalysisTab = uitab(app.NavigatorAnalysisTabGroup);
            app.NavigatoranalysisTab.Title = 'Navigator analysis';
            app.CardiacNavFig = uiaxes(app.NavigatoranalysisTab);
            title(app.CardiacNavFig, 'Heart navigator')
            xlabel(app.CardiacNavFig, 'Samples')
            ylabel(app.CardiacNavFig, 'Signal')
            app.RespirationNavFig = uiaxes(app.NavigatoranalysisTab);
            title(app.RespirationNavFig, 'Respiration navigator')
            xlabel(app.RespirationNavFig, 'Samples')
            ylabel(app.RespirationNavFig, 'Signal')
            app.HeartRateTimeFig = uiaxes(app.NavigatoranalysisTab);
            title(app.HeartRateTimeFig, 'Heart rate')
            xlabel(app.HeartRateTimeFig, 'Time (s)')
            ylabel(app.HeartRateTimeFig, 'Rate (bpm)')
            app.HeartRateTimeFig.XLim = [0 100];
            app.HeartRateTimeFig.YLim = [0 1000];
            app.RespRateTimeFig = uiaxes(app.NavigatoranalysisTab);
            title(app.RespRateTimeFig, 'Respiration rate')
            xlabel(app.RespRateTimeFig, 'Time (s)')
            ylabel(app.RespRateTimeFig, 'Rate (bpm)')
            app.RespRateTimeFig.XLim = [0 100];
            app.RespRateTimeFig.YLim = [0 100];
            app.ExcludeEndSlider = uislider(app.NavigatoranalysisTab);
            app.ExcludeEndSlider.Limits = [1 6000];
            app.ExcludeEndSlider.MajorTicks = [];
            app.ExcludeEndSlider.MajorTickLabels = {'1', '6000'};
            app.ExcludeEndSlider.ValueChangedFcn = createCallbackFcn(app, @ExcludeSliderValueChanged, true);
            app.ExcludeEndSlider.MinorTicks = [];
            app.ExcludeEndSlider.Enable = 'off';
            app.ExcludeEndSlider.Tooltip = {'Exclude data end'};
            app.ExcludeEndSlider.Value = 1000;
            app.ExcludeStartSlider = uislider(app.NavigatoranalysisTab);
            app.ExcludeStartSlider.Limits = [1 6000];
            app.ExcludeStartSlider.MajorTicks = [];
            app.ExcludeStartSlider.MajorTickLabels = {'1', '6000'};
            app.ExcludeStartSlider.ValueChangedFcn = createCallbackFcn(app, @ExcludeSliderValueChanged, true);
            app.ExcludeStartSlider.MinorTicks = [];
            app.ExcludeStartSlider.Enable = 'off';
            app.ExcludeStartSlider.Tooltip = {'Exclude data start'};
            app.ExcludeStartSlider.Value = 1000;
            app.ExcludeCheckBoxLabel = uilabel(app.NavigatoranalysisTab);
            app.ExcludeCheckBoxLabel.Enable = 'off';
            app.ExcludeCheckBoxLabel.Tooltip = {'Windows the data to exclude part of the acquisition. '; 'The red selection area will be excluded. '; 'Window can be adjusted with the sliders.'};
            app.ExcludeCheckBoxLabel.Text = 'Exclude';
            app.ExcludeCheckBox = uicheckbox(app.NavigatoranalysisTab);
            app.ExcludeCheckBox.ValueChangedFcn = createCallbackFcn(app, @ExcludeCheckBoxValueChanged, true);
            app.ExcludeCheckBox.Enable = 'off';
            app.ExcludeCheckBox.Tooltip = {'Switch include window on/off'};
            app.ExcludeCheckBox.Text = '';
            app.IncludeEndSlider = uislider(app.NavigatoranalysisTab);
            app.IncludeEndSlider.Limits = [1 6000];
            app.IncludeEndSlider.MajorTicks = [];
            app.IncludeEndSlider.MajorTickLabels = {'1', '6000'};
            app.IncludeEndSlider.ValueChangedFcn = createCallbackFcn(app, @IncludeSliderValueChanged, true);
            app.IncludeEndSlider.MinorTicks = [];
            app.IncludeEndSlider.Enable = 'off';
            app.IncludeEndSlider.Tooltip = {'Include data end'};
            app.IncludeEndSlider.Value = 1000;
            app.IncludeStartSlider = uislider(app.NavigatoranalysisTab);
            app.IncludeStartSlider.Limits = [1 6000];
            app.IncludeStartSlider.MajorTicks = [];
            app.IncludeStartSlider.MajorTickLabels = {'1', '6000'};
            app.IncludeStartSlider.ValueChangedFcn = createCallbackFcn(app, @IncludeSliderValueChanged, true);
            app.IncludeStartSlider.MinorTicks = [];
            app.IncludeStartSlider.Enable = 'off';
            app.IncludeStartSlider.Tooltip = {'Include data start'};
            app.IncludeStartSlider.Value = 1000;
            app.IncludeCheckBoxLabel = uilabel(app.NavigatoranalysisTab);
            app.IncludeCheckBoxLabel.Enable = 'off';
            app.IncludeCheckBoxLabel.Tooltip = {'Windows the data to reconstruct only part of the acquisition. '; 'Only the green selection area will be included. '; 'Window can be adjusted with the sliders.'};
            app.IncludeCheckBoxLabel.Text = 'Include';
            app.IncludeCheckBox = uicheckbox(app.NavigatoranalysisTab);
            app.IncludeCheckBox.ValueChangedFcn = createCallbackFcn(app, @IncludeCheckBoxValueChanged, true);
            app.IncludeCheckBox.Enable = 'off';
            app.IncludeCheckBox.Tooltip = {'Switch include window on/off'};
            app.IncludeCheckBox.Text = '';
            app.NavStartButton = uibutton(app.NavigatoranalysisTab, 'push');
            app.NavStartButton.ButtonPushedFcn = createCallbackFcn(app, @NavStartButtonPushed, true);
            app.NavStartButton.Enable = 'off';
            app.NavStartButton.Tooltip = {'Start navigator movie'};
            app.NavStartButton.Text = ' ';
            app.NavStopButton = uibutton(app.NavigatoranalysisTab, 'push');
            app.NavStopButton.ButtonPushedFcn = createCallbackFcn(app, @NavStopButtonPushed, true);
            app.NavStopButton.Enable = 'off';
            app.NavStopButton.Tooltip = {'Stop navigator movie'};
            app.NavStopButton.Text = ' ';
            app.NavFastSlowSwitch = uiswitch(app.NavigatoranalysisTab, 'rocker');
            app.NavFastSlowSwitch.Items = {'slow', 'fast'};
            app.NavFastSlowSwitch.ValueChangedFcn = createCallbackFcn(app, @NavFastSlowSwitchValueChanged, true);
            app.NavFastSlowSwitch.Enable = 'off';
            app.NavFastSlowSwitch.Tooltip = {'Navigator movie fast or slow'};
            app.NavFastSlowSwitch.Value = 'slow';
            app.startEditFieldLabel = uilabel(app.NavigatoranalysisTab);
            app.startEditFieldLabel.Enable = 'off';
            app.startEditFieldLabel.Tooltip = {''};
            app.startEditFieldLabel.Text = 'start';
            app.StartEditField = uieditfield(app.NavigatoranalysisTab, 'numeric');
            app.StartEditField.Limits = [1 Inf];
            app.StartEditField.RoundFractionalValues = 'on';
            app.StartEditField.ValueDisplayFormat = '%.0f';
            app.StartEditField.ValueChangedFcn = createCallbackFcn(app, @DisplayEditFieldValuesChanged, true);
            app.StartEditField.Enable = 'off';
            app.StartEditField.Tooltip = {'Start of plotted navigator window'};
            app.StartEditField.Value = 1000;
            app.StartSlider = uislider(app.NavigatoranalysisTab);
            app.StartSlider.Limits = [1 6000];
            app.StartSlider.MajorTicks = [];
            app.StartSlider.MajorTickLabels = {'1', '6000'};
            app.StartSlider.ValueChangedFcn = createCallbackFcn(app, @DisplaySlidersValueChanged, true);
            app.StartSlider.MinorTicks = [];
            app.StartSlider.Enable = 'off';
            app.StartSlider.Tooltip = {'Start slider'};
            app.StartSlider.Value = 1000;
            app.RangeSlider = uislider(app.NavigatoranalysisTab);
            app.RangeSlider.Limits = [1 2000];
            app.RangeSlider.MajorTicks = [];
            app.RangeSlider.MajorTickLabels = {'1', '6000'};
            app.RangeSlider.ValueChangedFcn = createCallbackFcn(app, @DisplaySlidersValueChanged, true);
            app.RangeSlider.MinorTicks = [];
            app.RangeSlider.Enable = 'off';
            app.RangeSlider.Tooltip = {'Range slider'};
            app.RangeSlider.Value = 600;
            app.RangeEditFieldLabel = uilabel(app.NavigatoranalysisTab);
            app.RangeEditFieldLabel.Enable = 'off';
            app.RangeEditFieldLabel.Tooltip = {''};
            app.RangeEditFieldLabel.Text = 'range';
            app.RangeEditField = uieditfield(app.NavigatoranalysisTab, 'numeric');
            app.RangeEditField.Limits = [1 2000];
            app.RangeEditField.RoundFractionalValues = 'on';
            app.RangeEditField.ValueDisplayFormat = '%.0f';
            app.RangeEditField.ValueChangedFcn = createCallbackFcn(app, @DisplayEditFieldValuesChanged, true);
            app.RangeEditField.Enable = 'off';
            app.RangeEditField.Tooltip = {'Range of plotted navigator window'};
            app.RangeEditField.Value = 600;
            app.DisplayRangeLabel = uilabel(app.NavigatoranalysisTab);
            app.DisplayRangeLabel.Tooltip = {'Selects the starting point and range of the heart- and respiratory-navigator windows'};
            app.DisplayRangeLabel.Text = 'Display range';
            app.NavigatorTabGroup = uitabgroup(app.UIFigure);
            app.NavigatorTab = uitab(app.NavigatorTabGroup);
            app.NavigatorTab.Title = 'Navigator';
            app.RawNavFig = uiaxes(app.NavigatorTab);
            title(app.RawNavFig, 'Raw navigator')
            xlabel(app.RawNavFig, 'Samples')
            ylabel(app.RawNavFig, 'Signal')
            app.SpectrumFig = uiaxes(app.NavigatorTab);
            title(app.SpectrumFig, 'Navigator Spectrum')
            xlabel(app.SpectrumFig, 'Frequency (bpm)')
            ylabel(app.SpectrumFig, 'Power')
            app.CoilSpinner = uispinner(app.NavigatorTab);
            app.CoilSpinner.Limits = [1 16];
            app.CoilSpinner.ValueDisplayFormat = '%.0f';
            app.CoilSpinner.ValueChangedFcn = createCallbackFcn(app, @CoilSpinnerValueChanged, true);
            app.CoilSpinner.Enable = 'off';
            app.CoilSpinner.Tooltip = {'RF receiver coil number'};
            app.CoilSpinner.Value = 1;
            app.CoilSpinnerLabel = uilabel(app.NavigatorTab);
            app.CoilSpinnerLabel.Enable = 'off';
            app.CoilSpinnerLabel.Tooltip = {'Toggles between RF-coils to inspect the raw navigator signals.'};
            app.CoilSpinnerLabel.Text = 'coil';
            app.LogLinButton = uibutton(app.NavigatorTab, 'push');
            app.LogLinButton.ButtonPushedFcn = createCallbackFcn(app, @LogLinButtonPushed, true);
            app.LogLinButton.Enable = 'off';
            app.LogLinButton.Text = 'lin';
            app.NavigatorFlipSwitchLabel = uilabel(app.NavigatorTab);
            app.NavigatorFlipSwitchLabel.Enable = 'off';
            app.NavigatorFlipSwitchLabel.Text = 'flip';
            app.NavigatorFlipSwitch = uiswitch(app.NavigatorTab, 'toggle');
            app.NavigatorFlipSwitch.Items = {'Down', 'Up'};
            app.NavigatorFlipSwitch.ValueChangedFcn = createCallbackFcn(app, @NavPointEditFieldValueChanged, true);
            app.NavigatorFlipSwitch.Enable = 'off';
            app.NavigatorFlipSwitch.Tooltip = {'Flip navigator signal'};
            app.NavigatorFlipSwitch.Value = 'Up';
            app.NavigatorSettingsTab = uitab(app.NavigatorTabGroup);
            app.NavigatorSettingsTab.Title = 'Settings';
            app.NavigatorLabel = uilabel(app.NavigatorSettingsTab);
            app.NavigatorLabel.Text = 'Navigator';
            app.PresetsLabel = uilabel(app.NavigatorSettingsTab);
            app.PresetsLabel.Text = 'Presets';
            app.NavigatorEndEditFieldLabel = uilabel(app.NavigatorSettingsTab);
            app.NavigatorEndEditFieldLabel.Enable = 'off';
            app.NavigatorEndEditFieldLabel.Tooltip = {'Primary navigator point.'};
            app.NavigatorEndEditFieldLabel.Text = 'primary';
            app.NavigatorEndEditField = uieditfield(app.NavigatorSettingsTab, 'numeric');
            app.NavigatorEndEditField.Limits = [1 1024];
            app.NavigatorEndEditField.ValueDisplayFormat = '%.0f';
            app.NavigatorEndEditField.ValueChangedFcn = createCallbackFcn(app, @NavPointEditFieldValueChanged, true);
            app.NavigatorEndEditField.Enable = 'off';
            app.NavigatorEndEditField.Tooltip = {'Primary navigator point analyzed'};
            app.NavigatorEndEditField.Value = 10;
            app.SpeciesButton = uibuttongroup(app.NavigatorSettingsTab);
            app.SpeciesButton.SelectionChangedFcn = createCallbackFcn(app, @SpeciesButtonSelectionChanged, true);
            app.SpeciesButton.Tooltip = {'Preset navigator filter values for mouse and rat'};
            app.SpeciesButton.ButtonDownFcn = createCallbackFcn(app, @SpeciesButtonButtonDown, true);
            app.MouseButton = uiradiobutton(app.SpeciesButton);
            app.MouseButton.Enable = 'off';
            app.MouseButton.Tooltip = {'Mouse preset navigator filter settings'};
            app.MouseButton.Text = 'mouse';
            app.MouseButton.Value = true;
            app.RatButton = uiradiobutton(app.SpeciesButton);
            app.RatButton.Enable = 'off';
            app.RatButton.Tooltip = {'Rat preset navigator filter settings'};
            app.RatButton.Text = 'rat';
            app.ZebraFishButton = uiradiobutton(app.SpeciesButton);
            app.ZebraFishButton.Enable = 'off';
            app.ZebraFishButton.Tooltip = {'Zebrafish preset navigator filter settings'};
            app.ZebraFishButton.Text = 'zebrafish';
            app.NavigatorNRPointsEditFieldLabel = uilabel(app.NavigatorSettingsTab);
            app.NavigatorNRPointsEditFieldLabel.Enable = 'off';
            app.NavigatorNRPointsEditFieldLabel.Tooltip = {'Number of points that are considered for navigator analysis.'};
            app.NavigatorNRPointsEditFieldLabel.Text = '# points';
            app.NavigatorNRPointsEditField = uieditfield(app.NavigatorSettingsTab, 'numeric');
            app.NavigatorNRPointsEditField.Limits = [1 24];
            app.NavigatorNRPointsEditField.ValueDisplayFormat = '%.0f';
            app.NavigatorNRPointsEditField.ValueChangedFcn = createCallbackFcn(app, @NavPointEditFieldValueChanged, true);
            app.NavigatorNRPointsEditField.Enable = 'off';
            app.NavigatorNRPointsEditField.Tooltip = {'Number of navigator points analyzed'};
            app.NavigatorNRPointsEditField.Value = 1;
            app.DiscardEditFieldLabel = uilabel(app.NavigatorSettingsTab);
            app.DiscardEditFieldLabel.Enable = 'off';
            app.DiscardEditFieldLabel.Text = 'freq Nx discard';
            app.DiscardEditField = uieditfield(app.NavigatorSettingsTab, 'numeric');
            app.DiscardEditField.Limits = [0 1024];
            app.DiscardEditField.ValueDisplayFormat = '%.0f';
            app.DiscardEditField.ValueChangedFcn = createCallbackFcn(app, @NavPointEditFieldValueChanged, true);
            app.DiscardEditField.Enable = 'off';
            app.DiscardEditField.Tooltip = {'Number of frequency encoding points discarded after navigator. Increase if FID artifact is present in movie.'};
            app.DiscardEditField.Value = 35;
            app.RespirationLabel = uilabel(app.NavigatorSettingsTab);
            app.RespirationLabel.Text = 'Respiration';
            app.PhysioFilteringLabel = uilabel(app.NavigatorSettingsTab);
            app.PhysioFilteringLabel.Text = 'Physio filtering';
            app.RespirationToggleCheckBoxLabel = uilabel(app.NavigatorSettingsTab);
            app.RespirationToggleCheckBoxLabel.Enable = 'off';
            app.RespirationToggleCheckBoxLabel.Tooltip = {'Reconstruct cardiac CINE frames in inspiration (not checked) or expiration (checked)'};
            app.RespirationToggleCheckBoxLabel.Text = 'toggle in/expiration';
            app.RespirationToggleCheckBox = uicheckbox(app.NavigatorSettingsTab);
            app.RespirationToggleCheckBox.ValueChangedFcn = createCallbackFcn(app, @RespirationToggleCheckBoxValueChanged, true);
            app.RespirationToggleCheckBox.Enable = 'off';
            app.RespirationToggleCheckBox.Tooltip = {'Reconstruct cardiac CINE frames in inspiration (not checked) or expiration (checked)'};
            app.RespirationToggleCheckBox.Text = '';
            app.FilterOrderEditFieldLabel = uilabel(app.NavigatorSettingsTab);
            app.FilterOrderEditFieldLabel.Enable = 'off';
            app.FilterOrderEditFieldLabel.Text = 'filter order';
            app.FilterOrderEditField = uieditfield(app.NavigatorSettingsTab, 'numeric');
            app.FilterOrderEditField.Limits = [1 6];
            app.FilterOrderEditField.ValueDisplayFormat = '%.0f';
            app.FilterOrderEditField.ValueChangedFcn = createCallbackFcn(app, @FilterSettingsChanged, true);
            app.FilterOrderEditField.Enable = 'off';
            app.FilterOrderEditField.Tooltip = {'Butterworth filter order'};
            app.FilterOrderEditField.Value = 4;
            app.IgnoreRespirationToggleCheckBoxLabel = uilabel(app.NavigatorSettingsTab);
            app.IgnoreRespirationToggleCheckBoxLabel.Enable = 'off';
            app.IgnoreRespirationToggleCheckBoxLabel.Tooltip = {'Check to ignore respiration and use all data'};
            app.IgnoreRespirationToggleCheckBoxLabel.Text = 'ignore respiration';
            app.IgnoreRespirationToggleCheckBox = uicheckbox(app.NavigatorSettingsTab);
            app.IgnoreRespirationToggleCheckBox.ValueChangedFcn = createCallbackFcn(app, @RespirationToggleCheckBoxValueChanged, true);
            app.IgnoreRespirationToggleCheckBox.Enable = 'off';
            app.IgnoreRespirationToggleCheckBox.Tooltip = {'Check to ignore respiration and use all data'};
            app.IgnoreRespirationToggleCheckBox.Text = '';
            app.MinHeartRateEditFieldLabel = uilabel(app.NavigatorSettingsTab);
            app.MinHeartRateEditFieldLabel.Enable = 'off';
            app.MinHeartRateEditFieldLabel.Text = 'min heart rate';
            app.MinHeartRateEditField = uieditfield(app.NavigatorSettingsTab, 'numeric');
            app.MinHeartRateEditField.Limits = [1 1000];
            app.MinHeartRateEditField.ValueDisplayFormat = '%.0f';
            app.MinHeartRateEditField.ValueChangedFcn = createCallbackFcn(app, @FilterSettingsChanged, true);
            app.MinHeartRateEditField.Enable = 'off';
            app.MinHeartRateEditField.Tooltip = {'Minimum of search range heart rate'};
            app.MinHeartRateEditField.Value = 100;
            app.MaxHeartRateEditFieldLabel = uilabel(app.NavigatorSettingsTab);
            app.MaxHeartRateEditFieldLabel.Enable = 'off';
            app.MaxHeartRateEditFieldLabel.Text = 'max heart rate';
            app.MaxHeartRateEditField = uieditfield(app.NavigatorSettingsTab, 'numeric');
            app.MaxHeartRateEditField.Limits = [1 1000];
            app.MaxHeartRateEditField.ValueDisplayFormat = '%.0f';
            app.MaxHeartRateEditField.ValueChangedFcn = createCallbackFcn(app, @FilterSettingsChanged, true);
            app.MaxHeartRateEditField.Enable = 'off';
            app.MaxHeartRateEditField.Tooltip = {'Maxiumum of search range heart rate'};
            app.MaxHeartRateEditField.Value = 1000;
            app.AutoSoundsCheckBoxLabel = uilabel(app.NavigatorSettingsTab);
            app.AutoSoundsCheckBoxLabel.Tooltip = {'Warning and error sounds on or off'};
            app.AutoSoundsCheckBoxLabel.Text = 'sounds on/off';
            app.SoundsLabel = uilabel(app.NavigatorSettingsTab);
            app.SoundsLabel.Text = 'Sounds';
            app.AutoSoundsCheckBox = uicheckbox(app.NavigatorSettingsTab);
            app.AutoSoundsCheckBox.Tooltip = {'Warning and error sounds on or off'};
            app.AutoSoundsCheckBox.Text = '';
            app.AutoSoundsCheckBox.Value = true;
            app.MinRespRateEditFieldLabel = uilabel(app.NavigatorSettingsTab);
            app.MinRespRateEditFieldLabel.Enable = 'off';
            app.MinRespRateEditFieldLabel.Text = 'min resp rate';
            app.MinRespRateEditField = uieditfield(app.NavigatorSettingsTab, 'numeric');
            app.MinRespRateEditField.Limits = [1 1000];
            app.MinRespRateEditField.ValueDisplayFormat = '%.0f';
            app.MinRespRateEditField.ValueChangedFcn = createCallbackFcn(app, @FilterSettingsChanged, true);
            app.MinRespRateEditField.Enable = 'off';
            app.MinRespRateEditField.Tooltip = {'Minimum search range respiration rate'};
            app.MinRespRateEditField.Value = 5;
            app.MaxRespRateEditFieldLabel = uilabel(app.NavigatorSettingsTab);
            app.MaxRespRateEditFieldLabel.Enable = 'off';
            app.MaxRespRateEditFieldLabel.Text = 'max resp rate';
            app.MaxRespRateEditField = uieditfield(app.NavigatorSettingsTab, 'numeric');
            app.MaxRespRateEditField.Limits = [1 1000];
            app.MaxRespRateEditField.ValueDisplayFormat = '%.0f';
            app.MaxRespRateEditField.ValueChangedFcn = createCallbackFcn(app, @FilterSettingsChanged, true);
            app.MaxRespRateEditField.Enable = 'off';
            app.MaxRespRateEditField.Tooltip = {'Maximum search range respiration rate'};
            app.MaxRespRateEditField.Value = 400;
            app.ColorsLabel = uilabel(app.NavigatorSettingsTab);
            app.ColorsLabel.Text = 'Colors';
            app.RespShiftEditFieldLabel = uilabel(app.NavigatorSettingsTab);
            app.RespShiftEditFieldLabel.Enable = 'off';
            app.RespShiftEditFieldLabel.Text = 'resp shift (samples)';
            app.RespShiftEditField = uieditfield(app.NavigatorSettingsTab, 'numeric');
            app.RespShiftEditField.Limits = [-100 100];
            app.RespShiftEditField.ValueDisplayFormat = '%.0f';
            app.RespShiftEditField.ValueChangedFcn = createCallbackFcn(app, @FilterSettingsChanged, true);
            app.RespShiftEditField.Enable = 'off';
            app.RespShiftEditField.Tooltip = {'Shifts the respiration window left or right'};
            app.ColorsButtonGroup = uibuttongroup(app.NavigatorSettingsTab);
            app.ColorsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ColorsButtonGroupSelectionChanged, true);
            app.ColorsButtonGroup.Tooltip = {'App color scheme selection'};
            app.ClassicButton = uiradiobutton(app.ColorsButtonGroup);
            app.ClassicButton.Text = 'classic';
            app.BlackWhiteButton = uiradiobutton(app.ColorsButtonGroup);
            app.BlackWhiteButton.Text = 'black/white';
            app.BlackWhiteButton.Value = true;
            app.FilterPerSliceCheckBox = uicheckbox(app.NavigatorSettingsTab);
            app.FilterPerSliceCheckBox.Enable = 'off';
            app.FilterPerSliceCheckBox.Tooltip = {'Filter cardiac and respiratory motion for each slice separately'};
            app.FilterPerSliceCheckBox.Text = '';
            app.FilterPerSliceLabel = uilabel(app.NavigatorSettingsTab);
            app.FilterPerSliceLabel.Enable = 'off';
            app.FilterPerSliceLabel.Tooltip = {'Filter cardiac and respiratory motion for each slice separately'};
            app.FilterPerSliceLabel.Text = 'filter per slice';
            app.DashboardTabGroup = uitabgroup(app.UIFigure);
            app.DashboardTab = uitab(app.DashboardTabGroup);
            app.DashboardTab.Title = 'Dashboard';
            app.Lamp1 = uilamp(app.DashboardTab);
            app.Lamp2 = uilamp(app.DashboardTab);
            app.Lamp3 = uilamp(app.DashboardTab);
            app.Lamp4 = uilamp(app.DashboardTab);
            app.Lamp5 = uilamp(app.DashboardTab);
            app.Lamp6 = uilamp(app.DashboardTab);
            app.Lamp7 = uilamp(app.DashboardTab);
            app.DetectedRatesBpmPanel = uipanel(app.DashboardTab);
            app.DetectedRatesBpmPanel.Title = 'Heart / respiration filters';
            app.HeartEditField = uieditfield(app.DetectedRatesBpmPanel, 'numeric');
            app.HeartEditField.Limits = [1 1000];
            app.HeartEditField.ValueChangedFcn = createCallbackFcn(app, @FilterEditFieldValueChanged, true);
            app.HeartEditField.Enable = 'off';
            app.HeartEditField.Tooltip = {'Heart rate estimate'};
            app.HeartEditField.Value = 500;
            app.HeartWidthEditField = uieditfield(app.DetectedRatesBpmPanel, 'numeric');
            app.HeartWidthEditField.Limits = [20 300];
            app.HeartWidthEditField.ValueDisplayFormat = '%.0f';
            app.HeartWidthEditField.ValueChangedFcn = createCallbackFcn(app, @FilterEditFieldValueChanged, true);
            app.HeartWidthEditField.Enable = 'off';
            app.HeartWidthEditField.Tooltip = {'Heart rate variation estimate'};
            app.HeartWidthEditField.Value = 100;
            app.RespirationEditField = uieditfield(app.DetectedRatesBpmPanel, 'numeric');
            app.RespirationEditField.Limits = [1 1000];
            app.RespirationEditField.ValueChangedFcn = createCallbackFcn(app, @FilterEditFieldValueChanged, true);
            app.RespirationEditField.Enable = 'off';
            app.RespirationEditField.Tooltip = {'Respiration rate estimate'};
            app.RespirationEditField.Value = 50;
            app.RespirationWidthEditField = uieditfield(app.DetectedRatesBpmPanel, 'numeric');
            app.RespirationWidthEditField.Limits = [5 100];
            app.RespirationWidthEditField.ValueChangedFcn = createCallbackFcn(app, @FilterEditFieldValueChanged, true);
            app.RespirationWidthEditField.Enable = 'off';
            app.RespirationWidthEditField.Tooltip = {'Respiration rate variation estimate'};
            app.RespirationWidthEditField.Value = 40;
            app.RespirationWindowEditField = uieditfield(app.DetectedRatesBpmPanel, 'numeric');
            app.RespirationWindowEditField.Limits = [5 95];
            app.RespirationWindowEditField.ValueDisplayFormat = '%.0f';
            app.RespirationWindowEditField.ValueChangedFcn = createCallbackFcn(app, @FilterEditFieldValueChanged, true);
            app.RespirationWindowEditField.Enable = 'off';
            app.RespirationWindowEditField.Tooltip = {'Respiration discard window width in percentage of respiration period'};
            app.RespirationWindowEditField.Value = 30;
            app.ResetFilterValuesButton = uibutton(app.DetectedRatesBpmPanel, 'push');
            app.ResetFilterValuesButton.ButtonPushedFcn = createCallbackFcn(app, @ResetFilterValuesButtonPushed, true);
            app.ResetFilterValuesButton.Enable = 'off';
            app.ResetFilterValuesButton.Tooltip = {'Reset filters'};
            app.ResetFilterValuesButton.Text = 'reset';
            app.CenterLabel = uilabel(app.DetectedRatesBpmPanel);
            app.CenterLabel.Enable = 'off';
            app.CenterLabel.Text = 'center';
            app.WidthLabel = uilabel(app.DetectedRatesBpmPanel);
            app.WidthLabel.Enable = 'off';
            app.WidthLabel.Text = 'width';
            app.HeartEditFieldLabel = uilabel(app.DetectedRatesBpmPanel);
            app.HeartEditFieldLabel.Enable = 'off';
            app.HeartEditFieldLabel.Text = 'heart (bpm)';
            app.RespirationEditFieldLabel = uilabel(app.DetectedRatesBpmPanel);
            app.RespirationEditFieldLabel.Enable = 'off';
            app.RespirationEditFieldLabel.Text = 'resp. (bpm)';
            app.RespirationWindowEditFieldLabel = uilabel(app.DetectedRatesBpmPanel);
            app.RespirationWindowEditFieldLabel.Enable = 'off';
            app.RespirationWindowEditFieldLabel.Text = 'window (%)';
            app.CinePanel = uipanel(app.DashboardTab);
            app.CinePanel.Title = 'Cine';
            app.RecoTypeDropDown = uidropdown(app.CinePanel);
            app.RecoTypeDropDown.Items = {'cardiac', 'cardiac abs', 'respiratory', 'realtime'};
            app.RecoTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @CinePanelValueChanged, true);
            app.RecoTypeDropDown.Enable = 'off';
            app.RecoTypeDropDown.Tooltip = {'Data sorting:'; '- cardiac phase binning'; '- cardiac absolute binning'; '- respiratory phase binning'; '- realtime, 1 dynamic per heart beat'};
            app.RecoTypeDropDown.Value = 'cardiac';
            app.FramesEditField = uieditfield(app.CinePanel, 'numeric');
            app.FramesEditField.Limits = [1 240];
            app.FramesEditField.RoundFractionalValues = 'on';
            app.FramesEditField.ValueDisplayFormat = '%.0f';
            app.FramesEditField.ValueChangedFcn = createCallbackFcn(app, @CinePanelValueChanged, true);
            app.FramesEditField.Enable = 'off';
            app.FramesEditField.Tooltip = {'Number of desired CINE frames'};
            app.FramesEditField.Value = 12;
            app.DynamicsEditField = uieditfield(app.CinePanel, 'numeric');
            app.DynamicsEditField.Limits = [1 Inf];
            app.DynamicsEditField.RoundFractionalValues = 'on';
            app.DynamicsEditField.ValueDisplayFormat = '%.0f';
            app.DynamicsEditField.ValueChangedFcn = createCallbackFcn(app, @CinePanelValueChanged, true);
            app.DynamicsEditField.Enable = 'off';
            app.DynamicsEditField.Tooltip = {'Number of desired dynamics'};
            app.DynamicsEditField.Value = 1;
            app.RecoTypeDropDownLabel = uilabel(app.CinePanel);
            app.RecoTypeDropDownLabel.Enable = 'off';
            app.RecoTypeDropDownLabel.Text = 'movie';
            app.FramesEditFieldLabel = uilabel(app.CinePanel);
            app.FramesEditFieldLabel.Enable = 'off';
            app.FramesEditFieldLabel.Text = '# CINE frames';
            app.DynamicsEditFieldLabel = uilabel(app.CinePanel);
            app.DynamicsEditFieldLabel.Enable = 'off';
            app.DynamicsEditFieldLabel.Tooltip = {'Number of desired dynamics'};
            app.DynamicsEditFieldLabel.Text = '# dynamics';
            app.ReconstructionPanel = uipanel(app.DashboardTab);
            app.ReconstructionPanel.Title = 'Reconstruction';
            app.ExportPanel = uipanel(app.DashboardTab);
            app.ExportPanel.Title = 'Export';
            app.LoadMRDButton = uibutton(app.DashboardTab, 'push');
            app.LoadMRDButton.ButtonPushedFcn = createCallbackFcn(app, @LoadMRDfileButtonPushed, true);
            app.LoadMRDButton.Enable = 'off';
            app.LoadMRDButton.Tooltip = {'Select MRD file - '; 'for multicoil data select one of the MRD files'};
            app.LoadMRDButton.Text = ' Load MRD';
            app.FilterButton = uibutton(app.DashboardTab, 'push');
            app.FilterButton.ButtonPushedFcn = createCallbackFcn(app, @FilterButtonPushed, true);
            app.FilterButton.Enable = 'off';
            app.FilterButton.Tooltip = {'Filters the navigator signal to extract cardiac and respiratory triggering points'};
            app.FilterButton.Text = ' Filter';
            app.SortKspaceButton = uibutton(app.DashboardTab, 'push');
            app.SortKspaceButton.ButtonPushedFcn = createCallbackFcn(app, @SortKspaceButtonPushed, true);
            app.SortKspaceButton.Enable = 'off';
            app.SortKspaceButton.Tooltip = {'Sort the data in the desired number of cardiac or respiratory frames'};
            app.SortKspaceButton.Text = 'Sort k-space';
            app.ReconstructButton = uibutton(app.DashboardTab, 'push');
            app.ReconstructButton.ButtonPushedFcn = createCallbackFcn(app, @RecoMoviesButtonPushed, true);
            app.ReconstructButton.Enable = 'off';
            app.ReconstructButton.Tooltip = {'Perform a compressed-sensing reconstruction of the data with the Bart-toolbox (if available) or Matlab'};
            app.ReconstructButton.Text = 'Reconstruct';
            app.ExportDCMButton = uibutton(app.DashboardTab, 'push');
            app.ExportDCMButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDCMButtonPushed, true);
            app.ExportDCMButton.Enable = 'off';
            app.ExportDCMButton.Tooltip = {'Export movie(s) to DICOM'};
            app.ExportDCMButton.Text = 'Export DCM';
            app.ExportGIFButton = uibutton(app.DashboardTab, 'push');
            app.ExportGIFButton.ButtonPushedFcn = createCallbackFcn(app, @ExportGIFButtonPushed, true);
            app.ExportGIFButton.Enable = 'off';
            app.ExportGIFButton.Tooltip = {'Export movie(s) to animated GIF'};
            app.ExportGIFButton.Text = 'Export GIF';
            app.ExportMRDButton = uibutton(app.DashboardTab, 'push');
            app.ExportMRDButton.ButtonPushedFcn = createCallbackFcn(app, @ExportMRDButtonPushed, true);
            app.ExportMRDButton.Enable = 'off';
            app.ExportMRDButton.Tooltip = {'Export movie(s) to a new MRD file'};
            app.ExportMRDButton.Text = 'Export MRD';
            app.ExitButton = uibutton(app.DashboardTab, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Tooltip = {'Exit the application'};
            app.ExitButton.Text = '     Exit';
            app.ProgressGauge = uigauge(app.DashboardTab, 'linear');
            app.ProgressGauge.MajorTicks = [0 25 50 75 100];
            app.ReconstructionTypeButtonGroup = uibuttongroup(app.DashboardTab);
            app.CompressedSensingButton = uiradiobutton(app.ReconstructionTypeButtonGroup);
            app.CompressedSensingButton.Enable = 'off';
            app.CompressedSensingButton.Tooltip = {'Compressed sensing reconstruction'};
            app.CompressedSensingButton.Text = 'Compressed Sensing';
            app.CompressedSensingButton.Value = true;
            app.DeepLearningButton = uiradiobutton(app.ReconstructionTypeButtonGroup);
            app.DeepLearningButton.Enable = 'off';
            app.DeepLearningButton.Tooltip = {'Dynamic recurrent inference machine reconstruction.'; 'Requires 2D data and #frames > 4'};
            app.DeepLearningButton.Text = 'Deep Learning';
            app.RetrospectiveCreditLabel = uilabel(app.UIFigure);
            app.RetrospectiveCreditLabel.Text = 'Retrospective can be credited by citing this paper:';
            app.JoveHyperlink = uihyperlink(app.UIFigure);
            app.JoveHyperlink.URL = 'https://www.jove.com/t/62595/quantification-mouse-heart-left-ventricular-function-myocardial';
            app.JoveHyperlink.Text = 'JOVE';
            app.RetrospectiveTitleImage = uiimage(app.UIFigure);
            app.AutoRecoCheckBox = uicheckbox(app.UIFigure);
            app.AutoRecoCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutoRecoCheckBoxValueChanged, true);
            app.AutoRecoCheckBox.Tooltip = {'Check for automatic reconstruction'};
            app.AutoRecoCheckBox.Text = '';
            app.AutoRecoLabel = uilabel(app.UIFigure);
            app.AutoRecoLabel.Tooltip = {'Check for automatic reconstruction'};
            app.AutoRecoLabel.Text = 'auto reco';
            app.MessageBox = uitextarea(app.UIFigure);
            app.MessageBox.Editable = 'off';
            app.MessageBox.Tooltip = {'Messages'};
            app.MessageBox.Value = {'Messages ...'};
            app.GithubHyperlink = uihyperlink(app.UIFigure);
            app.GithubHyperlink.URL = 'https://github.com/Moby1971/';
            app.GithubHyperlink.Text = 'Github';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = retrospective

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @StartupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end